classdef GPEARL < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AnalysisToolUIFigure            matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ConfigurationTab                matlab.ui.container.Tab
        Image4_2                        matlab.ui.control.Image
        Image4                          matlab.ui.control.Image
        ConsolePanel                    matlab.ui.container.Panel
        consoleWindow                   matlab.ui.control.TextArea
        ReceiverDataPanel               matlab.ui.container.Panel
        NMEATextArea                    matlab.ui.control.TextArea
        NMEATextAreaLabel               matlab.ui.control.Label
        RINEXObsTextArea                matlab.ui.control.TextArea
        RINEXObsTextAreaLabel           matlab.ui.control.Label
        OverrideRINEXFolder_2           matlab.ui.control.Button
        OverrideRINEXFolder             matlab.ui.control.Button
        ToolStatusPanel                 matlab.ui.container.Panel
        NMEANavreferenceLamp            matlab.ui.control.Lamp
        NMEANavreferenceLampLabel       matlab.ui.control.Label
        NMEANavLamp                     matlab.ui.control.Lamp
        RINEXnavdataloadingLabel        matlab.ui.control.Label
        RINEXObsLamp                    matlab.ui.control.Lamp
        InputDataLoadingLabel           matlab.ui.control.Label
        ResetSessionButton              matlab.ui.control.Button
        SessionsettingsPanel            matlab.ui.container.Panel
        ReferenceDataPanel              matlab.ui.container.Panel
        OverrideRINEXFolder_3           matlab.ui.control.Button
        NMEATextArea_2                  matlab.ui.control.TextArea
        NMEATextArea_2Label             matlab.ui.control.Label
        Label_137                       matlab.ui.control.Label
        Label_136                       matlab.ui.control.Label
        LoadDataButton                  matlab.ui.control.StateButton
        Panel_17                        matlab.ui.container.Panel
        UserTextArea                    matlab.ui.control.TextArea
        UserTextAreaLabel               matlab.ui.control.Label
        OutputDataPathLamp              matlab.ui.control.Lamp
        OverrideOutputFolder            matlab.ui.control.Button
        OutputFolderLabel               matlab.ui.control.Label
        OutputDataEditField             matlab.ui.control.TextArea
        Image3                          matlab.ui.control.Image
        GPEARLLabel                     matlab.ui.control.Label
        LuGRENavigationAnalysisandReportingToolQuickLookToolLabel  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        AnalysesTab                     matlab.ui.container.Tab
        ConstellationsLabel             matlab.ui.control.Label
        BDSCheckBox                     matlab.ui.control.CheckBox
        GALCheckBox                     matlab.ui.control.CheckBox
        GLOCheckBox                     matlab.ui.control.CheckBox
        GPSCheckBox                     matlab.ui.control.CheckBox
        Q2c_completed                   matlab.ui.control.Lamp
        Q1h_completed                   matlab.ui.control.Lamp
        Q1g_completed                   matlab.ui.control.Lamp
        Q2b_completed                   matlab.ui.control.Lamp
        Q1f_completed                   matlab.ui.control.Lamp
        Q2a_completed                   matlab.ui.control.Lamp
        Q1e_completed                   matlab.ui.control.Lamp
        Q1b_completed                   matlab.ui.control.Lamp
        Q1c_completed                   matlab.ui.control.Lamp
        Q1d_completed                   matlab.ui.control.Lamp
        Q1a_completed                   matlab.ui.control.Lamp
        TabGroup3                       matlab.ui.container.TabGroup
        aTab                            matlab.ui.container.Tab
        runPanel                        matlab.ui.container.Panel
        RunEditField_Q1a                matlab.ui.control.NumericEditField
        RunEditFieldLabel               matlab.ui.control.Label
        Experiment1bLabel_8             matlab.ui.control.Label
        Label_121                       matlab.ui.control.Label
        Experiment1aLabel_18            matlab.ui.control.Label
        OperatorCommentincludedinthereportPanel  matlab.ui.container.Panel
        CommentArea_Q1a                 matlab.ui.control.TextArea
        ResultsLamp_Q1a                 matlab.ui.control.Lamp
        SaveResultsButton_Q1a           matlab.ui.control.Button
        ReportLamp_Q1a                  matlab.ui.control.Lamp
        ExperimentSuccessLamp_Q1a       matlab.ui.control.Lamp
        GenerateReportButton_Q1a        matlab.ui.control.Button
        OutputPanel_15                  matlab.ui.container.Panel
        TextArea_Q1a                    matlab.ui.control.TextArea
        RunExperimentButton_Q1a         matlab.ui.control.Button
        Label_12                        matlab.ui.control.Label
        bTab                            matlab.ui.container.Tab
        ScienceProductLabel_20          matlab.ui.control.Label
        runPanel_Q1b                    matlab.ui.container.Panel
        RunEditField_Q1b                matlab.ui.control.NumericEditField
        RunEditField_2Label             matlab.ui.control.Label
        Experiment1bLabel_4             matlab.ui.control.Label
        SaveResultsButton_Q1b           matlab.ui.control.Button
        ReportLamp_Q1b                  matlab.ui.control.Lamp
        ResultsLamp_Q1b                 matlab.ui.control.Lamp
        OperatorCommentincludedinthereportPanel_6  matlab.ui.container.Panel
        CommentArea_Q1b                 matlab.ui.control.TextArea
        SettingsPanel_2                 matlab.ui.container.Panel
        Label_85                        matlab.ui.control.Label
        CN0ThresholdSpinner             matlab.ui.control.Spinner
        CN0ThresholdSpinnerLabel        matlab.ui.control.Label
        GenerateReportButton_Q1b        matlab.ui.control.Button
        RunExperimentButton_Q1b         matlab.ui.control.Button
        ExperimentSuccessLamp_Q1b       matlab.ui.control.Lamp
        OutputPanel_2                   matlab.ui.container.Panel
        TextArea_Q1b                    matlab.ui.control.TextArea
        Experiment1bLabel               matlab.ui.control.Label
        cTab                            matlab.ui.container.Tab
        runPanel_Q1c                    matlab.ui.container.Panel
        RunEditField_Q1c                matlab.ui.control.NumericEditField
        RunEditField_3Label             matlab.ui.control.Label
        Experiment1bLabel_5             matlab.ui.control.Label
        ScienceProductLabel_4           matlab.ui.control.Label
        SaveResultsButton_Q1c           matlab.ui.control.Button
        ReportLamp_Q1c                  matlab.ui.control.Lamp
        ResultsLamp_Q1c                 matlab.ui.control.Lamp
        OperatorCommentincludedinthereportPanel_7  matlab.ui.container.Panel
        CommentArea_Q1c                 matlab.ui.control.TextArea
        GenerateReportButton_Q1c        matlab.ui.control.Button
        RunExperimentButton_Q1c         matlab.ui.control.Button
        ExperimentSuccessLamp_Q1c       matlab.ui.control.Lamp
        OutputPanel_3                   matlab.ui.container.Panel
        TextArea_Q1c                    matlab.ui.control.TextArea
        Experiment1cLabel               matlab.ui.control.Label
        dTab                            matlab.ui.container.Tab
        RunExperimentButton_Q1d         matlab.ui.control.Button
        runPanel_Q1d                    matlab.ui.container.Panel
        RunEditField_Q1d                matlab.ui.control.NumericEditField
        RunEditField_4Label             matlab.ui.control.Label
        Experiment1bLabel_6             matlab.ui.control.Label
        UITable_Q1d                     matlab.ui.control.Table
        PseudorangeerroranalysisLabel   matlab.ui.control.Label
        OperatorComment_4               matlab.ui.container.Panel
        CommentArea_Q1d                 matlab.ui.control.TextArea
        ResultsLamp_Q1d                 matlab.ui.control.Lamp
        SaveResultsButton_Q1d           matlab.ui.control.Button
        ReportLamp_Q1d                  matlab.ui.control.Lamp
        GenerateReportButton_Q1d        matlab.ui.control.Button
        ScienceProductLabel             matlab.ui.control.Label
        ExperimentSuccessLamp_Q1d       matlab.ui.control.Lamp
        OutputPanel_4                   matlab.ui.container.Panel
        TextArea_Q1d                    matlab.ui.control.TextArea
        Experiment1dLabel               matlab.ui.control.Label
        eTab                            matlab.ui.container.Tab
        Experiment1bLabel_9             matlab.ui.control.Label
        ScienceProductLabel_16          matlab.ui.control.Label
        runPanel_Q1e                    matlab.ui.container.Panel
        RunEditField_Q1e                matlab.ui.control.NumericEditField
        RunEditField_5Label             matlab.ui.control.Label
        SaveResultsButton_Q1e           matlab.ui.control.Button
        ResultsLamp_Q1e                 matlab.ui.control.Lamp
        ReportLamp_Q1e                  matlab.ui.control.Lamp
        OperatorCommentincludedinthereportPanel_10  matlab.ui.container.Panel
        CommentArea_Q1e                 matlab.ui.control.TextArea
        GenerateReportButton_Q1e        matlab.ui.control.Button
        RunExperimentButton_Q1e         matlab.ui.control.Button
        ExperimentSuccessLamp_Q1e       matlab.ui.control.Lamp
        OutputPanel_5                   matlab.ui.container.Panel
        TextArea_Q1e                    matlab.ui.control.TextArea
        Experiment1eLabel               matlab.ui.control.Label
        fTab                            matlab.ui.container.Tab
        L5E5OutliersLabel               matlab.ui.control.Label
        L1E1OutliersLabel               matlab.ui.control.Label
        UITable_Q1f_L5                  matlab.ui.control.Table
        UITable_Q1f_L1                  matlab.ui.control.Table
        SettingsPanel                   matlab.ui.container.Panel
        FindOutliersCheckBox            matlab.ui.control.CheckBox
        ThresholdSpinner                matlab.ui.control.Spinner
        ThresholdSpinnerLabel           matlab.ui.control.Label
        WindowSizeSpinner               matlab.ui.control.Spinner
        WindowSizeSpinnerLabel          matlab.ui.control.Label
        SaveResultsButton_Q1f           matlab.ui.control.Button
        ResultsLamp_Q1f                 matlab.ui.control.Lamp
        ReportLamp_Q1f                  matlab.ui.control.Lamp
        GenerateReportButton_Q1f        matlab.ui.control.Button
        OperatorCommentincludedinthereportPanel_11  matlab.ui.container.Panel
        CommentArea_Q1f                 matlab.ui.control.TextArea
        OutputPanel_19                  matlab.ui.container.Panel
        TextArea_Q1f                    matlab.ui.control.TextArea
        runPanel_Q1f                    matlab.ui.container.Panel
        RunEditField_Q1f                matlab.ui.control.NumericEditField
        RunEditField_5Label_2           matlab.ui.control.Label
        Experiment1bLabel_14            matlab.ui.control.Label
        ScienceProductLabel_8           matlab.ui.control.Label
        RunExperimentButton_Q1f         matlab.ui.control.Button
        ExperimentSuccessLamp_Q1f       matlab.ui.control.Lamp
        Experiment1fLabel               matlab.ui.control.Label
        gTab                            matlab.ui.container.Tab
        SaveResultsButton_Q1g           matlab.ui.control.Button
        ResultsLamp_Q1g                 matlab.ui.control.Lamp
        ReportLamp_Q1g                  matlab.ui.control.Lamp
        GenerateReportButton_Q1g        matlab.ui.control.Button
        OutputPanel_22                  matlab.ui.container.Panel
        TextArea_Q1g                    matlab.ui.control.TextArea
        OperatorCommentincludedinthereportPanel_13  matlab.ui.container.Panel
        CommentArea_Q1g                 matlab.ui.control.TextArea
        RunExperimentButton_Q1g         matlab.ui.control.Button
        ExperimentSuccessLamp_Q1g       matlab.ui.control.Lamp
        runPanel_Q1g                    matlab.ui.container.Panel
        RunEditField_Q1g                matlab.ui.control.NumericEditField
        RunEditField_5Label_3           matlab.ui.control.Label
        Experiment1bLabel_17            matlab.ui.control.Label
        ScienceProductLabel_24          matlab.ui.control.Label
        Experiment1gLabel               matlab.ui.control.Label
        hTab                            matlab.ui.container.Tab
        ScienceProductLabel_27          matlab.ui.control.Label
        SaveResultsButton_Q1h           matlab.ui.control.Button
        ResultsLamp_Q1h                 matlab.ui.control.Lamp
        ReportLamp_Q1h                  matlab.ui.control.Lamp
        GenerateReportButton_Q1h        matlab.ui.control.Button
        OutputPanel_23                  matlab.ui.container.Panel
        TextArea_Q1h                    matlab.ui.control.TextArea
        OperatorCommentincludedinthereportPanel_14  matlab.ui.container.Panel
        CommentArea_Q1h                 matlab.ui.control.TextArea
        RunExperimentButton_Q1h         matlab.ui.control.Button
        ExperimentSuccessLamp_Q1h       matlab.ui.control.Lamp
        runPanel_Q1h                    matlab.ui.container.Panel
        RunEditField_Q1h                matlab.ui.control.NumericEditField
        RunEditField_5Label_4           matlab.ui.control.Label
        Experiment1bLabel_18            matlab.ui.control.Label
        Experiment1fLabel_3             matlab.ui.control.Label
        aTab_2                          matlab.ui.container.Tab
        ReferenceFrame_Q2a              matlab.ui.control.Switch
        ReferenceFrameSwitchLabel       matlab.ui.control.Label
        runPanel_6                      matlab.ui.container.Panel
        RunEditField_Q2a                matlab.ui.control.NumericEditField
        RunEditField_6Label             matlab.ui.control.Label
        Experiment1bLabel_10            matlab.ui.control.Label
        ScienceProductLabel_17          matlab.ui.control.Label
        SaveResultsButton_Q2a           matlab.ui.control.Button
        ReportLamp_Q2a                  matlab.ui.control.Lamp
        ResultsLamp_Q2a                 matlab.ui.control.Lamp
        OperatorCommentincludedinthereportPanel_12  matlab.ui.container.Panel
        CommentArea_Q2a                 matlab.ui.control.TextArea
        GenerateReportButton_Q2a        matlab.ui.control.Button
        RunExperimentButton_Q2a         matlab.ui.control.Button
        ExperimentSuccessLamp_Q2a       matlab.ui.control.Lamp
        OutputPanel_8                   matlab.ui.container.Panel
        TextArea_Q2a                    matlab.ui.control.TextArea
        Experiment2aLabel               matlab.ui.control.Label
        bTab_3                          matlab.ui.container.Tab
        Experiment1bLabel_11            matlab.ui.control.Label
        Label_135                       matlab.ui.control.Label
        runPanel_Q2b                    matlab.ui.container.Panel
        RunEditField_Q2b                matlab.ui.control.NumericEditField
        RunEditField_8Label             matlab.ui.control.Label
        SaveResultsButton_Q2b           matlab.ui.control.Button
        ReportLamp_Q2b                  matlab.ui.control.Lamp
        ResultsLamp_Q2b                 matlab.ui.control.Lamp
        GenerateReportButton_Q2b        matlab.ui.control.Button
        RunExperimentButton_Q2b         matlab.ui.control.Button
        ExperimentSuccessLamp_Q2b       matlab.ui.control.Lamp
        OperatorCommentincludedinthereportPanel_3  matlab.ui.container.Panel
        CommentArea_Q2b                 matlab.ui.control.TextArea
        OutputPanel_16                  matlab.ui.container.Panel
        TextArea_Q2b                    matlab.ui.control.TextArea
        Experiment2bLabel               matlab.ui.control.Label
        cTab_2                          matlab.ui.container.Tab
        SaveResultsButton_Q2c           matlab.ui.control.Button
        ReportLamp_Q2c                  matlab.ui.control.Lamp
        ResultsLamp_Q2c                 matlab.ui.control.Lamp
        GenerateReportButton_Q2c        matlab.ui.control.Button
        RunExperimentButton_Q2c         matlab.ui.control.Button
        ExperimentSuccessLamp_Q2c       matlab.ui.control.Lamp
        OperatorCommentincludedinthereportPanel_15  matlab.ui.container.Panel
        CommentArea_Q2c                 matlab.ui.control.TextArea
        OutputPanel_24                  matlab.ui.container.Panel
        TextArea_Q2c                    matlab.ui.control.TextArea
        runPanel_Q2c                    matlab.ui.container.Panel
        RunEditField_Q2c                matlab.ui.control.NumericEditField
        RunEditField_8Label_4           matlab.ui.control.Label
        Experiment1bLabel_19            matlab.ui.control.Label
        Experiment2dLabel_2             matlab.ui.control.Label
        SummaryReportTab                matlab.ui.container.Tab
        OPSSummaryReportOSRPanel        matlab.ui.container.Panel
        OSR_operatorComments            matlab.ui.container.Panel
        OSR_comments                    matlab.ui.control.TextArea
        CheckBox_Q1c                    matlab.ui.control.CheckBox
        EditField_Q1d_OSR               matlab.ui.control.EditField
        EditField_Q2d_OSR               matlab.ui.control.EditField
        CheckBox_Q2d                    matlab.ui.control.CheckBox
        EditField_Q3b_OSR               matlab.ui.control.EditField
        EditField_Q3a_OSR               matlab.ui.control.EditField
        EditField_Q2c_OSR               matlab.ui.control.EditField
        EditField_Q2a_OSR               matlab.ui.control.EditField
        EditField_Q1f_OSR               matlab.ui.control.EditField
        EditField_Q1e_OSR               matlab.ui.control.EditField
        EditField_Q1c_OSR               matlab.ui.control.EditField
        EditField_Q1b_OSR               matlab.ui.control.EditField
        EditField_Q1a_OSR               matlab.ui.control.EditField
        Objective1Label_2               matlab.ui.control.Label
        Objective2Label_2               matlab.ui.control.Label
        Objective3Label_3               matlab.ui.control.Label
        CheckBox_Q3b                    matlab.ui.control.CheckBox
        CheckBox_Q3a                    matlab.ui.control.CheckBox
        CheckBox_Q2c                    matlab.ui.control.CheckBox
        CheckBox_Q2a                    matlab.ui.control.CheckBox
        CheckBox_Q1f                    matlab.ui.control.CheckBox
        CheckBox_Q1e                    matlab.ui.control.CheckBox
        CheckBox_Q1d                    matlab.ui.control.CheckBox
        CheckBox_Q1b                    matlab.ui.control.CheckBox
        CheckBox_Q1a                    matlab.ui.control.CheckBox
        OSRLamp                         matlab.ui.control.Lamp
        GenerateReportButton_OSR        matlab.ui.control.Button
        ReportselectionPanel            matlab.ui.container.Panel
        RestorelistsButton              matlab.ui.control.Button
        Objective3Label                 matlab.ui.control.Label
        Objective2Label                 matlab.ui.control.Label
        Objective1Label                 matlab.ui.control.Label
        ListBox_Q3b                     matlab.ui.control.ListBox
        Q3bListBoxLabel                 matlab.ui.control.Label
        ListBox_Q3a                     matlab.ui.control.ListBox
        Q3aListBoxLabel                 matlab.ui.control.Label
        DeleteReport_Q3b                matlab.ui.control.Button
        AddReport_Q3b                   matlab.ui.control.Button
        OpenReport_Q3b                  matlab.ui.control.Button
        DeleteReport_Q3a                matlab.ui.control.Button
        AddReport_Q3a                   matlab.ui.control.Button
        OpenReport_Q3a                  matlab.ui.control.Button
        ListBox_Q2d                     matlab.ui.control.ListBox
        Q2dListBoxLabel                 matlab.ui.control.Label
        ListBox_Q2c                     matlab.ui.control.ListBox
        Q2cListBoxLabel                 matlab.ui.control.Label
        ListBox_Q2a                     matlab.ui.control.ListBox
        Q2aListBoxLabel                 matlab.ui.control.Label
        DeleteReport_Q2d                matlab.ui.control.Button
        AddReport_Q2d                   matlab.ui.control.Button
        OpenReport_Q2d                  matlab.ui.control.Button
        DeleteReport_Q2c                matlab.ui.control.Button
        AddReport_Q2c                   matlab.ui.control.Button
        OpenReport_Q2c                  matlab.ui.control.Button
        DeleteReport_Q2a                matlab.ui.control.Button
        AddReport_Q2a                   matlab.ui.control.Button
        OpenReport_Q2a                  matlab.ui.control.Button
        ListBox_Q1f                     matlab.ui.control.ListBox
        Q1fListBoxLabel                 matlab.ui.control.Label
        DeleteReport_Q1f                matlab.ui.control.Button
        AddReport_Q1f                   matlab.ui.control.Button
        OpenReport_Q1f                  matlab.ui.control.Button
        ListBox_Q1e                     matlab.ui.control.ListBox
        Q1eListBoxLabel                 matlab.ui.control.Label
        DeleteReport_Q1e                matlab.ui.control.Button
        AddReport_Q1e                   matlab.ui.control.Button
        OpenReport_Q1e                  matlab.ui.control.Button
        ListBox_Q1d                     matlab.ui.control.ListBox
        Q1dListBoxLabel                 matlab.ui.control.Label
        DeleteReportQ1d                 matlab.ui.control.Button
        AddReport_Q1d                   matlab.ui.control.Button
        OpenReport_Q1d                  matlab.ui.control.Button
        ListBox_Q1c                     matlab.ui.control.ListBox
        Q1cListBoxLabel                 matlab.ui.control.Label
        DeleteReport_Q1c                matlab.ui.control.Button
        AddReport_Q1c                   matlab.ui.control.Button
        OpenReport_Q1c                  matlab.ui.control.Button
        ListBox_Q1b                     matlab.ui.control.ListBox
        Q1bListBoxLabel                 matlab.ui.control.Label
        DeleteReport_Q1b                matlab.ui.control.Button
        AddReport_Q1b                   matlab.ui.control.Button
        OpenReport_Q1b                  matlab.ui.control.Button
        DeleteReport_Q1a                matlab.ui.control.Button
        AddReport_Q1a                   matlab.ui.control.Button
        OpenReport_Q1a                  matlab.ui.control.Button
        ListBox_Q1a                     matlab.ui.control.ListBox
        Q1aListBoxLabel                 matlab.ui.control.Label
        IQSTab                          matlab.ui.container.Tab
        WARNINGLabel_q1d_5              matlab.ui.control.Label
        GalE1bLabel                     matlab.ui.control.Label
        GPSL1CALabel                    matlab.ui.control.Label
        UITable_Q3b_Galileo             matlab.ui.control.Table
        UITable_Q3b_GPS                 matlab.ui.control.Table
        GNSSSoftwareReceiverSettingsAcquisitionStagePanel  matlab.ui.container.Panel
        CodeDopplerCheckBoxE1B          matlab.ui.control.CheckBox
        CodeDopplerCheckBoxG1C          matlab.ui.control.CheckBox
        CodeDopplerSearchLabel          matlab.ui.control.Label
        DopplerrangeHzEditField         matlab.ui.control.NumericEditField
        DopplerrangeHzEditFieldLabel    matlab.ui.control.Label
        DopplerStepEditField_3          matlab.ui.control.NumericEditField
        DopplercenteroffsetHzEditField  matlab.ui.control.NumericEditField
        DopplercenteroffsetHzEditFieldLabel  matlab.ui.control.Label
        DopplerStepEditField_2          matlab.ui.control.NumericEditField
        TelemetryInputLamp_4Label_2     matlab.ui.control.Label
        TelemetryInputLamp_4Label       matlab.ui.control.Label
        FalseAlarmProbabilityEditField_2  matlab.ui.control.NumericEditField
        CoherentIntegrationtimeEditField  matlab.ui.control.NumericEditField
        NonCoherentsumsEditField_2      matlab.ui.control.NumericEditField
        DopplerStepEditField            matlab.ui.control.NumericEditField
        E1BCheckBox                     matlab.ui.control.CheckBox
        G1CCheckBox                     matlab.ui.control.CheckBox
        FalseAlarmProbabilityEditField  matlab.ui.control.NumericEditField
        FalseAlarmProbabilityEditFieldLabel  matlab.ui.control.Label
        CoherentIntegrationtimesEditField  matlab.ui.control.NumericEditField
        CoherentIntegrationtimesEditFieldLabel  matlab.ui.control.Label
        NonCoherentsumsEditField        matlab.ui.control.NumericEditField
        NonCoherentsumsEditFieldLabel   matlab.ui.control.Label
        DopplerstepHzEditField          matlab.ui.control.NumericEditField
        DopplerstepHzEditFieldLabel     matlab.ui.control.Label
        Experiment1bLabel_21            matlab.ui.control.Label
        ScienceProductLabel_26          matlab.ui.control.Label
        runPanel_Q3b                    matlab.ui.container.Panel
        RunEditField_Q3b_3              matlab.ui.control.NumericEditField
        RunEditField_8Label_6           matlab.ui.control.Label
        SaveResultsButton_Q3b           matlab.ui.control.Button
        ReportLamp_Q3b                  matlab.ui.control.Lamp
        ResultsLamp_Q3b                 matlab.ui.control.Lamp
        GenerateReportButton_Q3b        matlab.ui.control.Button
        OperatorCommentincludedinthereportPanel_16  matlab.ui.container.Panel
        CommentArea_Q3b                 matlab.ui.control.TextArea
        OutputPanel_25                  matlab.ui.container.Panel
        TextArea_Q3b                    matlab.ui.control.TextArea
        RunExperimentButton_Q3b         matlab.ui.control.Button
        ExperimentSuccessLamp_Q3b       matlab.ui.control.Lamp
        Experiment3bLabel_3             matlab.ui.control.Label
        IQSFilebinTextArea              matlab.ui.control.TextArea
        IQSFilebinTextAreaLabel         matlab.ui.control.Label
        OverrideIQSFolder               matlab.ui.control.Button
    end

    properties (Access = private)
        %--- Names of files of parsed data
        navFileName         % navSolutions file name
        obsFileName         % obsSolutions file name
        opMetaFields        % Serial metadata fields for report
        folderName          % temporary folder for all parsed data
        iqsFile             % IQS file

        %--- Reference data structs
        refNavFileName      % navSolutions file name for reference data

        %--- Plot informations
        PRN                 % struct: PRN and the color triplet
        obsLimit            % keep track of max epochs
        obsEpochDate        % table of the Epochs dates
        navLimit
        navEpochDate

        %--- Colors
        colorGreen          % Global lamp color name
        colorYellow         % Global lamp color name
        colorRed            % Global lamp color name
        colorGray           % Global lamp color name
        colorBgGray         % Global background color name

        %--- Session information
        figurePaths         % Global repo for experiment outputs
        currentOperator     % Selected operator name/id for the current session
        currentDataset      % Take datetime of load data as a name for results folder
        currentOpsWindow    % Selected ops windows to be investigated in the current session
        sessionSettings     % Tool configuration (hard coded on .mat file)
        expCounters         % It counts the number of runs for each experiment
        expCompleted        % Keeps track of experiments sent to POC for each window
        logFileId           % Log file identifier (fopen)
        tmpConfigData       % Temporary container for experiment configuration data
    end

    properties (Access = public)
        OSR                       % It cointains metadata of relevant quick experiment reports (QER) to be included in the OSR
        OSR_reportList = [];      % Contains the path of the selected quick experiment reports (QER) to be concatenated
        currentWindowOSR;         % It stores the name of the current OP's OSR generated
    end

    methods (Access = private)

        %--- Function that updates the Console Message Text Box
        function updateConsoleBox(app,newMessage)
            consoleText = app.consoleWindow.Value;
            messageDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"] ");

            %--- Update console
            if isempty(consoleText{1})
                app.consoleWindow.Value = sprintf(strcat(messageDate,newMessage));
            else
                app.consoleWindow.Value = [app.consoleWindow.Value; strcat(messageDate,newMessage)];
            end
            %--- Print on the log file
            fprintf(app.logFileId, strcat(messageDate,newMessage,'\n'));
            %--- Scroll text area to the bottom
            scroll(app.consoleWindow, 'bottom')
        end

        function resetExpButtons(app)
            app.SaveResultsButton_Q1a.Enable = "off";
            app.GenerateReportButton_Q1a.Enable = "off";

            app.SaveResultsButton_Q1b.Enable = "off";
            app.GenerateReportButton_Q1b.Enable = "off";

            app.SaveResultsButton_Q1c.Enable = "off";
            app.GenerateReportButton_Q1c.Enable = "off";

            app.SaveResultsButton_Q1d.Enable = "off";
            app.GenerateReportButton_Q1d.Enable = "off";

            app.SaveResultsButton_Q1e.Enable = "off";
            app.GenerateReportButton_Q1e.Enable = "off";

            app.SaveResultsButton_Q1f.Enable = "off";
            app.GenerateReportButton_Q1f.Enable = "off";

            app.SaveResultsButton_Q1g.Enable = "off";
            app.GenerateReportButton_Q1g.Enable = "off";

            app.SaveResultsButton_Q1h.Enable = "off";
            app.GenerateReportButton_Q1h.Enable = "off";


            app.SaveResultsButton_Q2a.Enable = "off";
            app.GenerateReportButton_Q2a.Enable = "off";

            app.SaveResultsButton_Q2b.Enable = "off";
            app.GenerateReportButton_Q2b.Enable = "off";

            app.SaveResultsButton_Q2c.Enable = "off";
            app.GenerateReportButton_Q2c.Enable = "off";


            app.SaveResultsButton_Q3b.Enable = "off";
            app.GenerateReportButton_Q3b.Enable = "off";
        end

        function resetExpLamps(app)
            app.ExperimentSuccessLamp_Q1a.Color = app.colorGray;
            app.ResultsLamp_Q1a.Color = app.colorGray;
            app.ReportLamp_Q1a.Color = app.colorGray;

            app.ExperimentSuccessLamp_Q1b.Color = app.colorGray;
            app.ResultsLamp_Q1b.Color = app.colorGray;
            app.ReportLamp_Q1b.Color = app.colorGray;

            app.ExperimentSuccessLamp_Q1c.Color = app.colorGray;
            app.ResultsLamp_Q1c.Color = app.colorGray;
            app.ReportLamp_Q1c.Color = app.colorGray;

            app.ExperimentSuccessLamp_Q1d.Color = app.colorGray;
            app.ResultsLamp_Q1d.Color = app.colorGray;
            app.ReportLamp_Q1d.Color = app.colorGray;

            app.ExperimentSuccessLamp_Q1e.Color = app.colorGray;
            app.ResultsLamp_Q1e.Color = app.colorGray;
            app.ReportLamp_Q1e.Color = app.colorGray;

            app.ExperimentSuccessLamp_Q1f.Color = app.colorGray;
            app.ResultsLamp_Q1f.Color = app.colorGray;
            app.ReportLamp_Q1f.Color = app.colorGray;

            app.ExperimentSuccessLamp_Q1g.Color = app.colorGray;
            app.ResultsLamp_Q1g.Color = app.colorGray;
            app.ReportLamp_Q1g.Color = app.colorGray;

            app.ExperimentSuccessLamp_Q1h.Color = app.colorGray;
            app.ResultsLamp_Q1h.Color = app.colorGray;
            app.ReportLamp_Q1h.Color = app.colorGray;


            app.ExperimentSuccessLamp_Q2a.Color = app.colorGray;
            app.ResultsLamp_Q2a.Color = app.colorGray;
            app.ReportLamp_Q2a.Color = app.colorGray;

            app.ExperimentSuccessLamp_Q2b.Color = app.colorGray;
            app.ResultsLamp_Q2b.Color = app.colorGray;
            app.ReportLamp_Q2b.Color = app.colorGray;

            app.ExperimentSuccessLamp_Q2c.Color = app.colorGray;
            app.ResultsLamp_Q2c.Color = app.colorGray;
            app.ReportLamp_Q2c.Color = app.colorGray;


            app.ExperimentSuccessLamp_Q3b.Color = app.colorGray;
            app.ResultsLamp_Q3b.Color = app.colorGray;
            app.ReportLamp_Q3b.Color = app.colorGray;
        end

        function resetExpText(app)
            app.TextArea_Q1a.Value = strings;
            app.CommentArea_Q1a.Value = "No relevant comments.";

            app.TextArea_Q1b.Value = strings;
            app.CommentArea_Q1b.Value = "No relevant comments.";

            app.TextArea_Q1c.Value = strings;
            app.CommentArea_Q1c.Value = "No relevant comments.";

            app.TextArea_Q1d.Value = strings;
            app.CommentArea_Q1d.Value = "No relevant comments.";

            app.TextArea_Q1e.Value = strings;
            app.CommentArea_Q1e.Value = "No relevant comments.";

            app.TextArea_Q1f.Value = strings;
            app.CommentArea_Q1f.Value = "No relevant comments.";

            app.TextArea_Q1g.Value = strings;
            app.CommentArea_Q1g.Value = "No relevant comments.";

            app.TextArea_Q1h.Value = strings;
            app.CommentArea_Q1h.Value = "No relevant comments.";


            app.TextArea_Q2a.Value = strings;
            app.CommentArea_Q2a.Value = "No relevant comments.";

            app.TextArea_Q2b.Value = strings;
            app.CommentArea_Q2b.Value = "No relevant comments.";

            app.TextArea_Q2c.Value = strings;
            app.CommentArea_Q2c.Value = "No relevant comments.";


            app.TextArea_Q3b.Value = strings;
            app.CommentArea_Q3b.Value = "No relevant comments.";
        end      

        function resetCountersCompleted(app)
            app.RunEditField_Q1a.Value = 0;
            app.Q1a_completed.Color = app.colorGray;

            app.RunEditField_Q1b.Value = 0;
            app.Q1b_completed.Color = app.colorGray;

            app.RunEditField_Q1c.Value = 0;
            app.Q1c_completed.Color = app.colorGray;

            app.RunEditField_Q1d.Value = 0;
            app.Q1d_completed.Color = app.colorGray;

            app.RunEditField_Q1e.Value = 0;
            app.Q1e_completed.Color = app.colorGray;

            app.RunEditField_Q1f.Value = 0;
            app.Q1f_completed.Color = app.colorGray;

            app.RunEditField_Q1g.Value = 0;
            app.Q1g_completed.Color = app.colorGray;

            app.RunEditField_Q1h.Value = 0;
            app.Q1h_completed.Color = app.colorGray;

            app.RunEditField_Q2a.Value = 0;
            app.Q2a_completed.Color = app.colorGray;

            app.RunEditField_Q2b.Value = 0;
            app.Q2b_completed.Color = app.colorGray;

            app.RunEditField_Q2c.Value = 0;
            app.Q2c_completed.Color = app.colorGray;
        end

        function resetOSRPanel(app)

            app.ListBox_Q1a.Items = {};
            app.ListBox_Q1b.Items = {};
            app.ListBox_Q1c.Items = {};
            app.ListBox_Q1d.Items = {};
            app.ListBox_Q1e.Items = {};
            app.ListBox_Q1f.Items = {};
            app.ListBox_Q2a.Items = {};
            app.ListBox_Q2d.Items = {};
            app.ListBox_Q3b.Items = {};

            app.ListBox_Q1a.Value = {};
            app.ListBox_Q1b.Value = {};
            app.ListBox_Q1c.Value = {};
            app.ListBox_Q1d.Value = {};
            app.ListBox_Q1e.Value = {};
            app.ListBox_Q1f.Value = {};
            app.ListBox_Q2a.Value = {};
            app.ListBox_Q2d.Value = {};
            app.ListBox_Q3b.Value = {};

            app.CheckBox_Q1a.Value = false;
            app.CheckBox_Q1b.Value = false;
            app.CheckBox_Q1c.Value = false;
            app.CheckBox_Q1d.Value = false;
            app.CheckBox_Q1e.Value = false;
            app.CheckBox_Q1f.Value = false;
            app.CheckBox_Q2a.Value = false;
            app.CheckBox_Q2d.Value = false;
            app.CheckBox_Q3b.Value = false;

            app.EditField_Q1a_OSR.Value = "";
            app.EditField_Q1b_OSR.Value = "";
            app.EditField_Q1c_OSR.Value = "";
            app.EditField_Q1d_OSR.Value = "";
            app.EditField_Q1e_OSR.Value = "";
            app.EditField_Q1f_OSR.Value = "";
            app.EditField_Q2a_OSR.Value = "";
            app.EditField_Q2d_OSR.Value = "";
            app.EditField_Q3b_OSR.Value = "";

            app.OSR_comments.Value = "No relevant comments.";
        end

        function enableExperimentsRunButtons(app)
            app.RunExperimentButton_Q1a.Enable = true;
            app.RunExperimentButton_Q1b.Enable = true;
            app.RunExperimentButton_Q1c.Enable = true;
            app.RunExperimentButton_Q1d.Enable = true;
            app.RunExperimentButton_Q1e.Enable = true;
            app.RunExperimentButton_Q1f.Enable = true;
            app.RunExperimentButton_Q1g.Enable = true;
            %app.RunExperimentButton_Q1h.Enable = true;

            app.RunExperimentButton_Q2a.Enable = true;         
            app.RunExperimentButton_Q2b.Enable = true;
            %app.RunExperimentButton_Q2c.Enable = true;

            app.RunExperimentButton_Q3b.Enable = true;
        end

        function disableExperimentsRunButtons(app)
            app.RunExperimentButton_Q1a.Enable = false;
            app.RunExperimentButton_Q1b.Enable = false;
            app.RunExperimentButton_Q1c.Enable = false;
            app.RunExperimentButton_Q1d.Enable = false;
            app.RunExperimentButton_Q1e.Enable = false;
            app.RunExperimentButton_Q1f.Enable = false;
            app.RunExperimentButton_Q1g.Enable = false;
            app.RunExperimentButton_Q1h.Enable = false;

            app.RunExperimentButton_Q2a.Enable = false;
            app.RunExperimentButton_Q2b.Enable = false;
            app.RunExperimentButton_Q2c.Enable = false;

            app.RunExperimentButton_Q3b.Enable = false;
        end

        function updateProgressBar(app,event,d,value,msg)
            %--- Check if user clicked Cancel
            if d.CancelRequested
                %--- Close the dialog
                close(d);
                %--- Exit the loop or function
                disp('Process canceled by the user.');
                ResetSessionButtonPushed(app, event);
                return;
            else
                d.Value = value;
                d.Message = msg;
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% Add folders to path
            if (~isdeployed)
                addpath(genpath(['.' filesep]));
            end

            %% Hide OSR tab
            app.SummaryReportTab.Parent=[];


            %% Open/create log file
            try
                if isdeployed
                    baseFolder = fileparts(ctfroot);  % folder where .exe resides
                else
                    baseFolder = pwd;
                end

                %--- Utilities folder
                logFolder = fullfile(baseFolder,'utilities');

                %--- Create folder if it doesn't exist
                if ~exist(logFolder,'dir')
                    mkdir(logFolder);
                end

                %--- Open log file for appending
                logFile = fullfile(logFolder,'log.txt');
                [app.logFileId,msg] = fopen(logFile,'a');
                if app.logFileId == -1
                    error("Failed to open log file: %s", msg);
                end

                %--- Write header
                messageDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"] ");
                fprintf(app.logFileId,"########################################################################################\n");
                fprintf(app.logFileId,strcat(messageDate,"G-PEARL (v1.0) - Activities Log\n"));
                fprintf(app.logFileId,"----------------------------------------------------------------------------------------\n");

                updateConsoleBox(app,"G-PEARL is logging the session activities.");

            catch ME
                %--- Warn user but continue
                warndlg('Log file unavailable. You can still operate without logging.','Warning');
                updateConsoleBox(app,"WARNING: G-PEARL is not logging the session activities.");
                fprintf(2,"[Warning] %s\n",ME.message); % optional debug in console
            end


            %% Define colors (readiness/progress level)
            app.colorGreen = [0.00,0.56,0.06];
            app.colorYellow = [0.90,0.75,0.25];
            app.colorRed = [1.00,0.00,0.00];
            app.colorGray = [0.94,0.94,0.94];
            app.colorBgGray = [0.94,0.94,0.94];

            %% Load settings and status variables
            app.sessionSettings = parseConfigFile('settings/config.txt');

            %% Check reading/writing user privileges through folder
            %--- Check if there is write permission on input folder
            app.OutputDataEditField.Value = app.sessionSettings.outputFilePath;
            [~,outPathValues] = fileattrib(app.sessionSettings.outputFilePath);

            if exist(app.sessionSettings.outputFilePath, 'dir') && outPathValues.UserWrite
                app.OutputDataPathLamp.Color = app.colorGreen;
            else
                app.OutputDataPathLamp.Color = app.colorRed;
                updateConsoleBox(app,"[CONGIF.ERR_01] Output data path not found or no writing permission.")
            end

            %--- Notify user the tool is ready
            updateConsoleBox(app,("Startup completed, ready for session configuration."));
        end

        % Button pushed function: RunExperimentButton_Q1a
        function RunExperimentButton_Q1aPushed(app, event)
            %% Close previous plots, reset lamps and messages
            close all

            %--- First disable all experiments to avoid interference
            %(activate again when finished running)
            disableExperimentsRunButtons(app);

            app.TextArea_Q1a.Value = "Running experiment...";
            app.CommentArea_Q1a.Value = "No relevant comments.";
            app.ExperimentSuccessLamp_Q1a.Color = app.colorYellow;

            app.ReportLamp_Q1a.Color = app.colorGray;
            app.ResultsLamp_Q1a.Color = app.colorGray;

            resetExpButtons(app);
            drawnow;

            %% Constellations checkboxes
            settings.GPS = app.GPSCheckBox.Value;
            settings.GLO = app.GLOCheckBox.Value;
            settings.GAL = app.GALCheckBox.Value;
            settings.BDS = app.BDSCheckBox.Value;

            %% Run experiment
            try
                msg100 = PlotCNo(app.folderName, app.obsFileName, app.obsLimit, app.obsEpochDate, settings, 100);

                app.ExperimentSuccessLamp_Q1a.Color = app.colorGreen;
                app.SaveResultsButton_Q1a.Enable = "on";

                expDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                app.TextArea_Q1a.Value = sprintf("%s\n%s",expDate, msg100);
                fprintf(app.logFileId,strcat(expDate,' Experiment 1a ran succesfully.\n'));
            catch
                app.ExperimentSuccessLamp_Q1a.Color = app.colorRed;

                expDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                app.TextArea_Q1a.Value = sprintf("%s\nExperiment failed!",expDate);
                fprintf(app.logFileId,strcat(expDate,' Experiment 1a failed.\n'));
            end

            %--- Enable all experiments again
            enableExperimentsRunButtons(app);
        end

        % Button pushed function: RunExperimentButton_Q1b
        function RunExperimentButton_Q1bPushed(app, event)
            %% Close previous plots, reset lamps and buttons
            close all

            %--- First disable all experiments to avoid interference
            %(activate again when finished running)
            disableExperimentsRunButtons(app);

            app.TextArea_Q1b.Value = "Running experiment...";
            app.CommentArea_Q1b.Value = "No relevant comments.";
            app.ExperimentSuccessLamp_Q1b.Color = app.colorYellow;

            app.ResultsLamp_Q1b.Color = app.colorGray;
            app.ReportLamp_Q1b.Color = app.colorGray;

            resetExpButtons(app);
            drawnow;

            %% Save settings to be passed to plot function
            set.threshold = app.CN0ThresholdSpinner.Value;

            app.tmpConfigData = [string(app.CN0ThresholdSpinner.Value)];

            %% Run experiment
            try
                [msg100, msg101, msg102] = PlotAvailability(app.folderName, app.obsFileName, app.navFileName, app.obsLimit, app.obsEpochDate, set);

                msg103 = sprintf("SETTINGS:\nC/N0 thresholds [dB-Hz]: %.1f\n", set.threshold);

                app.ExperimentSuccessLamp_Q1b.Color = app.colorGreen;
                app.SaveResultsButton_Q1b.Enable = "on";

                expDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                app.TextArea_Q1b.Value = sprintf("%s\n%s\n%s\n%s\n%s",expDate, msg100, msg101, msg102, msg103);
                fprintf(app.logFileId,strcat(expDate,' Experiment 1b ran succesfully.\n'));
            catch
                app.ExperimentSuccessLamp_Q1b.Color = app.colorRed;

                expDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                app.TextArea_Q1b.Value = sprintf("%s\nExperiment failed!",expDate);
                fprintf(app.logFileId,strcat(expDate,' Experiment 1b failed.\n'));
            end

            %--- Enable all experiments again
            enableExperimentsRunButtons(app);
        end

        % Button pushed function: GenerateReportButton_Q1a
        function GenerateReportButton_Q1aPushed(app, event)
            import mlreportgen.dom.*

            app.ReportLamp_Q1a.Color = app.colorYellow;
            drawnow;

            try
                for iFig = 1:length(app.figurePaths)
                    figures(iFig) = Image(app.figurePaths(iFig));
                end
                runId = strcat("r",sprintf("%02d",(app.expCounters(1))));
                resultsFolderName = fullfile(app.sessionSettings.outputFilePath, filesep, app.currentDataset, filesep, "exp_1a", filesep, runId);
         
                %--- Document metadata fields
                genTime = string(datetime("now"));
                reportID = string(generateReportSerial([app.opMetaFields, genTime]));

                %--- Report text content
                repTestFields = [genTime, reportID, "1a", string(app.expCounters(1)), app.currentOperator, app.opMetaFields];

                [msg00] = genReport(resultsFolderName, strcat('1a_',reportID), 'rptTmpl\1a_G-PEARL_tmpl.dotx', repTestFields, figures, app.TextArea_Q1a.Value, app.CommentArea_Q1a.Value);

                % ---- if flagged -> Operation Science Report (OSR)
                msg01 = makeAvailable2OSR(app.currentDataset, '1a', resultsFolderName, strcat('1a_',reportID), [genTime, reportID], app);
                OSR_data = app.OSR;
                save('settings\OSR.mat', "OSR_data");

                %--- Update lamps and buttons
                app.ReportLamp_Q1a.Color = app.colorGreen;
                app.GenerateReportButton_Q1a.Enable = "off";
                %--- Experiment has been completed
                app.expCompleted(1) = true;
                app.Q1a_completed.Color = app.colorGreen;

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nReport saved in: %s\n%s", saveDate, resultsFolderName, msg01);
                app.TextArea_Q1a.Value = [app.TextArea_Q1a.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Report of experiment 1a saved (Run ID: %d)\n", saveDate, app.expCounters(1)));
            catch
                app.ReportLamp_Q1a.Color = app.colorRed;

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nError saving report", saveDate);
                app.TextArea_Q1a.Value = [app.TextArea_Q1a.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Error saving report of experiment 1a (Run ID: %d)\n", saveDate, app.expCounters(1)));
            end

            scroll(app.TextArea_Q1a, 'bottom');
        end

        % Button pushed function: RunExperimentButton_Q1c
        function RunExperimentButton_Q1cPushed(app, event)
            %% Close previous plots, reset lamps and buttons
            close all;

            %--- First disable all experiments to avoid interference
            %(activate again when finished running)
            disableExperimentsRunButtons(app);

            app.TextArea_Q1c.Value = "Running experiment...";
            app.CommentArea_Q1c.Value = "No relevant comments.";
            app.ExperimentSuccessLamp_Q1c.Color = app.colorYellow;

            app.ResultsLamp_Q1c.Color = app.colorGray;
            app.ReportLamp_Q1c.Color = app.colorGray;

            resetExpButtons(app);
            drawnow;

            %% Constellations checkboxes
            settings.GPS = app.GPSCheckBox.Value;
            settings.GLO = app.GLOCheckBox.Value;
            settings.GAL = app.GALCheckBox.Value;
            settings.BDS = app.BDSCheckBox.Value;

            %% Run Experiment
            try
                msg100 = PlotRawPseudoranges(app.folderName, app.obsFileName, app.obsLimit, app.obsEpochDate, settings, 120);

                app.ExperimentSuccessLamp_Q1c.Color = app.colorGreen;
                app.SaveResultsButton_Q1c.Enable = "on";

                expDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");       
                app.TextArea_Q1c.Value = sprintf("%s\n%s",expDate, msg100);
                fprintf(app.logFileId,strcat(expDate,' Experiment 1c ran succesfully.\n'));
            catch
                app.ExperimentSuccessLamp_Q1c.Color = app.colorRed;

                expDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                app.TextArea_Q1c.Value = sprintf("%s\nExperiment failed!",expDate);
                fprintf(app.logFileId,strcat(expDate,' Experiment 1c failed.\n'));
            end

            %--- Enable all experiments again
            enableExperimentsRunButtons(app);
        end

        % Button pushed function: RunExperimentButton_Q1e
        function RunExperimentButton_Q1ePushed(app, event)
            %% Close previous plots, reset lamps and buttons
            close all

            %--- First disable all experiments to avoid interference
            disableExperimentsRunButtons(app);

            app.TextArea_Q1e.Value = "Running experiment...";
            app.CommentArea_Q1e.Value = "No relevant comments.";
            app.ExperimentSuccessLamp_Q1e.Color = app.colorYellow;

            app.ReportLamp_Q1e.Color = app.colorGray;
            app.ResultsLamp_Q1e.Color = app.colorGray;

            resetExpButtons(app);
            drawnow;

            %% Constellations checkboxes
            settings.GPS = app.GPSCheckBox.Value;
            settings.GLO = app.GLOCheckBox.Value;
            settings.GAL = app.GALCheckBox.Value;
            settings.BDS = app.BDSCheckBox.Value;

            %% Run experiment
            try
                msg100 = PlotDopplerShift(app.folderName, app.obsFileName, app.obsLimit, app.obsEpochDate, settings, 140);

                app.ExperimentSuccessLamp_Q1e.Color = app.colorGreen;
                app.SaveResultsButton_Q1e.Enable = "on";

                expDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                app.TextArea_Q1e.Value = sprintf("%s\n%s",expDate, msg100);
                fprintf(app.logFileId,strcat(expDate,' Experiment 1e ran succesfully.\n'));
            catch
                app.ExperimentSuccessLamp_Q1e.Color = app.colorRed;

                expDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                app.TextArea_Q1e.Value = sprintf("%s\nExperiment failed!",expDate);
                fprintf(app.logFileId,strcat(expDate,' Experiment 1e failed.\n'));
            end

            %--- Enable all experiments again
            enableExperimentsRunButtons(app);
        end

        % Button pushed function: SaveResultsButton_Q1a
        function SaveResultsButton_Q1aPushed(app, event)
            app.ResultsLamp_Q1a.Color = app.colorYellow;
            drawnow;

            try
                %--- Save figures
                runId = strcat("r",sprintf("%02d",(app.expCounters(1) + 1)));
                %--- datetime dropped-off because does not match with input data folder
                resultsFolderName = fullfile(app.sessionSettings.outputFilePath,filesep,app.currentDataset,filesep,"exp_1a",filesep,runId);   % Your destination folder
                app.figurePaths = saveFigs(resultsFolderName);

                %--- update run counters
                app.expCounters(1) = app.expCounters(1) + 1;
                app.RunEditField_Q1a.Value = app.expCounters(1);

                app.ResultsLamp_Q1a.Color = app.colorGreen;
                app.GenerateReportButton_Q1a.Enable = "on";
                app.SaveResultsButton_Q1a.Enable = "off";

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nResults saved in: %s", saveDate, resultsFolderName);
                app.TextArea_Q1a.Value = [app.TextArea_Q1a.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Results of experiment 1a saved (Run ID: %d)\n", saveDate, app.expCounters(1)));
            catch
                app.ResultsLamp_Q1a.Color = app.colorRed;

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nError saving results", saveDate);
                app.TextArea_Q1a.Value = [app.TextArea_Q1a.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Error saving results of experiment 1a (Run ID: %d)\n", saveDate, app.expCounters(1)));
            end

            scroll(app.TextArea_Q1a, 'bottom');
        end

        % Value changed function: CN0ThresholdSpinner
        function CN0ThresholdSpinnerValueChanged(app, event)
            if app.LinkvaluesCheckBox.Value == true
                value = event.Source.Value;

                app.CN0ThresholdSpinner.Value = value;
                app.GPSL5Spinner.Value = value;
                app.GALE1Spinner.Value = value;
                app.GALE5aSpinner.Value = value;
            end
        end

        % Button pushed function: SaveResultsButton_Q1b
        function SaveResultsButton_Q1bPushed(app, event)
            app.ResultsLamp_Q1b.Color = app.colorYellow;
            drawnow;

            try
                %--- Save figures
                runId = strcat("r",sprintf("%02d",(app.expCounters(2) + 1)));
                resultsFolderName = strcat(app.sessionSettings.outputFilePath,filesep,app.currentDataset,filesep,"exp_1b",filesep,runId);   % Your destination folder
                app.figurePaths = saveFigs(resultsFolderName);

                % save the configuration
                CNoThresholds = app.tmpConfigData;  % Extract the field
                save(strcat(resultsFolderName, filesep,"expSettings"), 'CNoThresholds');

                % update run counters
                app.expCounters(2) = app.expCounters(2) + 1;
                % update run counters field on GUI
                app.RunEditField_Q1b.Value = app.expCounters(2);

                % % flush the temporary container for configuration data
                % app.tmpConfigData=[];
                app.ResultsLamp_Q1b.Color = app.colorGreen;
                app.GenerateReportButton_Q1b.Enable = "on";
                app.SaveResultsButton_Q1b.Enable = "off";

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nResults saved in: %s", saveDate, resultsFolderName);
                app.TextArea_Q1b.Value = [app.TextArea_Q1b.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Results of experiment 1b saved (Run ID: %d)\n", saveDate, app.expCounters(2)));
            catch
                app.ResultsLamp_Q1b.Color = app.colorRed;

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nError saving results", saveDate);
                app.TextArea_Q1b.Value = [app.TextArea_Q1b.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Error saving results of experiment 1b (Run ID: %d)\n", saveDate, app.expCounters(2)));
            end

            scroll(app.TextArea_Q1b, 'bottom');
        end

        % Button pushed function: SaveResultsButton_Q1c
        function SaveResultsButton_Q1cPushed(app, event)
            app.ResultsLamp_Q1c.Color = app.colorYellow;
            drawnow;

            try
                %--- Save figures
                runId = strcat("r",sprintf("%02d",(app.expCounters(3) + 1)));
                resultsFolderName = fullfile(app.sessionSettings.outputFilePath,filesep,app.currentDataset,filesep,"exp_1c",filesep,runId);   % Your destination folder
                app.figurePaths = saveFigs(resultsFolderName);

                %--- update run counters
                app.expCounters(3) = app.expCounters(3) + 1;
                app.RunEditField_Q1c.Value = app.expCounters(3);

                app.ResultsLamp_Q1c.Color = app.colorGreen;
                app.GenerateReportButton_Q1c.Enable = "on";
                app.SaveResultsButton_Q1c.Enable = "off";

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nResults saved in: %s", saveDate, resultsFolderName);
                app.TextArea_Q1c.Value = [app.TextArea_Q1c.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Results of experiment 1c saved (Run ID: %d)\n", saveDate, app.expCounters(3)));
            catch
                app.ResultsLamp_Q1c.Color = app.colorRed;

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nError saving results", saveDate);
                app.TextArea_Q1c.Value = [app.TextArea_Q1c.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Error saving results of experiment 1c (Run ID: %d)\n", saveDate, app.expCounters(3)));
            end

            scroll(app.TextArea_Q1c, 'bottom');
        end

        % Button pushed function: SaveResultsButton_Q1e
        function SaveResultsButton_Q1ePushed(app, event)
            app.ResultsLamp_Q1e.Color = app.colorYellow;
            drawnow;

            try
                % Save figures
                runId = strcat("r",sprintf("%02d",(app.expCounters(5) + 1)));
                resultsFolderName = fullfile(app.sessionSettings.outputFilePath,filesep,app.currentDataset,filesep,"exp_1e",filesep,runId);   % Your destination folder
                app.figurePaths = saveFigs(resultsFolderName);

                %--- update run counters
                app.expCounters(5) = app.expCounters(5) + 1;
                app.RunEditField_Q1e.Value = app.expCounters(5);

                app.ResultsLamp_Q1e.Color = app.colorGreen;
                app.GenerateReportButton_Q1e.Enable = "on";
                app.SaveResultsButton_Q1e.Enable = "off";

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nResults saved in: %s", saveDate, resultsFolderName);
                app.TextArea_Q1e.Value = [app.TextArea_Q1e.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Results of experiment 1e saved (Run ID: %d)\n", saveDate, app.expCounters(5)));
            catch
                app.ResultsLamp_Q1e.Color = app.colorRed;

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nError saving results", saveDate);
                app.TextArea_Q1e.Value = [app.TextArea_Q1e.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Error saving results of experiment 1e (Run ID: %d)\n", saveDate, app.expCounters(5)));
            end

            scroll(app.TextArea_Q1e, 'bottom');
        end

        % Button pushed function: ResetSessionButton
        function ResetSessionButtonPushed(app, event)
            %--- Ask user confirmation
            YN = uiconfirm(app.AnalysisToolUIFigure,'Do you want to reset the session?', 'Operation reset request');

            if strcmpi(YN,'OK')
                close all;
                clc;

                %% Clear all paths and corresponding UI elements
                app.obsFileName = [];
                app.navFileName = [];

                %% Reset all color lamps
                app.RINEXObsLamp.Color = app.colorGray;
                app.NMEANavLamp.Color = app.colorGray;

                %% Lock and reset experiemnts
                disableExperimentsRunButtons(app);
                resetExpButtons(app);
                resetExpLamps(app);
                resetExpText(app);

                %--- Reset ID counters and lamps of completion
                resetCountersCompleted(app);

                %--- Other UI elements in experiments
                app.UITable_Q1d.Data = {};
                app.UITable_Q1f_L1.Data = {};
                app.UITable_Q1f_L5.Data = {};
                app.UITable_Q3b_GPS.Data = {};
                app.UITable_Q3b_Galileo.Data = {};

                %% Reset log window
                updateConsoleBox(app,"Session has been reset.");
                app.consoleWindow.Value = "";

                %% Settings panels reset
                app.UserTextArea.Editable = false;

                app.LoadDataButton.Value = false;
                app.LoadDataButton.Enable = true;
   
                %% Clear variables and UI elements of the plots tab
                app.obsLimit     = [];
                app.obsEpochDate = [];
                app.navLimit     = [];
                app.navEpochDate = [];

                %--- Other variables
                app.figurePaths = [];

                %--- Reset OSR panel
                resetOSRPanel(app);

                %--- Close the log file
                fclose all;

                %% Reload the app
                startupFcn(app);
            end
        end

        % Button pushed function: RunExperimentButton_Q2a
        function RunExperimentButton_Q2aPushed(app, event)
            %% Close previous plots, reset lamps and buttons
            close all

            %--- First disable all experiments to avoid interference
            %(activate again when finished running)
            disableExperimentsRunButtons(app);

            app.TextArea_Q2a.Value = "Running experiment...";
            app.CommentArea_Q2a.Value = "No relevant comments.";
            app.ExperimentSuccessLamp_Q2a.Color = app.colorYellow;

            app.ResultsLamp_Q2a.Color = app.colorGray;
            app.ReportLamp_Q2a.Color = app.colorGray;

            resetExpButtons(app);
            drawnow;

            %% Reference Frame Settings
            settings = app.ReferenceFrame_Q2a.Value;

            %% Run experiment
            try
                %--- If NAV is not present, make it catch
                assert(~isempty(app.navFileName));

                if ~isempty(app.refNavFileName)
                    [msg100] = PlotMapPosition(app.folderName, app.navFileName, app.navLimit, app.navEpochDate, 200, app.refNavFileName);
                    [msg102] = PlotPosition(app.folderName, app.navFileName, app.navLimit, app.navEpochDate, settings, 201, app.refNavFileName);
                    %[msg103] = PlotVelocity(app.folderName, app.navFileName, app.obsLimit, app.obsEpochDate, 202, settings, app.refNavFileName);
                    msg101 = "";
                else
                    [msg100] = PlotMapPosition(app.folderName, app.navFileName, app.navLimit, app.navEpochDate, 200);
                    [msg102] = PlotPosition(app.folderName, app.navFileName, app.navLimit, app.navEpochDate, settings, 201);
                    %[msg103] = PlotVelocity(app.folderName, app.navFileName, app.obsLimit, app.obsEpochDate, 202, settings);
                    msg101 = "Warning: No reference trajectory";
                end
                %[msg104] = PlotClocks(app.folderName, app.navFileName, app.obsLimit, app.obsEpochDate, 203);
                %[msg105] = PlotStd(app.folderName, app.navFileName, app.obsLimit, app.obsEpochDate, 204);

                app.ExperimentSuccessLamp_Q2a.Color = app.colorGreen;
                app.SaveResultsButton_Q2a.Enable = "on";

                expDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                app.TextArea_Q2a.Value = sprintf("%s\n%s\n%s\n%s",expDate, msg100, msg101, msg102);
                fprintf(app.logFileId,strcat(expDate,' Experiment 2a ran succesfully.\n'));
            catch
                app.ExperimentSuccessLamp_Q2a.Color = app.colorRed;

                expDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                app.TextArea_Q2a.Value = sprintf("%s\nExperiment failed!",expDate);
                fprintf(app.logFileId,strcat(expDate,' Experiment 2a failed.\n'));
            end

            %--- Enable all experiments again
            enableExperimentsRunButtons(app);
        end

        % Button pushed function: SaveResultsButton_Q2a
        function SaveResultsButton_Q2aPushed(app, event)
            app.ResultsLamp_Q2a.Color = app.colorYellow;
            drawnow;

            try
                %--- Save figures
                runId = strcat("r",sprintf("%02d",(app.expCounters(9) + 1)));
                resultsFolderName = fullfile(app.sessionSettings.outputFilePath,filesep,app.currentDataset,filesep,"exp_2a",filesep,runId);   % Your destination folder
                app.figurePaths = saveFigs(resultsFolderName);

                %--- update run counters
                app.expCounters(9) = app.expCounters(9) + 1;
                app.RunEditField_Q2a.Value = app.expCounters(9);

                app.ResultsLamp_Q2a.Color = app.colorGreen;
                app.GenerateReportButton_Q2a.Enable = "on";
                app.SaveResultsButton_Q2a.Enable = "off";

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nResults saved in: %s", saveDate, resultsFolderName);
                app.TextArea_Q2a.Value = [app.TextArea_Q2a.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Results of experiment 2a saved (Run ID: %d)\n", saveDate, app.expCounters(9)));
            catch
                app.ResultsLamp_Q2a.Color = app.colorRed;

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nError saving results", saveDate);
                app.TextArea_Q2a.Value = [app.TextArea_Q2a.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Error saving results of experiment 2a (Run ID: %d)\n", saveDate, app.expCounters(9)));
            end

            scroll(app.TextArea_Q2a, 'bottom');
        end

        % Button pushed function: RunExperimentButton_Q2b
        function RunExperimentButton_Q2bPushed(app, event)
            %% Close previous plots, reset lamps and buttons
            close all

            %--- First disable all experiments to avoid interference
            %(activate again when finished running)
            disableExperimentsRunButtons(app);

            app.TextArea_Q2b.Value = "Running experiment...";
            app.CommentArea_Q2b.Value = "No relevant comments.";
            app.ExperimentSuccessLamp_Q2b.Color = app.colorYellow;

            app.ResultsLamp_Q2b.Color = app.colorGray;
            app.ReportLamp_Q2b.Color = app.colorGray;

            resetExpButtons(app);
            drawnow;          

            %% Run experiment
            try
                %--- If NAV is not present, make it catch
                assert(~isempty(app.navFileName));

                msg100 = PlotDOP(app.folderName, app.navFileName, app.navLimit, app.navEpochDate, 210, app.refNavFileName);

                app.ExperimentSuccessLamp_Q2b.Color = app.colorGreen;
                app.SaveResultsButton_Q2b.Enable = "on";

                expDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                app.TextArea_Q2b.Value = sprintf("%s\n%s", expDate, msg100);
                fprintf(app.logFileId,strcat(expDate,' Experiment 2b ran succesfully.\n'));
            catch
                app.ExperimentSuccessLamp_Q2b.Color = app.colorRed;

                expDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                app.TextArea_Q2b.Value = sprintf("%s\nExperiment failed!", expDate);
                fprintf(app.logFileId,strcat(expDate,' Experiment 2b failed.\n'));
            end

            %--- Enable all experiments again
            enableExperimentsRunButtons(app);
        end

        % Button pushed function: SaveResultsButton_Q2b
        function SaveResultsButton_Q2bPushed(app, event)
            app.ResultsLamp_Q2b.Color = app.colorYellow;
            drawnow;

            try
                % Save figures
                runId = strcat("r",sprintf("%02d",(app.expCounters(10) + 1)));
                % datetime dropped-off because does not match with input data folder
                resultsFolderName = fullfile(app.sessionSettings.outputFilePath,filesep,app.currentDataset,filesep,"exp_2d",filesep,runId);   % Your destination folder
                app.figurePaths = saveFigs(resultsFolderName);

                % update run counters
                app.expCounters(10) = app.expCounters(10) + 1;
                app.RunEditField_Q2b.Value = app.expCounters(10);

                app.ResultsLamp_Q2b.Color = app.colorGreen;
                app.GenerateReportButton_Q2b.Enable = "on";
                app.SaveResultsButton_Q2b.Enable = "off";

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nResults saved in: %s", saveDate, resultsFolderName);
                app.TextArea_Q2b.Value = [app.TextArea_Q2b.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Results of experiment 2b saved (Run ID: %d)\n", saveDate, app.expCounters(10)));
            catch
                app.ResultsLamp_Q2b.Color = app.colorRed;

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nError saving results", saveDate);
                app.TextArea_Q2b.Value = [app.TextArea_Q2b.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Error saving results of experiment 2b (Run ID: %d)\n", saveDate, app.expCounters(10)));
            end

            scroll(app.TextArea_Q2b, 'bottom');
        end

        % Button pushed function: RunExperimentButton_Q1d
        function RunExperimentButton_Q1dPushed(app, event)
            %% Close previous plots, reset lamps and buttons
            close all

            %--- First disable all experiments to avoid interference
            %(activate again when finished running)
            disableExperimentsRunButtons(app);

            app.TextArea_Q1d.Value = "Running experiment...";
            app.CommentArea_Q1d.Value = "No relevant comments.";
            app.ExperimentSuccessLamp_Q1d.Color = app.colorYellow;

            app.ReportLamp_Q1d.Color = app.colorGray;
            app.ResultsLamp_Q1d.Color = app.colorGray;

            resetExpButtons(app);

            %% Reset other UI elements
            app.UITable_Q1d.Data = {};
            drawnow;

            %% Constellations checkboxes
            settings.GPS = app.GPSCheckBox.Value;
            settings.GLO = app.GLOCheckBox.Value;
            settings.GAL = app.GALCheckBox.Value;
            settings.BDS = app.BDSCheckBox.Value;

            %% Run experiment
            try
                [msg100, msg101, msg102, statTable] = PlotDiffDiff(app.folderName, app.obsFileName, app.obsLimit, app.obsEpochDate, settings, 130);

                app.UITable_Q1d.Data = statTable;

                h = figure(132);
                uitable('Data',statTable{:,:},'ColumnName',statTable.Properties.VariableNames,'RowName',statTable.Properties.RowNames,'Units', 'Normalized', 'Position',[0, 0, 1, 1]);
                Pix_SS = get(0,'screensize');
                h.OuterPosition = [3*Pix_SS(3)/4 0 Pix_SS(3)/4 Pix_SS(4)/2];
                h.WindowState = 'minimized';

                app.ExperimentSuccessLamp_Q1d.Color = app.colorGreen;
                app.SaveResultsButton_Q1d.Enable = "on";

                expDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                app.TextArea_Q1d.Value = sprintf("%s\n%s\n%s\n%s",expDate, msg100, msg101, msg102);
                fprintf(app.logFileId,strcat(expDate,' Experiment 1d ran succesfully.\n'));
            catch
                app.ExperimentSuccessLamp_Q1d.Color = app.colorRed;

                expDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                app.TextArea_Q1d.Value = sprintf("%s\n Experiment failed!",expDate);
                fprintf(app.logFileId,strcat(expDate,' Experiment 1d failed.\n'));
            end

            %--- Enable all experiments again
            enableExperimentsRunButtons(app);
        end

        % Button pushed function: SaveResultsButton_Q1d
        function SaveResultsButton_Q1dPushed(app, event)
            app.ResultsLamp_Q1d.Color = app.colorYellow;
            drawnow;

            try
                % Save figures
                runId = strcat("r",sprintf("%02d",(app.expCounters(4) + 1)));
                resultsFolderName = fullfile(app.sessionSettings.outputFilePath,filesep,app.currentDataset,filesep,"exp_1d",filesep,runId);   % Your destination folder
                app.figurePaths = saveFigs(resultsFolderName);

                % Save table
                statTable=app.UITable_Q1d.Data;
                if istable(statTable)
                    writetable(statTable,fullfile(resultsFolderName, "statTable.xlsx"));
                else
                    writecell(statTable,fullfile(resultsFolderName, "statTable.xlsx"));
                end

                %--- update run counters
                app.expCounters(4) = app.expCounters(4) + 1;
                app.RunEditField_Q1d.Value = app.expCounters(4);

                app.ResultsLamp_Q1d.Color = app.colorGreen;
                app.GenerateReportButton_Q1d.Enable = "on";
                app.SaveResultsButton_Q1d.Enable = "off";

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nResults saved in: %s", saveDate, resultsFolderName);
                app.TextArea_Q1d.Value = [app.TextArea_Q1d.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Results of experiment 1d saved (Run ID: %d)\n", saveDate, app.expCounters(4)));
            catch
                app.ResultsLamp_Q1d.Color = app.colorRed;

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nError saving results", saveDate);
                app.TextArea_Q1d.Value = [app.TextArea_Q1d.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Error saving results of experiment 1d (Run ID: %d)\n", saveDate, app.expCounters(4)));
            end

            scroll(app.TextArea_Q1d, 'bottom');
        end

        % Value changed function: LoadDataButton
        function LoadDataButtonValueChanged(app, event)
            %% Check if OP already exists and ask user if they want to parse again
            app.folderName = append(".",filesep,"data");

            if ~exist(app.folderName, 'dir')               
                mkdir(app.folderName);
            end

            %% Reset elements
            %--- Disable itself
            app.LoadDataButton.Enable = false;
            %--- Update lamps
            app.RINEXObsLamp.Color = app.colorYellow;

            %% Update variables
            %--- Save current operator, op window and location
            app.UserTextArea.Editable = false;
            app.currentOperator = string(app.UserTextArea.Value);

            %--- Update OSR list
            app.OSRLamp.Color = app.colorGray;
            app.OSR_reportList = cell(1, 12);

            %--- Notify of successful change of settings
            updateConsoleBox(app,sprintf("Session configured successfully. Current Operator: %s",app.currentOperator));

            drawnow;

            %% Load already parsed data
            %% Parse and load
            %--- Wait bar to show progress
            d = uiprogressdlg(app.AnalysisToolUIFigure,'Title','Please Wait',...
                'Message','Loading mission data','Cancelable','on');

            %--- Load Telemetry, Config & Spirent files
            updateConsoleBox(app,"RINEX data parsing. It may take a few seconds...");
            drawnow;

            updateProgressBar(app,event,d,1/10,'Parsing RINEX obs file...');
            try
                %--- Extract metadata from RINEX header
                app.opMetaFields = rinexReadHeader(string(app.RINEXObsTextArea.Value));              
                %--- Custom RINEX parser (based on rinexread)
                [obsSolutions] = RinexParser(string(app.RINEXObsTextArea.Value));
                %--- Save local copy of parsed data
                app.obsFileName = 'obsSolutions.mat';
                save(strcat(app.folderName,'\',app.obsFileName),'obsSolutions');

                app.RINEXObsLamp.Color = app.colorGreen;
                updateConsoleBox(app,"RINEX obs data saved succesfully");
            catch
                app.RINEXObsLamp.Color = app.colorRed;
                updateConsoleBox(app,"[CONGIF.ERR_04] RINEX obs data parsing failed.");
            end

            updateProgressBar(app,event,d,6/10,'Parsing NMEA nav data...');
            try
                %--- Parse NMEA log file
                [navSolutions] = NavParser(string(app.NMEATextArea.Value));
                app.navFileName = 'navSolutions.mat';
                save(strcat(app.folderName,'\',app.navFileName),'navSolutions');

                app.NMEANavLamp.Color = app.colorGreen;
                updateConsoleBox(app,"NMEA data saved succesfully");
            catch
                app.NMEANavLamp.Color = app.colorRed;
                updateConsoleBox(app,"[CONGIF.ERR_03] NMEA data parsing failed.");
            end

            %--- Save output data to ASI/PoliTo data-structure
            updateProgressBar(app,event,d,8/10,'Creating internal structures...');

            %% Crate time series and other useful structures
            try
                %--- Index limites
                [app.navLimit, app.obsLimit] = GetLimit(app.folderName, app.obsFileName, app.navFileName);
                %--- Epoch dates
                [app.navEpochDate, app.obsEpochDate] = GetEpochDate(app.folderName, app.obsFileName, app.navFileName);

                %--- Update UI
                updateConsoleBox(app,"Experimental setup preparation completed successfully.")
                enableExperimentsRunButtons(app);
            catch
                updateConsoleBox(app,"[CONGIF.ERR_07] Experimental setup preparation failed.")
            end

            % %% IQS
            % %--- Check for presence of IQS data  
            % app.iqsFile = string(app.IQSFilebinTextArea.Value);
            % 
            % if ~isempty(app.iqsFile)
            %     %--- Enable IQS experiment even if TLM data loading failed
            %     app.RunExperimentButton_Q3b.Enable = true;
            % 
            %     app.IQSamplesLamp.Color = app.colorGreen;
            %     updateConsoleBox(app,"IQS data preparation completed successfully.")
            % else
            %     app.IQSamplesLamp.Color = app.colorRed;
            % end

            %% Other tasks
            %--- Create folder for results
            app.currentDataset = string(datetime('now','Format','y-MMM-dd_HH-mm-ss'));
            %--- Reset exp counters 
            app.expCounters = zeros(1,20);
            app.expCompleted = false(1,20);

            %% Final taks
            try
                %--- Close wait bar
                d.Value = 10/10;
                d.Message = 'Loading completed.';
                close(d);
            catch
            end
        end

        % Close request function: AnalysisToolUIFigure
        function AnalysisToolUIFigureCloseRequest(app, event)
            YN = uiconfirm(app.AnalysisToolUIFigure,'Do you want to close the tool?', 'Close request',"Icon","warning");

            if strcmpi(YN,'OK')

                %OSR_data = app.OSR;
                %save('settings\OSR.mat', 'OSR_data');

                %fclose(app.logFileId);

                fclose all;
                close all force;
                delete(app);
            end
        end

        % Button pushed function: GenerateReportButton_Q1b
        function GenerateReportButton_Q1bPushed(app, event)
            import mlreportgen.dom.*

            app.ReportLamp_Q1b.Color = app.colorYellow;
            drawnow % force lamp color change

            try
                for iFig = 1:length(app.figurePaths)
                    figures(iFig) = Image(app.figurePaths(iFig));
                end         
                runId = strcat("r",sprintf("%02d",(app.expCounters(2))));
                resultsFolderName = strcat(app.sessionSettings.outputFilePath,filesep,app.currentDataset,filesep,"exp_1b",filesep,runId);

                %--- Document metadata fields
                genTime = string(datetime("now"));
                reportID = string(generateReportSerial([app.opMetaFields, genTime]));

                %--- Report text content
                repTestFields = [genTime, reportID, "1b", string(app.expCounters(2)), app.currentOperator, app.opMetaFields];

                [msg00] = genReport(resultsFolderName, strcat('1b_',reportID), 'rptTmpl\1b_G-PEARL_tmpl.dotx', repTestFields, figures, app.TextArea_Q1b.Value, app.CommentArea_Q1b.Value);

                % ---- if flagged -> Operation Science Report (OSR)
                msg01 = makeAvailable2OSR(app.currentDataset,'1b',resultsFolderName,strcat('1b_',reportID),docMetaFields,app);
                OSR_data = app.OSR;
                save('settings\OSR.mat', "OSR_data");

                %---
                app.ReportLamp_Q1b.Color = app.colorGreen;
                app.GenerateReportButton_Q1b.Enable = "off";

                %--- Experiment has been completed
                app.expCompleted(2) = true;
                app.Q1b_completed.Color = app.colorGreen;

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nReport saved in: %s\n%s", saveDate, resultsFolderName, msg01);
                app.TextArea_Q1b.Value = [app.TextArea_Q1b.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Report of experiment 1b saved (Run ID: %d)\n", saveDate, app.expCounters(2)));
            catch
                app.ReportLamp_Q1b.Color = app.colorRed;

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nError saving report", saveDate);
                app.TextArea_Q1b.Value = [app.TextArea_Q1b.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Error saving report of experiment 1b (Run ID: %d)\n", saveDate, app.expCounters(2)));
            end

            scroll(app.TextArea_Q1b, 'bottom');
        end

        % Size changed function: eTab
        function eTabSizeChanged(app, event)
            position = app.eTab.Position;

        end

        % Button pushed function: GenerateReportButton_Q1c
        function GenerateReportButton_Q1cPushed(app, event)
            import mlreportgen.dom.*

            app.ReportLamp_Q1c.Color = app.colorYellow;
            drawnow;

            try
                for iFig = 1:length(app.figurePaths)
                    figures(iFig) = Image(app.figurePaths(iFig));
                end
                runId = strcat("r",sprintf("%02d",(app.expCounters(3))));
                resultsFolderName = strcat(app.sessionSettings.outputFilePath,filesep,app.currentDataset,filesep,"exp_Q1c",filesep,runId);

                %--- Document metadata fields
                genTime = string(datetime("now"));
                reportID = string(generateReportSerial([app.opMetaFields, genTime]));

                %--- Report text content
                repTestFields = [genTime, reportID, "1c", string(app.expCounters(3)), app.currentOperator, app.opMetaFields];

                [msg00] = genReport(resultsFolderName, strcat('1c_',reportID), 'rptTmpl\1c_G-PEARL_tmpl.dotx', repTestFields, figures, app.TextArea_Q1c.Value, app.CommentArea_Q1c.Value);

                % ---- if flagged -> Operation Science Report (OSR)
                msg01 = makeAvailable2OSR(app.currentDataset,'Q1c',resultsFolderName,strcat('Q1c_',reportID),docMetaFields,app);
                OSR_data = app.OSR;
                save('settings\OSR.mat', "OSR_data");

                % ----
                app.ReportLamp_Q1c.Color = app.colorGreen;
                app.GenerateReportButton_Q1c.Enable = "off";

                %--- Experiment has been completed
                app.expCompleted(3) = true;
                app.Q1c_completed.Color = app.colorGreen;

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nReport saved in: %s\n%s", saveDate, resultsFolderName, msg01);
                app.TextArea_Q1c.Value = [app.TextArea_Q1c.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Report of experiment Q1c saved (Run ID: %d)\n", saveDate, app.expCounters(3)));
            catch
                app.ReportLamp_Q1c.Color = app.colorRed;

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nError saving report", saveDate);
                app.TextArea_Q1c.Value = [app.TextArea_Q1c.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Error saving report of experiment Q1c (Run ID: %d)\n", saveDate, app.expCounters(3)));
            end

            scroll(app.TextArea_Q1c, 'bottom');
        end

        % Button pushed function: RunExperimentButton_Q1f
        function RunExperimentButton_Q1fPushed(app, event)
            %% Close previous plots, reset lamps and buttons
            close all

            %--- First disable all experiments to avoid interference
            disableExperimentsRunButtons(app);

            app.TextArea_Q1f.Value = "Running experiment...";
            app.CommentArea_Q1f.Value = "No relevant comments.";
            app.ExperimentSuccessLamp_Q1f.Color = app.colorYellow;

            app.ResultsLamp_Q1f.Color = app.colorGray;
            app.ReportLamp_Q1f.Color = app.colorGray;

            resetExpButtons(app);
            app.UITable_Q1f_L1.Data = {};
            app.UITable_Q1f_L5.Data = {};
            drawnow;

            %% Save settings
            %--- Constellations Checkboxes
            settings.GPS = app.GPSCheckBox.Value;
            settings.GLO = app.GLOCheckBox.Value;
            settings.GAL = app.GALCheckBox.Value;
            settings.BDS = app.BDSCheckBox.Value;
            %--- Other Settings
            settings.findOutliers = app.FindOutliersCheckBox.Value;
            settings.window = app.WindowSizeSpinner.Value;
            settings.threshold = app.ThresholdSpinner.Value;

            %% Run experiment
            try
                [L1outliers, L5outliers, msg100] = PlotDoubleCMC(app.folderName, app.obsFileName, app.obsLimit, app.obsEpochDate, settings, 150);

                %--- Show outliers in UI
                app.UITable_Q1f_L1.Data = L1outliers(:, 2:end);
                app.UITable_Q1f_L5.Data = L5outliers(:, 2:end);

                if settings.findOutliers
                    msg101 = sprintf("SETTINGS:\nWindow size: %d, Outlier threshold: %.1f %c", settings.window, settings.threshold, 963);
                else
                    msg101 = "Outlier detection turned off";
                end

                app.ExperimentSuccessLamp_Q1f.Color = app.colorGreen;
                app.SaveResultsButton_Q1f.Enable = "on";

                expDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                app.TextArea_Q1f.Value = sprintf("%s\n%s\n%s\n%s",expDate, msg100, msg101);
                fprintf(app.logFileId,strcat(expDate,' Experiment 1f ran succesfully.\n'));
            catch
                app.ExperimentSuccessLamp_Q1f.Color = app.colorRed;

                expDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                app.TextArea_Q1f.Value = sprintf("%s\nExperiment failed!",expDate);
                fprintf(app.logFileId,strcat(expDate,' Experiment 1f failed.\n'));
            end

            %--- Enable all experiments again
            enableExperimentsRunButtons(app);
        end

        % Button pushed function: SaveResultsButton_Q1f
        function SaveResultsButton_Q1fPushed(app, event)
            app.ResultsLamp_Q1f.Color = app.colorYellow;
            drawnow;

            try
                % Save figures
                runId = strcat("r",sprintf("%02d",(app.expCounters(6) + 1)));
                % datetime dropped-off because does not match with input data folder
                resultsFolderName = fullfile(app.sessionSettings.outputFilePath,filesep,app.currentDataset,filesep,"exp_1f",filesep,runId);   % Your destination folder
                app.figurePaths = saveFigs(resultsFolderName);

                % Save table
                statTable_L1=app.UITable_Q1f_L1.Data;
                statTable_L5=app.UITable_Q1f_L5.Data;
                         
                try
                    if ~isempty(statTable_L1)
                        if istable(statTable_L1)
                            writetable(statTable_L1,fullfile(resultsFolderName, "statTable_L1.xlsx"));
                        else
                            writecell(statTable_L1,fullfile(resultsFolderName, "statTable_L1.xlsx"));
                        end
                    end
                    if ~isempty(statTable_L5)
                        if istable(statTable_L5)
                            writetable(statTable_L5,fullfile(resultsFolderName, "statTable_L5.xlsx"));
                        else
                            writecell(statTable_L5,fullfile(resultsFolderName, "statTable_L5.xlsx"));
                        end
                    end
                catch
                    newMessage = sprintf("Writetable() on excel file has failed.");
                    app.TextArea_Q1f.Value = [app.TextArea_Q1f.Value; newMessage];
                end

                %--- update run counters
                app.expCounters(6) = app.expCounters(6) + 1;
                app.RunEditField_Q1f.Value = app.expCounters(6);

                app.ResultsLamp_Q1f.Color = app.colorGreen;
                app.GenerateReportButton_Q1f.Enable = "on";
                app.SaveResultsButton_Q1f.Enable = "off";

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nResults saved in: %s", saveDate, resultsFolderName);
                app.TextArea_Q1f.Value = [app.TextArea_Q1f.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Results of experiment 1f saved (Run ID: %d)\n", saveDate, app.expCounters(6)));
            catch
                app.ResultsLamp_Q1f.Color = app.colorRed;

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nError saving results", saveDate);
                app.TextArea_Q1f.Value = [app.TextArea_Q1f.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Error saving results of experiment 1f (Run ID: %d)\n", saveDate, app.expCounters(6)));
            end

            scroll(app.TextArea_Q1f, 'bottom');
        end

        % Button pushed function: GenerateReportButton_Q1f
        function GenerateReportButton_Q1fPushed(app, event)
            import mlreportgen.dom.*

            app.ReportLamp_Q1f.Color = app.colorYellow;
            drawnow;

            try
                for iFig = 1:length(app.figurePaths)
                    figures(iFig) = Image(app.figurePaths(iFig));
                end

                runId = strcat("r",sprintf("%02d",(app.expCounters(6))));
                resultsFolderName = fullfile(app.sessionSettings.outputFilePath,filesep,app.currentDataset,filesep,"exp_1f",filesep,runId);

                %--- Document metadata fields
                genTime = string(datetime("now"));
                reportID = string(generateReportSerial([app.opMetaFields, genTime]));

                %--- Report text content
                repTestFields = [genTime, reportID, "1f", string(app.expCounters(6)), app.currentOperator, app.opMetaFields];

                [msg00] = genReport(resultsFolderName, strcat('1f_',reportID), 'rptTmpl\1f_G-PEARL_tmpl.dotx', repTestFields, figures, app.TextArea_Q1f.Value, app.CommentArea_Q1f.Value);

                %--- If flagged -> Operation Science Report (OSR)
                msg01 = makeAvailable2OSR(app.currentDataset,'1f',resultsFolderName,strcat('1f_',reportID),docMetaFields,app);
                OSR_data = app.OSR;
                save('settings\OSR.mat', "OSR_data");

                %---
                app.ReportLamp_Q1f.Color = app.colorGreen;
                app.GenerateReportButton_Q1f.Enable = "off";

                %--- Experiment has been completed
                app.expCompleted(6) = true;
                app.Q1f_completed.Color = app.colorGreen;

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nReport saved in: %s\n%s\nStatistics table included in the report is also saved separately in the folder and can be sent to POC", saveDate, resultsFolderName, msg01);
                app.TextArea_Q1f.Value = [app.TextArea_Q1f.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Report of experiment 1f saved (Run ID: %d)\n", saveDate, app.expCounters(6)));
            catch
                app.ReportLamp_Q1f.Color = app.colorRed;

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nError saving report", saveDate);
                app.TextArea_Q1f.Value = [app.TextArea_Q1f.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Error saving report of experiment 1f (Run ID: %d)\n", saveDate, app.expCounters(6)));
            end

            scroll(app.TextArea_Q1f, 'bottom');
        end

        % Button pushed function: GenerateReportButton_Q1e
        function GenerateReportButton_Q1ePushed(app, event)
            import mlreportgen.dom.*

            app.ReportLamp_Q1e.Color = app.colorYellow;
            drawnow;

            try
                for iFig = 1:length(app.figurePaths)
                    figures(iFig) = Image(app.figurePaths(iFig));
                end

                runId = strcat("r",sprintf("%02d",(app.expCounters(5))));
                resultsFolderName = strcat(app.sessionSettings.outputFilePath,filesep,app.currentDataset,filesep,"exp_1e",filesep,runId);

                %--- Document metadata fields
                genTime = string(datetime("now"));
                reportID = string(generateReportSerial([app.opMetaFields, genTime]));

                %--- Report text content
                repTestFields = [genTime, reportID, "1e", string(app.expCounters(5)), app.currentOperator, app.opMetaFields];

                [msg00] = genReport(resultsFolderName, strcat('1e_',reportID), 'rptTmpl\1e_G-PEARL_tmpl.dotx', repTestFields, figures, app.TextArea_Q1e.Value, app.CommentArea_Q1e.Value);

                % ---- if flagged -> Operation Science Report (OSR)
                msg01 = makeAvailable2OSR(app.currentDataset,'1e',resultsFolderName,strcat('1e_',reportID),docMetaFields,app);
                OSR_data = app.OSR;
                save('settings\OSR.mat', "OSR_data");

                % ----
                app.ReportLamp_Q1e.Color = app.colorGreen;
                app.GenerateReportButton_Q1e.Enable = "off";

                %--- Experiment has been completed
                app.expCompleted(5) = true;
                app.Q1e_completed.Color = app.colorGreen;

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nReport saved in: %s\n%s", saveDate, resultsFolderName, msg01);
                app.TextArea_Q1e.Value = [app.TextArea_Q1e.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Report of experiment 1e saved (Run ID: %d)\n", saveDate, app.expCounters(5)));
            catch
                app.ReportLamp_Q1e.Color = app.colorRed;

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nError saving report", saveDate);
                app.TextArea_Q1e.Value = [app.TextArea_Q1e.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Error saving report of experiment 1e (Run ID: %d)\n", saveDate, app.expCounters(5)));
            end

            scroll(app.TextArea_Q1e, 'bottom');
        end

        % Button pushed function: RunExperimentButton_Q3b
        function RunExperimentButton_Q3bPushed(app, event)
            %% Close previous plots, reset lamps and messages
            close all

            %--- First disable all experiments to avoid interference
            %(activate again when finished running)
            disableExperimentsRunButtons(app);

            app.TextArea_Q3b.Value = "Running experiment...";
            app.CommentArea_Q3b.Value = "No relevant comments.";
            app.ExperimentSuccessLamp_Q3b.Color = app.colorYellow;

            app.ResultsLamp_Q3b.Color = app.colorGray;
            app.ReportLamp_Q3b.Color = app.colorGray;

            %--- Empty tables from previous run
            app.UITable_Q3b_GPS.Data = {};
            app.UITable_Q3b_Galileo.Data = {};

            resetExpButtons(app);
            drawnow;

            %% Remove ppPvt folder to avoid possible conflicts with names
            warning('off','MATLAB:rmpath:DirNotFound');
            addpath(genpath('extSftw\swRx\'));

            try
                % Collect parameters from SwRx panel and RUN
                %--- GPS Signals
                %--- G1C
                if app.G1CCheckBox_2.Value == true
                    acqSettingsG1C.signal            = app.G1CCheckBox_2.Text;
                    acqSettingsG1C.dopplerStep       = app.DopplerstepHzEditField.Value;
                    acqSettingsG1C.forceSSshift      = app.DopplercenteroffsetHzEditField.Value;
                    acqSettingsG1C.searchSpace       = app.DopplerrangeHzEditField.Value;
                    acqSettingsG1C.K                 = app.NonCoherentsumsEditField_8.Value;
                    acqSettingsG1C.acqTcoh           = app.CoherentIntegrationtimesEditField.Value;
                    acqSettingsG1C.acqPfaSys         = app.FalseAlarmProbabilityEditField_8.Value;
                    acqSettingsG1C.codeDopplerSearch = app.CodeDopplerCheckBoxG1C.Value;

                    acqSettingsG1C.acqSatelliteList = 1:32;
                    acqSettingsG1C.rawFileL1 = app.iqsFileList{1};
                    acqSettingsG1C.doProbeData = 1;

                    [G1Csettings, acqG1CRes] = NavSASrx_fcn(acqSettingsG1C,app);

                    [msg100] = PlotAcqResults(G1Csettings, acqG1CRes, 311);

                    [msg101, CNoTab] = CompareCNo(acqG1CRes, 312);

                    % Convert the table to a cell array
                    CNoTabCell = table2cell(CNoTab);

                    if ~isempty(CNoTabCell)
                        % Assign specific columns (1, 3, and 5) of the UITable to the converted cell array
                        app.UITable_Q3b_GPS.Data(:, [1, 3, 5]) = CNoTabCell;
                    end
                else
                    msg100 = "GPS L1 not selected";
                    msg101 = "";
                end
            catch
                app.ExperimentSuccessLamp_Q3b.Color = app.colorRed;

                expDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                app.TextArea_Q3b.Value = sprintf("%s\nExperiment failed!",expDate);
                fprintf(app.logFileId,strcat(expDate,' Experiment Q3b failed.\n'));
            end

            try
                if app.E1BCheckBox_2.Value == true
                    acqSettingsE1B.signal               = app.E1BCheckBox_2.Text;
                    acqSettingsE1B.dopplerStep          = app.DopplerStepEditField_12.Value;
                    acqSettingsE1B.forceSSshift         = app.DopplerStepEditField_22.Value;
                    acqSettingsE1B.searchSpace          = app.DopplerStepEditField_23.Value;
                    acqSettingsE1B.K                    = app.NonCoherentsumsEditField_12.Value;
                    acqSettingsE1B.acqTcoh              = app.CoherentIntegrationtimeEditField_12.Value;
                    acqSettingsE1B.acqPfaSys            = app.FalseAlarmProbabilityEditField_12.Value;
                    acqSettingsE1B.codeDopplerSearch    = app.CodeDopplerCheckBoxE1B.Value;

                    acqSettingsE1B.acqSatelliteList = 1:50;
                   
                    %fullFilePath = fullfile(app.IQSdir, app.iqsFileList{1});
                    acqSettingsE1B.rawFileL1 = app.iqsFileList{1};
                    acqSettingsE1B.doProbeData = 0;

                    [E1Bsettings, acqE1BRes] = NavSASrx_fcn(acqSettingsE1B,app);

                    [msg102] = PlotAcqResults(E1Bsettings, acqE1BRes, 313);

                    [msg103, CNoTab_E1B] = CompareCNo(acqE1BRes, 314);

                    app.UITable_Q3b_Galileo.Data = CNoTab_E1B;

                    % Convert the table to a cell array
                    CNoTabCell = table2cell(CNoTab_E1B);

                    % Assign specific columns (1, 3, and 5) of the UITable to the converted cell array
                    if ~isempty(CNoTabCell)
                        app.UITable_Q3b_Galileo.Data(:, [1, 3, 5]) = CNoTabCell;
                    end
                else
                    msg102 = "Galileo E1 not selected";
                    msg103 = "";
                end

                app.ExperimentSuccessLamp_Q3b.Color = app.colorGreen;
                app.SaveResultsButton_Q3b.Enable = "on";

                expDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                app.TextArea_Q3b.Value = sprintf("%s\n%s\n%s\n%s\n%s", expDate, msg100, msg101, msg102, msg103);
                fprintf(app.logFileId,strcat(expDate,' Experiment Q3b ran succesfully.\n'));
            catch
                app.ExperimentSuccessLamp_Q3b.Color = app.colorRed;

                expDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                app.TextArea_Q3b.Value = sprintf("%s\nExperiment failed!",expDate);
                fprintf(app.logFileId,strcat(expDate,' Experiment Q3b failed.\n'));
            end

            %--- Enable all experiments again
            enableExperimentsRunButtons(app);
        end

        % Button pushed function: GenerateReportButton_Q1d
        function GenerateReportButton_Q1dPushed(app, event)
            import mlreportgen.dom.*

            app.ReportLamp_Q1d.Color = app.colorYellow;
            drawnow;

            try
                for iFig = 1:length(app.figurePaths)
                    figures(iFig) = Image(app.figurePaths(iFig));
                end

                runId = strcat("r",sprintf("%02d",(app.expCounters(4))));
                resultsFolderName = fullfile(app.sessionSettings.outputFilePath,filesep,app.currentDataset,filesep,"exp_1d",filesep,runId);

                %--- Document metadata fields
                genTime = string(datetime("now"));
                reportID = string(generateReportSerial([app.opMetaFields, genTime]));

                %--- Report text content
                repTestFields = [genTime, reportID, "1d", string(app.expCounters(4)), app.currentOperator, app.opMetaFields];

                [msg00] = genReport(resultsFolderName, strcat('1d_',reportID), 'rptTmpl\1d_G-PEARL_tmpl.dotx', repTestFields, figures, app.TextArea_Q1d.Value, app.CommentArea_Q1d.Value);

                % ---- if flagged -> Operation Science Report (OSR)
                msg01 = makeAvailable2OSR(app.currentDataset,'1d',resultsFolderName,strcat('1d_',reportID),docMetaFields,app);
                OSR_data = app.OSR;
                save('settings\OSR.mat', "OSR_data");

                % ----
                app.ReportLamp_Q1d.Color = app.colorGreen;
                app.GenerateReportButton_Q1d.Enable = "off";

                %--- Experiment has been completed
                app.expCompleted(4) = true;
                app.Q1d_completed.Color = app.colorGreen;

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nReport saved in: %s\n%s\nStatistics table included in the report is also saved separately in the folder", saveDate, resultsFolderName, msg01);
                app.TextArea_Q1d.Value = [app.TextArea_Q1d.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Report of experiment 1d saved (Run ID: %d)\n", saveDate, app.expCounters(4)));
            catch
                app.ReportLamp_Q1a.Color = app.colorRed;

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nError saving report", saveDate);
                app.TextArea_Q1d.Value = [app.TextArea_Q1d.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Error saving report of experiment 1d (Run ID: %d)\n", saveDate, app.expCounters(4)));
            end

            scroll(app.TextArea_Q1d, 'bottom');
        end

        % Button pushed function: GenerateReportButton_Q2a
        function GenerateReportButton_Q2aPushed(app, event)
            import mlreportgen.dom.*

            app.ReportLamp_Q2a.Color = app.colorYellow;
            drawnow;

            try
                for iFig = 1:length(app.figurePaths)
                    figures(iFig) = Image(app.figurePaths(iFig));
                end

                runId = strcat("r",sprintf("%02d",(app.expCounters(9))));
                resultsFolderName = fullfile(app.sessionSettings.outputFilePath,filesep,app.currentDataset,filesep,"exp_Q2a",filesep,runId);

                %--- Document metadata fields
                genTime = string(datetime("now"));
                reportID = string(generateReportSerial([app.opMetaFields, genTime]));

                %--- Report text content
                repTestFields = [genTime, reportID, "2a", string(app.expCounters(9)), app.currentOperator, app.opMetaFields];

                [msg00] = genReport(resultsFolderName, strcat('2a_',reportID), 'rptTmpl\2a_G-PEARL_tmpl.dotx', repTestFields, figures, app.TextArea_Q2a.Value, app.CommentArea_Q2a.Value);

                %--- if flagged -> Operation Science Report (OSR)
                msg01 = makeAvailable2OSR(app.currentDataset,'2a',resultsFolderName,strcat('2a_',reportID),docMetaFields,app);
                OSR_data = app.OSR;
                save('settings\OSR.mat', "OSR_data");

                %---
                app.ReportLamp_Q2a.Color = app.colorGreen;
                app.GenerateReportButton_Q2a.Enable = "off";

                %--- Experiment has been completed
                app.expCompleted(9) = true;
                app.Q2a_completed.Color = app.colorGreen;

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nReport saved in: %s\n%s", saveDate, resultsFolderName, msg01);
                app.TextArea_Q2a.Value = [app.TextArea_Q2a.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Report of experiment 2a saved (Run ID: %d)\n", saveDate, app.expCounters(9)));
            catch
                app.ReportLamp_Q2a.Color = app.colorRed;

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nError saving report", saveDate);
                app.TextArea_Q2a.Value = [app.TextArea_Q2a.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Error saving report of experiment 2a (Run ID: %d)\n", saveDate, app.expCounters(9)));
            end

            scroll(app.TextArea_Q2a, 'bottom');
        end

        % Button pushed function: GenerateReportButton_Q2b
        function GenerateReportButton_Q2bPushed(app, event)
            import mlreportgen.dom.*

            app.ReportLamp_Q2b.Color = app.colorYellow;
            drawnow;

            try
                for iFig = 1:length(app.figurePaths)
                    figures(iFig) = Image(app.figurePaths(iFig));
                end

                runId = strcat("r",sprintf("%02d",(app.expCounters(10))));
                resultsFolderName = fullfile(app.sessionSettings.outputFilePath,filesep,app.currentDataset,filesep,"exp_2b",filesep,runId);

                %--- Document metadata fields
                genTime = string(datetime("now"));
                reportID = string(generateReportSerial([app.opMetaFields, genTime]));

                %--- Report text content
                repTestFields = [genTime, reportID, "2b", string(app.expCounters(10)), app.currentOperator, app.opMetaFields];

                [msg00] = genReport(resultsFolderName, strcat('2b_',reportID), 'rptTmpl\2b_G-PEARL_tmpl.dotx', repTestFields, figures, app.TextArea_Q2b.Value, app.CommentArea_Q2b.Value);

                % ---- if flagged -> Operation Science Report (OSR)
                msg01 = makeAvailable2OSR(app.currentDataset,'2b',resultsFolderName,strcat('2b_',reportID),docMetaFields,app);
                OSR_data = app.OSR;
                save('settings\OSR.mat', "OSR_data");

                % ----
                app.ReportLamp_Q2b.Color = app.colorGreen;
                app.GenerateReportButton_Q2b.Enable = "off";

                %--- Experiment has been completed
                app.expCompleted(10) = true;
                app.Q2b_completed.Color = app.colorGreen;

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nReport saved in: %s\n%s", saveDate, resultsFolderName, msg01);
                app.TextArea_Q2b.Value = [app.TextArea_Q2b.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Report of experiment 2b saved (Run ID: %d)\n", saveDate, app.expCounters(10)));
            catch
                app.ReportLamp_Q2b.Color = app.colorRed;

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nError saving report", saveDate);
                app.TextArea_Q2b.Value = [app.TextArea_Q2b.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Error saving report of experiment 2b (Run ID: %d)\n", saveDate, app.expCounters(10)));
            end

            scroll(app.TextArea_Q2b, 'bottom');
        end

        % Button pushed function: GenerateReportButton_Q3b
        function GenerateReportButton_Q3bPushed(app, event)
            import mlreportgen.dom.*

            app.ReportLamp_Q3b.Color = app.colorYellow;
            drawnow;

            try
                for iFig = 1:length(app.figurePaths)
                    figures(iFig) = Image(app.figurePaths(iFig));
                end

                runId = strcat("r",sprintf("%02d",(app.expCounters(15))));
                resultsFolderName = strcat(app.sessionSettings.outputFilePath,filesep,app.currentDataset,filesep,"exp_Q3b",filesep,runId);

                %--- OP metadata fields
                opMetaFields = [app.OperationWindowDropDown.Value, app.PhaseDropDown.Value, app.ModeDropDown.Value, ...
                    app.OPStartEditField.Value, app.InitialAltitudeREEditField.Value, ...
                    string(app.RTPCheckBox.Value), app.RTPStartEditField.Value, app.RTPEndEditField.Value, ...
                    app.SCStartEditField.Value, app.SCBandsDropDown.Value, string(app.L1CheckBox.Value), string(app.L5CheckBox.Value)];
                %--- Document metadata fields
                reportID = string(generateReportSerial([opMetaFields, string(datetime("now"))]));
                docMetaFields = [string(datetime("now")), reportID];
                %--- Report metadata fields
                reportMetaFields = ["Q3.b", string(app.expCounters(15)), app.folderName, app.currentOperator];

                %--- Report text content
                repTestFields = [docMetaFields, opMetaFields, reportMetaFields];
                repLogField  = app.TextArea_Q3b.Value;
                repOpComment = app.CommentArea_Q3b.Value;

                [msg1] = genReport(resultsFolderName,strcat('3b_',reportID),'rptTmpl\RSPC\Q3b_tmpl_v2.dotx',repTestFields,figures,repLogField,repOpComment,app.tmpConfigData);

                % ---- if flagged -> Operation Science Report (OSR)
                msg01 = makeAvailable2OSR(app.currentDataset,'3b',resultsFolderName,strcat('3b_',reportID),docMetaFields,app);
                OSR_data = app.OSR;
                save('settings\OSR.mat', "OSR_data");

                % ----
                app.ReportLamp_Q3b.Color = app.colorGreen;
                app.GenerateReportButton_Q3b.Enable = "off";

                %--- Experiment has been completed
                app.expCompleted(15) = true;
                app.Q3b_completed.Color = app.colorGreen;

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nReport saved in: %s\n%s", saveDate, resultsFolderName, msg01);
                app.TextArea_Q3b.Value = [app.TextArea_Q3b.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Report of experiment 3b saved (Run ID: %d)\n", saveDate, app.expCounters(15)));
            catch
                app.ReportLamp_Q3b.Color = app.colorRed;

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nError saving report", saveDate);
                app.TextArea_Q3b.Value = [app.TextArea_Q3b.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Error saving report of experiment 3b (Run ID: %d)\n", saveDate, app.expCounters(15)));
            end

            scroll(app.TextArea_Q3b, 'bottom');
        end

        % Cell edit callback: UITable_Q1d
        function UITable_Q1dCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;

        end

        % Button pushed function: SaveResultsButton_Q3b
        function SaveResultsButton_Q3bPushed(app, event)
            app.ResultsLamp_Q3b.Color = app.colorYellow;
            drawnow;

            try
                % Save figures
                runId = strcat("r",sprintf("%02d",(app.expCounters(15) + 1)));
                % datetime dropped-off because does not match with input data folder
                resultsFolderName = fullfile(app.sessionSettings.outputFilePath,filesep,app.currentDataset,filesep,"exp_Q3b",filesep,runId);   % Your destination folder
                app.figurePaths = saveFigs(resultsFolderName);

                % Save table
                statTable_GPS=app.UITable_Q3b_GPS.Data;
                statTable_Galileo=app.UITable_Q3b_Galileo.Data;

                if ~isempty(statTable_GPS)
                    if istable(statTable_GPS)
                        writetable(statTable_GPS,fullfile(resultsFolderName, "statTable_GPS.xlsx"));
                    else
                        writecell(statTable_GPS,fullfile(resultsFolderName, "statTable_GPS.xlsx"));
                    end
                end

                if ~isempty(statTable_Galileo)
                    if istable(statTable_Galileo)
                        writetable(statTable_Galileo,fullfile(resultsFolderName, "statTable_Galileo.xlsx"));
                    else
                        writecell(statTable_Galileo,fullfile(resultsFolderName, "statTable_Galileo.xlsx"));
                    end
                end

                % update run counters
                app.expCounters(15) = app.expCounters(15) + 1;
                app.RunEditField_Q3b.Value = app.expCounters(15);

                app.ResultsLamp_Q3b.Color = app.colorGreen;
                app.GenerateReportButton_Q3b.Enable = "on";
                app.SaveResultsButton_Q3b.Enable = "off";

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nResults saved in: %s", saveDate, resultsFolderName);
                app.TextArea_Q3b.Value = [app.TextArea_Q3b.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Results of experiment Q3b saved (Run ID: %d)\n", saveDate, app.expCounters(15)));
            catch
                app.ResultsLamp_Q3b.Color = app.colorRed;

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nError saving results", saveDate);
                app.TextArea_Q3b.Value = [app.TextArea_Q3b.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Error saving results of experiment Q3b (Run ID: %d)\n", saveDate, app.expCounters(15)));
            end

            scroll(app.TextArea_Q3b, 'bottom');
        end

        % Button pushed function: OpenReport_Q1a
        function OpenReport_Q1aPushed(app, event)
            % split string in list
            data = split(app.ListBox_Q1a.Value,' ');

            % read from list the filename to be open
            toFind = string(data(1));

            % look for filepath
            reportIdx = find(cellfun(@(x) isequal(x{1}, toFind), app.OSR.(app.currentOpsWindow).Q1a(:, 3)));

            % Open it for visualization
            rptview(strcat(string(app.OSR.(app.currentOpsWindow).Q1a(reportIdx,2)),'.pdf'), 'pdf');

        end

        % Button pushed function: DeleteReport_Q1a
        function DeleteReport_Q1aPushed(app, event)
            %--- Get current items in the list box
            items = app.ListBox_Q1a.Items;

            %--- Get index of the selected item
            selectedIndex = app.ListBox_Q1a.Value;

            %--- Check if list is empty or only has one item
            if isempty(items) || isscalar(items)
                app.ListBox_Q1a.Items = {};
            else
                %--- Remove selected item from the list
                items(strcmp(items, selectedIndex)) = [];
                %--- Update list box
                app.ListBox_Q1a.Items = items;

                %--- Adjust value if the selected index was the last item
                if any(strcmp(selectedIndex, items))
                    app.ListBox_Q1a.Value = items{end};  % Select the last item in the updated list
                else
                    app.ListBox_Q1a.Value = items{1};  % Select the first item in the updated list
                end
            end
        end

        % Button pushed function: AddReport_Q1a
        function AddReport_Q1aButtonPushed(app, event)
            %--- Add to the textbox and check experiment checkbox
            app.CheckBox_Q1a.Value=true;
            app.EditField_Q1a_OSR.Value=app.ListBox_Q1a.Value;

            %-- Add to a temporary list for the generation of the OSR
            % split string in list
            data = split(app.ListBox_Q1a.Value,' ');

            %--- Read from list the filename to be open
            toFind = string(data(1));

            %--- Look for filepath
            reportIdx = find(cellfun(@(x) isequal(x{1}, toFind), app.OSR.(app.currentOpsWindow).Q1a(:, 3)));
            newreport = {strcat(string(app.OSR.(app.currentOpsWindow).Q1a(reportIdx,2)),'.pdf')};

            insertPosition = 1;
            app.OSR_reportList{insertPosition} = string(newreport);
        end

        % Button pushed function: OpenReport_Q1b
        function OpenReport_Q1bPushed(app, event)
            %--- Split string in list
            data = split(app.ListBox_Q1b.Value,' ');

            %--- Read from list the filename to be open
            toFind =string(data(1));

            %--- Look for filepath
            reportIdx = find(cellfun(@(x) isequal(x{1}, toFind), app.OSR.(app.currentOpsWindow).Q1b(:, 3)));

            %--- Open for visualization
            rptview(strcat(string(app.OSR.(app.currentOpsWindow).Q1b(reportIdx,2)),'.pdf'), 'pdf');
        end

        % Button pushed function: DeleteReport_Q1b
        function DeleteReport_Q1bPushed(app, event)

            % Get the current items in the list box
            items = app.ListBox_Q1b.Items;

            % Get the index of the selected item
            selectedIndex = app.ListBox_Q1b.Value;

            % Check if the list is empty or only has one item
            if isempty(items) || isscalar(items)
                % If the list is empty or has only one item, clear the list box
                app.ListBox_Q1b.Items = {};
            else
                % Remove the selected item from the list
                items(strcmp(items, selectedIndex)) = [];

                % Update the list box with the new list
                app.ListBox_Q1b.Items = items;

                % Adjust the Value property if the selected index was the last item
                if any(strcmp(selectedIndex, items))
                    app.ListBox_Q1b.Value = items{end};  % Select the last item in the updated list
                else
                    app.ListBox_Q1b.Value = items{1};  % Select the first item in the updated list
                end
            end
        end

        % Button pushed function: AddReport_Q1b
        function AddReport_Q1bPushed(app, event)
            % Add to the textbox and check experiment checkbox

            app.CheckBox_Q1b.Value=true;
            app.EditField_Q1b_OSR.Value=app.ListBox_Q1b.Value;

            % Add to a temporary list for the generation of the OSR

            % split string in list
            data=split(app.ListBox_Q1b.Value,' ');

            % read from list the filename to be open
            toFind=string(data(1));

            % look for filepath
            reportIdx = find(cellfun(@(x) isequal(x{1}, toFind), app.OSR.(app.currentOpsWindow).Q1b(:, 3)));

            newreport = {strcat(string(app.OSR.(app.currentOpsWindow).Q1b(reportIdx,2)),'.pdf')};

            insertPosition = 2;

            app.OSR_reportList{insertPosition} = string(newreport);
        end

        % Button pushed function: GenerateReportButton_OSR
        function GenerateReportButton_OSRPushed(app, event)
            %--- Specify the output file name
            % Define the location of the output file
            resultsFolderNameOSR = fullfile(app.sessionSettings.outputFilePath,filesep,app.currentOpsWindow);

            %--- OP metadata fields
            opMetaFields = [app.OperationWindowDropDown.Value, app.PhaseDropDown.Value, app.ModeDropDown.Value, ...
                app.OPStartEditField.Value, app.InitialAltitudeREEditField.Value, ...
                string(app.RTPCheckBox.Value), app.RTPStartEditField.Value, app.RTPEndEditField.Value, ...
                app.SCStartEditField.Value, app.SCBandsDropDown.Value, string(app.L1CheckBox.Value), string(app.L5CheckBox.Value)];
            %--- Document metadata fields
            reportID = string(generateReportSerial([opMetaFields, string(datetime("now"))]));
            docMetaFields = [string(datetime("now")), reportID];
            repTestFields = [docMetaFields, opMetaFields];
            oprtComment = app.OSR_comments.Value;

            %--- Convert the cell array to a comma-separated list of arguments
            pdfList = app.OSR_reportList;

            %--- Initialize the result string array
            resultArray = cell(1, length(pdfList));

            %--- Iterate over each element in pdfList
            for i = 1:length(pdfList)
                if isempty(pdfList{i})
                    resultArray{i} = '☐';
                else
                    resultArray{i} = '☑';
                end
            end

            %--- Name files
            coverFile = strcat(resultsFolderNameOSR,'\',app.currentOpsWindow,'_OSR_cover');
            outputFile = strcat(resultsFolderNameOSR,'\',app.currentOpsWindow, sprintf('_OSR_%s', reportID));

            %--- Generate OSR cover page (specific for the current OPS)
            [msg] = genOSRCover(coverFile,'rptTmpl\OSR\General_OSR.dotx', [repTestFields string(resultArray)], oprtComment);

            %--- Remove empty items
            nonEmptyPdfList = [{strcat(coverFile, ".pdf")} pdfList(~cellfun('isempty', pdfList))];

            %--- Concatenate the PDFs
            try
                mergePdfs(string(nonEmptyPdfList), strcat(outputFile,".pdf"));
                rptview(outputFile, 'pdf')
                app.currentWindowOSR = outputFile;
                app.OSRLamp.Color    = app.colorGreen;
            catch
                app.OSRLamp.Color    = app.colorRed;
            end
        end

        % Button pushed function: OpenReport_Q1c
        function OpenReport_Q1cPushed(app, event)
            % split string in list
            data=split(app.ListBox_Q1c.Value,' ');

            % read from list the filename to be open
            toFind=string(data(1));

            % look for filepath
            reportIdx = find(cellfun(@(x) isequal(x{1}, toFind), app.OSR.(app.currentOpsWindow).Q1c(:, 3)));

            % Open it for visualization
            rptview(strcat(string(app.OSR.(app.currentOpsWindow).Q1c(reportIdx,2)),'.pdf'), 'pdf');
        end

        % Button pushed function: DeleteReport_Q1c
        function DeleteReport_Q1cPushed(app, event)
            % Get the current items in the list box
            items = app.ListBox_Q1c.Items;

            % Get the index of the selected item
            selectedIndex = app.ListBox_Q1c.Value;

            % Check if the list is empty or only has one item
            if isempty(items) || isscalar(items)
                % If the list is empty or has only one item, clear the list box
                app.ListBox_Q1c.Items = {};
            else
                % Remove the selected item from the list
                items(strcmp(items, selectedIndex)) = [];

                % Update the list box with the new list
                app.ListBox_Q1c.Items = items;

                % Adjust the Value property if the selected index was the last item
                if any(strcmp(selectedIndex, items))
                    app.ListBox_Q1c.Value = items{end};  % Select the last item in the updated list
                else
                    app.ListBox_Q1c.Value = items{1};  % Select the first item in the updated list
                end
            end
        end

        % Button pushed function: AddReport_Q1c
        function AddReport_Q1cPushed(app, event)
            % Add to the textbox and check experiment checkbox

            app.CheckBox_Q1c.Value=true;
            app.EditField_Q1c_OSR.Value=app.ListBox_Q1c.Value;

            % Add to a temporary list for the generation of the OSR

            % split string in list
            data=split(app.ListBox_Q1c.Value,' ');

            % read from list the filename to be open
            toFind=string(data(1));

            % look for filepath
            reportIdx = find(cellfun(@(x) isequal(x{1}, toFind), app.OSR.(app.currentOpsWindow).Q1c(:, 3)));

            newreport = {strcat(string(app.OSR.(app.currentOpsWindow).Q1c(reportIdx,2)),'.pdf')};

            insertPosition = 3;

            app.OSR_reportList{insertPosition} = string(newreport);
        end

        % Button pushed function: OpenReport_Q1d
        function OpenReport_Q1dPushed(app, event)
            % split string in list
            data=split(app.ListBox_Q1d.Value,' ');

            % read from list the filename to be open
            toFind=string(data(1));

            % look for filepath
            reportIdx = find(cellfun(@(x) isequal(x{1}, toFind), app.OSR.(app.currentOpsWindow).Q1d(:, 3)));

            % Open it for visualization
            rptview(strcat(string(app.OSR.(app.currentOpsWindow).Q1d(reportIdx,2)),'.pdf'), 'pdf');
        end

        % Button pushed function: DeleteReportQ1d
        function DeleteReportQ1dPushed(app, event)
            % Get the current items in the list box
            items = app.ListBox_Q1d.Items;

            % Get the index of the selected item
            selectedIndex = app.ListBox_Q1d.Value;

            % Check if the list is empty or only has one item
            if isempty(items) || isscalar(items)
                % If the list is empty or has only one item, clear the list box
                app.ListBox_Q1d.Items = {};
            else
                % Remove the selected item from the list
                items(strcmp(items, selectedIndex)) = [];

                % Update the list box with the new list
                app.ListBox_Q1d.Items = items;

                % Adjust the Value property if the selected index was the last item
                if any(strcmp(selectedIndex, items))
                    app.ListBox_Q1d.Value = items{end};  % Select the last item in the updated list
                else
                    app.ListBox_Q1d.Value = items{1};  % Select the first item in the updated list
                end
            end
        end

        % Button pushed function: AddReport_Q1d
        function AddReport_Q1dPushed(app, event)
            % Add to the textbox and check experiment checkbox

            app.CheckBox_Q1d.Value=true;
            app.EditField_Q1d_OSR.Value=app.ListBox_Q1d.Value;

            % Add to a temporary list for the generation of the OSR

            % split string in list
            data=split(app.ListBox_Q1d.Value,' ');

            % read from list the filename to be open
            toFind=string(data(1));

            % look for filepath
            reportIdx = find(cellfun(@(x) isequal(x{1}, toFind), app.OSR.(app.currentOpsWindow).Q1d(:, 3)));

            newreport = {strcat(string(app.OSR.(app.currentOpsWindow).Q1d(reportIdx,2)),'.pdf')};

            insertPosition = 4;

            app.OSR_reportList{insertPosition} = string(newreport);
        end

        % Button pushed function: OpenReport_Q1e
        function OpenReport_Q1ePushed(app, event)
            % split string in list
            data=split(app.ListBox_Q1e.Value,' ');

            % read from list the filename to be open
            toFind=string(data(1));

            % look for filepath
            reportIdx = find(cellfun(@(x) isequal(x{1}, toFind), app.OSR.(app.currentOpsWindow).Q1e(:, 3)));

            % Open it for visualization
            rptview(strcat(string(app.OSR.(app.currentOpsWindow).Q1e(reportIdx,2)),'.pdf'), 'pdf');
        end

        % Button pushed function: DeleteReport_Q1e
        function DeleteReport_Q1ePushed(app, event)
            % Get the current items in the list box
            items = app.ListBox_Q1e.Items;

            % Get the index of the selected item
            selectedIndex = app.ListBox_Q1e.Value;

            % Check if the list is empty or only has one item
            if isempty(items) || isscalar(items)
                % If the list is empty or has only one item, clear the list box
                app.ListBox_Q1e.Items = {};
            else
                % Remove the selected item from the list
                items(strcmp(items, selectedIndex)) = [];

                % Update the list box with the new list
                app.ListBox_Q1e.Items = items;

                % Adjust the Value property if the selected index was the last item
                if any(strcmp(selectedIndex, items))
                    app.ListBox_Q1e.Value = items{end};  % Select the last item in the updated list
                else
                    app.ListBox_Q1e.Value = items{1};  % Select the first item in the updated list
                end
            end
        end

        % Button pushed function: AddReport_Q1e
        function AddReport_Q1ePushed(app, event)
            % Add to the textbox and check experiment checkbox

            app.CheckBox_Q1e.Value=true;
            app.EditField_Q1e_OSR.Value=app.ListBox_Q1e.Value;

            % Add to a temporary list for the generation of the OSR

            % split string in list
            data=split(app.ListBox_Q1e.Value,' ');

            % read from list the filename to be open
            toFind=string(data(1));

            % look for filepath
            reportIdx = find(cellfun(@(x) isequal(x{1}, toFind), app.OSR.(app.currentOpsWindow).Q1e(:, 3)));

            newreport = {strcat(string(app.OSR.(app.currentOpsWindow).Q1e(reportIdx,2)),'.pdf')};

            insertPosition = 5;

            app.OSR_reportList{insertPosition} = string(newreport);


        end

        % Button pushed function: OpenReport_Q1f
        function OpenReport_Q1fPushed(app, event)
            % split string in list
            data=split(app.ListBox_Q1f.Value,' ');

            % read from list the filename to be open
            toFind=string(data(1));

            % look for filepath
            reportIdx = find(cellfun(@(x) isequal(x{1}, toFind), app.OSR.(app.currentOpsWindow).Q1f(:, 3)));

            % Open it for visualization
            rptview(strcat(string(app.OSR.(app.currentOpsWindow).Q1f(reportIdx,2)),'.pdf'), 'pdf');
        end

        % Button pushed function: DeleteReport_Q1f
        function DeleteReport_Q1fPushed(app, event)
            % Get the current items in the list box
            items = app.ListBox_Q1f.Items;

            % Get the index of the selected item
            selectedIndex = app.ListBox_Q1f.Value;

            % Check if the list is empty or only has one item
            if isempty(items) || isscalar(items)
                % If the list is empty or has only one item, clear the list box
                app.ListBox_Q1f.Items = {};
            else
                % Remove the selected item from the list
                items(strcmp(items, selectedIndex)) = [];

                % Update the list box with the new list
                app.ListBox_Q1f.Items = items;

                % Adjust the Value property if the selected index was the last item
                if any(strcmp(selectedIndex, items))
                    app.ListBox_Q1f.Value = items{end};  % Select the last item in the updated list
                else
                    app.ListBox_Q1f.Value = items{1};  % Select the first item in the updated list
                end
            end
        end

        % Button pushed function: AddReport_Q1f
        function AddReport_Q1fPushed(app, event)
            % Add to the textbox and check experiment checkbox

            app.CheckBox_Q1f.Value=true;
            app.EditField_Q1f_OSR.Value=app.ListBox_Q1f.Value;

            % Add to a temporary list for the generation of the OSR

            % split string in list
            data=split(app.ListBox_Q1f.Value,' ');

            % read from list the filename to be open
            toFind=string(data(1));

            % look for filepath
            reportIdx = find(cellfun(@(x) isequal(x{1}, toFind), app.OSR.(app.currentOpsWindow).Q1f(:, 3)));

            newreport = {strcat(string(app.OSR.(app.currentOpsWindow).Q1f(reportIdx,2)),'.pdf')};

            insertPosition = 6;

            app.OSR_reportList{insertPosition} = string(newreport);
        end

        % Button pushed function: OpenReport_Q2a
        function OpenReport_Q2aPushed(app, event)
            % split string in list
            data=split(app.ListBox_Q2a.Value,' ');

            % read from list the filename to be open
            toFind=string(data(1));

            % look for filepath
            reportIdx = find(cellfun(@(x) isequal(x{1}, toFind), app.OSR.(app.currentOpsWindow).Q2a(:, 3)));

            % Open it for visualization
            rptview(strcat(string(app.OSR.(app.currentOpsWindow).Q2a(reportIdx,2)),'.pdf'), 'pdf');
        end

        % Button pushed function: DeleteReport_Q2a
        function DeleteReport_Q2aPushed(app, event)
            % Get the current items in the list box
            items = app.ListBox_Q2a.Items;

            % Get the index of the selected item
            selectedIndex = app.ListBox_Q2a.Value;

            % Check if the list is empty or only has one item
            if isempty(items) || isscalar(items)
                % If the list is empty or has only one item, clear the list box
                app.ListBox_Q2a.Items = {};
            else
                % Remove the selected item from the list
                items(strcmp(items, selectedIndex)) = [];

                % Update the list box with the new list
                app.ListBox_Q2a.Items = items;

                % Adjust the Value property if the selected index was the last item
                if any(strcmp(selectedIndex, items))
                    app.ListBox_Q2a.Value = items{end};  % Select the last item in the updated list
                else
                    app.ListBox_Q2a.Value = items{1};  % Select the first item in the updated list
                end
            end
        end

        % Button pushed function: AddReport_Q2a
        function AddReport_Q2aPushed(app, event)
            % Add to the textbox and check experiment checkbox

            app.CheckBox_Q2a.Value=true;
            app.EditField_Q2a_OSR.Value=app.ListBox_Q2a.Value;

            % Add to a temporary list for the generation of the OSR

            % split string in list
            data=split(app.ListBox_Q2a.Value,' ');

            % read from list the filename to be open
            toFind=string(data(1));

            % look for filepath
            reportIdx = find(cellfun(@(x) isequal(x{1}, toFind), app.OSR.(app.currentOpsWindow).Q2a(:, 3)));

            newreport = {strcat(string(app.OSR.(app.currentOpsWindow).Q2a(reportIdx,2)),'.pdf')};

            insertPosition = 7;

            app.OSR_reportList{insertPosition} = string(newreport);
        end

        % Button pushed function: OpenReport_Q2c
        function OpenReport_Q2cPushed(app, event)
            % split string in list
            data=split(app.ListBox_Q2c.Value,' ');

            % read from list the filename to be open
            toFind=string(data(1));

            % look for filepath
            reportIdx = find(cellfun(@(x) isequal(x{1}, toFind), app.OSR.(app.currentOpsWindow).Q2c(:, 3)));

            % Open it for visualization
            rptview(strcat(string(app.OSR.(app.currentOpsWindow).Q2c(reportIdx,2)),'.pdf'), 'pdf');
        end

        % Button pushed function: DeleteReport_Q2c
        function DeleteReport_Q2cPushed(app, event)
            % Get the current items in the list box
            items = app.ListBox_Q2c.Items;

            % Get the index of the selected item
            selectedIndex = app.ListBox_Q2c.Value;

            % Check if the list is empty or only has one item
            if isempty(items) || isscalar(items)
                % If the list is empty or has only one item, clear the list box
                app.ListBox_Q2c.Items = {};
            else
                % Remove the selected item from the list
                items(strcmp(items, selectedIndex)) = [];

                % Update the list box with the new list
                app.ListBox_Q2c.Items = items;

                % Adjust the Value property if the selected index was the last item
                if any(strcmp(selectedIndex, items))
                    app.ListBox_Q2c.Value = items{end};  % Select the last item in the updated list
                else
                    app.ListBox_Q2c.Value = items{1};  % Select the first item in the updated list
                end
            end
        end

        % Button pushed function: AddReport_Q2c
        function AddReport_Q2cPushed(app, event)
            % Add to the textbox and check experiment checkbox

            app.CheckBox_Q2c.Value=true;
            app.EditField_Q2c_OSR.Value=app.ListBox_Q2c.Value;

            % Add to a temporary list for the generation of the OSR

            % split string in list
            data=split(app.ListBox_Q2c.Value,' ');

            % read from list the filename to be open
            toFind=string(data(1));

            % look for filepath
            reportIdx = find(cellfun(@(x) isequal(x{1}, toFind), app.OSR.(app.currentOpsWindow).Q2c(:, 3)));

            newreport = {strcat(string(app.OSR.(app.currentOpsWindow).Q2c(reportIdx,2)),'.pdf')};

            insertPosition = 9;

            app.OSR_reportList{insertPosition} = string(newreport);
        end

        % Button pushed function: OpenReport_Q2d
        function OpenReport_Q2dPushed(app, event)
            % split string in list
            data=split(app.ListBox_Q2d.Value,' ');

            % read from list the filename to be open
            toFind=string(data(1));

            % look for filepath
            reportIdx = find(cellfun(@(x) isequal(x{1}, toFind), app.OSR.(app.currentOpsWindow).Q2d(:, 3)));

            % Open it for visualization
            rptview(strcat(string(app.OSR.(app.currentOpsWindow).Q2d(reportIdx,2)),'.pdf'), 'pdf');
        end

        % Button pushed function: DeleteReport_Q2d
        function DeleteReport_Q2dPushed(app, event)
            % Get the current items in the list box
            items = app.ListBox_Q2d.Items;

            % Get the index of the selected item
            selectedIndex = app.ListBox_Q2d.Value;

            % Check if the list is empty or only has one item
            if isempty(items) || isscalar(items)
                % If the list is empty or has only one item, clear the list box
                app.ListBox_Q2d.Items = {};
            else
                % Remove the selected item from the list
                items(strcmp(items, selectedIndex)) = [];

                % Update the list box with the new list
                app.ListBox_Q2d.Items = items;

                % Adjust the Value property if the selected index was the last item
                if any(strcmp(selectedIndex, items))
                    app.ListBox_Q2d.Value = items{end};  % Select the last item in the updated list
                else
                    app.ListBox_Q2d.Value = items{1};  % Select the first item in the updated list
                end
            end
        end

        % Button pushed function: AddReport_Q2d
        function AddReport_Q2dPushed(app, event)
            % Add to the textbox and check experiment checkbox

            app.CheckBox_Q2d.Value=true;
            app.EditField_Q2d_OSR.Value=app.ListBox_Q2d.Value;

            % Add to a temporary list for the generation of the OSR

            % split string in list
            data=split(app.ListBox_Q2d.Value,' ');

            % read from list the filename to be open
            toFind=string(data(1));

            % look for filepath
            reportIdx = find(cellfun(@(x) isequal(x{1}, toFind), app.OSR.(app.currentOpsWindow).Q2d(:, 3)));

            newreport = {strcat(string(app.OSR.(app.currentOpsWindow).Q2d(reportIdx,2)),'.pdf')};

            insertPosition = 10;

            app.OSR_reportList{insertPosition} = string(newreport);
        end

        % Button pushed function: OpenReport_Q3a
        function OpenReport_Q3aPushed(app, event)
            % read from list the filename to be open
            % split string in list
            data=split(app.ListBox_Q3a.Value,' ');

            % read from list the filename to be open
            toFind=string(data(1));

            % look for filepath
            reportIdx = find(cellfun(@(x) isequal(x{1}, toFind), app.OSR.(app.currentOpsWindow).Q3a(:, 3)));

            % Open it for visualization
            rptview(strcat(string(app.OSR.(app.currentOpsWindow).Q3a(reportIdx,2)),'.pdf'), 'pdf');
        end

        % Button pushed function: DeleteReport_Q3a
        function DeleteReport_Q3aPushed(app, event)
            % Get the current items in the list box
            items = app.ListBox_Q3a.Items;

            % Get the index of the selected item
            selectedIndex = app.ListBox_Q3a.Value;

            % Check if the list is empty or only has one item
            if isempty(items) || isscalar(items)
                % If the list is empty or has only one item, clear the list box
                app.ListBox_Q3a.Items = {};
            else
                % Remove the selected item from the list
                items(strcmp(items, selectedIndex)) = [];

                % Update the list box with the new list
                app.ListBox_Q3a.Items = items;

                % Adjust the Value property if the selected index was the last item
                if any(strcmp(selectedIndex, items))
                    app.ListBox_Q3a.Value = items{end};  % Select the last item in the updated list
                else
                    app.ListBox_Q3a.Value = items{1};  % Select the first item in the updated list
                end
            end
        end

        % Button pushed function: AddReport_Q3a
        function AddReport_Q3aPushed(app, event)
            % Add to the textbox and check experiment checkbox

            app.CheckBox_Q3a.Value=true;
            app.EditField_Q3a_OSR.Value=app.ListBox_Q3a.Value;

            % Add to a temporary list for the generation of the OSR

            % split string in list
            data=split(app.ListBox_Q3a.Value,' ');

            % read from list the filename to be open
            toFind=string(data(1));

            % look for filepath
            reportIdx = find(cellfun(@(x) isequal(x{1}, toFind), app.OSR.(app.currentOpsWindow).Q3a(:, 3)));

            newreport = {strcat(string(app.OSR.(app.currentOpsWindow).Q3a(reportIdx,2)),'.pdf')};

            insertPosition = 11;

            app.OSR_reportList{insertPosition} = string(newreport);
        end

        % Button pushed function: OpenReport_Q3b
        function OpenReport_Q3bPushed(app, event)
            % read from list the filename to be open
            % split string in list
            data=split(app.ListBox_Q3b.Value,' ');

            % read from list the filename to be open
            toFind=string(data(1));

            % look for filepath
            reportIdx = find(cellfun(@(x) isequal(x{1}, toFind), app.OSR.(app.currentOpsWindow).Q3b(:, 3)));

            % Open it for visualization
            rptview(strcat(string(app.OSR.(app.currentOpsWindow).Q3b(reportIdx,2)),'.pdf'), 'pdf');
        end

        % Button pushed function: DeleteReport_Q3b
        function DeleteReport_Q3bPushed(app, event)
            % Get the current items in the list box
            items = app.ListBox_Q3b.Items;

            % Get the index of the selected item
            selectedIndex = app.ListBox_Q3b.Value;

            % Check if the list is empty or only has one item
            if isempty(items) || isscalar(items)
                % If the list is empty or has only one item, clear the list box
                app.ListBox_Q3b.Items = {};
            else
                % Remove the selected item from the list
                items(strcmp(items, selectedIndex)) = [];

                % Update the list box with the new list
                app.ListBox_Q3b.Items = items;

                % Adjust the Value property if the selected index was the last item
                if any(strcmp(selectedIndex, items))
                    app.ListBox_Q3b.Value = items{end};  % Select the last item in the updated list
                else
                    app.ListBox_Q3b.Value = items{1};  % Select the first item in the updated list
                end
            end
        end

        % Button pushed function: AddReport_Q3b
        function AddReport_Q3bPushed(app, event)
            % Add to the textbox and check experiment checkbox

            app.CheckBox_Q3b.Value=true;
            app.EditField_Q3b_OSR.Value=app.ListBox_Q3b.Value;

            % Add to a temporary list for the generation of the OSR

            % split string in list
            data=split(app.ListBox_Q3b.Value,' ');

            % read from list the filename to be open
            toFind=string(data(1));

            % look for filepath
            reportIdx = find(cellfun(@(x) isequal(x{1}, toFind), app.OSR.(app.currentOpsWindow).Q3b(:, 3)));

            newreport = {strcat(string(app.OSR.(app.currentOpsWindow).Q3b(reportIdx,2)),'.pdf')};

            insertPosition = 12;

            app.OSR_reportList{insertPosition} = string(newreport);
        end

        % Button pushed function: RestorelistsButton
        function RestorelistsButtonPushed(app, event)
            %--- Repopulate lists
            try
                app.ListBox_Q1a.Items=flipud(string(app.OSR.(app.currentOpsWindow).Q1a(:,4)));
            catch
            end
            try
                app.ListBox_Q1b.Items=flipud(string(app.OSR.(app.currentOpsWindow).Q1b(:,4)));
            catch
            end
            try
                app.ListBox_Q1c.Items=flipud(string(app.OSR.(app.currentOpsWindow).Q1c(:,4)));
            catch
            end
            try
                app.ListBox_Q1d.Items=flipud(string(app.OSR.(app.currentOpsWindow).Q1d(:,4)));
            catch
            end
            try
                app.ListBox_Q1e.Items=flipud(string(app.OSR.(app.currentOpsWindow).Q1e(:,4)));
            catch
            end
            try
                app.ListBox_Q1f.Items=flipud(string(app.OSR.(app.currentOpsWindow).Q1f(:,4)));
            catch
            end
            try
                app.ListBox_Q2a.Items=flipud(string(app.OSR.(app.currentOpsWindow).Q2a(:,4)));
            catch
            end
            try
                app.ListBox_Q2c.Items=flipud(string(app.OSR.(app.currentOpsWindow).Q2c(:,4)));
            catch
            end
            try
                app.ListBox_Q2d.Items=flipud(string(app.OSR.(app.currentOpsWindow).Q2d(:,4)));
            catch
            end
            try
                app.ListBox_Q3a.Items=flipud(string(app.OSR.(app.currentOpsWindow).Q3a(:,4)));
            catch
            end
            try
                app.ListBox_Q3b.Items=flipud(string(app.OSR.(app.currentOpsWindow).Q3b(:,4)));
            catch
            end
        end

        % Value changed function: E1BCheckBox, G1CCheckBox
        function G1CCheckBox_2ValueChanged(app, event)
            valueG1C = app.G1CCheckBox_2.Value;
            valueE1B = app.E1BCheckBox_2.Value;

            if ~valueG1C
                app.FalseAlarmProbabilityEditField_8.Enable="off";
                app.CoherentIntegrationtimesEditField.Enable="off";
                app.NonCoherentsumsEditField_8.Enable="off";
                app.DopplerrangeHzEditField.Enable="off";
                app.DopplercenteroffsetHzEditField.Enable="off";
                app.DopplerstepHzEditField.Enable="off";
                app.CodeDopplerCheckBoxG1C.Enable="off";
            else
                app.FalseAlarmProbabilityEditField_8.Enable="on";
                app.CoherentIntegrationtimesEditField.Enable="on";
                app.NonCoherentsumsEditField_8.Enable="on";
                app.DopplerrangeHzEditField.Enable="on";
                app.DopplercenteroffsetHzEditField.Enable="on";
                app.DopplerstepHzEditField.Enable="on";
                app.CodeDopplerCheckBoxG1C.Enable="on";
            end

            if ~valueE1B
                app.DopplerStepEditField_12.Enable="off";
                app.DopplerStepEditField_22.Enable="off";
                app.DopplerStepEditField_23.Enable="off";
                app.DopplerStepEditField_22.Enable="off";
                app.FalseAlarmProbabilityEditField_12.Enable="off";
                app.CoherentIntegrationtimeEditField_12.Enable="off";
                app.NonCoherentsumsEditField_12.Enable="off";
                app.CodeDopplerCheckBoxE1B.Enable="off";
            else
                app.DopplerStepEditField_12.Enable="on";
                app.DopplerStepEditField_22.Enable="on";
                app.DopplerStepEditField_23.Enable="on";
                app.DopplerStepEditField_22.Enable="on";
                app.FalseAlarmProbabilityEditField_12.Enable="on";
                app.CoherentIntegrationtimeEditField_12.Enable="on";
                app.NonCoherentsumsEditField_12.Enable="on";
                app.CodeDopplerCheckBoxE1B.Enable="on";
            end
        end

        % Button pushed function: OverrideOutputFolder
        function inspectOverrideOutputFolderButtonPushed(app, event)
            selpath = uigetdir(path);

            drawnow;
            figure(app.AnalysisToolUIFigure);

            %--- Ovveride into local variable and GUI field
            app.sessionSettings.outputFilePath = selpath;
            app.OutputDataEditField.Value = app.sessionSettings.outputFilePath;

            %--- Ovveride settings for the next session
            settings = app.sessionSettings;

            %--- Update both matlab and txt
            updateConfigFile('config.txt', settings);

            %--- Check folder attributes
            [~,outPathValues] = fileattrib(app.sessionSettings.outputFilePath);

            if exist(app.sessionSettings.outputFilePath, 'dir') && outPathValues.UserWrite
                app.OutputDataPathLamp.Color = app.colorGreen;
            else
                app.OutputDataPathLamp.Color = app.colorRed;
                updateConsoleBox(app,"[CONGIF.ERR_01] Output data path not found or no writing permission.")
            end
        end

        % Button pushed function: OverrideRINEXFolder
        function OverrideRINEXFolderButtonPushed(app, event)
            [file, folder] = uigetfile('*.*');

            drawnow;
            figure(app.AnalysisToolUIFigure);

            %--- Ovveride into local variable and GUI field
            app.RINEXObsTextArea.Value = fullfile(folder, file);
        end

        % Button pushed function: OverrideRINEXFolder_2
        function OverrideRINEXFolder_2ButtonPushed(app, event)
            [file, folder] = uigetfile('*.*');

            drawnow;
            figure(app.AnalysisToolUIFigure);

            %--- Ovveride into local variable and GUI field
            app.NMEATextArea.Value = fullfile(folder, file);
        end

        % Button pushed function: OverrideIQSFolder
        function OverrideIQSFolderButtonPushed(app, event)
            [file, folder] = uigetfile('*.*');

            drawnow;
            figure(app.AnalysisToolUIFigure);

            %--- Ovveride into local variable and GUI field
            app.IQSFilebinTextArea.Value = fullfile(folder, file);
        end

        % Button pushed function: OverrideRINEXFolder_3
        function OverrideRINEXFolder_3ButtonPushed(app, event)
            [file, folder] = uigetfile('*.*');

            drawnow;
            figure(app.AnalysisToolUIFigure);

            %--- Ovveride into local variable and GUI field
            app.NMEATextArea_2.Value = fullfile(folder, file);
        end

        % Button pushed function: RunExperimentButton_Q1g
        function RunExperimentButton_Q1gPushed(app, event)
            %% Close previous plots, reset lamps and buttons
            close all

            %--- First disable all experiments to avoid interference
            %(activate again when finished running)
            disableExperimentsRunButtons(app);

            app.TextArea_Q1g.Value = "Running experiment...";
            app.CommentArea_Q1g.Value = "No relevant comments.";
            app.ExperimentSuccessLamp_Q1g.Color = app.colorYellow;

            app.ResultsLamp_Q1g.Color = app.colorGray;
            app.ReportLamp_Q1g.Color = app.colorGray;

            resetExpButtons(app);
            drawnow;

            %% Constellations checkboxes
            settings.GPS = app.GPSCheckBox.Value;
            settings.GLO = app.GLOCheckBox.Value;
            settings.GAL = app.GALCheckBox.Value;
            settings.BDS = app.BDSCheckBox.Value;

            %% Run experiment
            try
                [msg100] = PlotSingleCMC(app.folderName, app.obsFileName, app.obsLimit, app.obsEpochDate, settings, 160);
                [msg101] = PlotTEC(app.folderName, app.obsFileName, app.obsLimit, app.obsEpochDate, settings, 161);

                app.ExperimentSuccessLamp_Q1g.Color = app.colorGreen;
                app.SaveResultsButton_Q1g.Enable = "on";

                expDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                app.TextArea_Q1g.Value = sprintf("%s\n%s\n%s",expDate, msg100, msg101);
                fprintf(app.logFileId,strcat(expDate,' Experiment 1g ran succesfully.\n'));
            catch
                app.ExperimentSuccessLamp_Q1g.Color = app.colorRed;

                expDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                app.TextArea_Q1g.Value = sprintf("%s\nExperiment failed!",expDate);
                fprintf(app.logFileId,strcat(expDate,' Experiment 1g failed.\n'));
            end

            %--- Enable all experiments again
            enableExperimentsRunButtons(app);
        end

        % Button pushed function: SaveResultsButton_Q1g
        function SaveResultsButton_Q1gPushed(app, event)
            app.ResultsLamp_Q1g.Color = app.colorYellow;
            drawnow;

            try
                % Save figures
                runId = strcat("r",sprintf("%02d",(app.expCounters(7) + 1)));
                % datetime dropped-off because does not match with input data folder
                resultsFolderName = fullfile(app.sessionSettings.outputFilePath,filesep,app.currentDataset,filesep,"exp_1g",filesep,runId);   % Your destination folder
                app.figurePaths = saveFigs(resultsFolderName);

                % update run counters
                app.expCounters(7) = app.expCounters(7) + 1;
                app.RunEditField_Q1g.Value = app.expCounters(7);

                app.ResultsLamp_Q1g.Color = app.colorGreen;
                app.GenerateReportButton_Q1g.Enable = "on";
                app.SaveResultsButton_Q1g.Enable = "off";

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nResults saved in: %s", saveDate, resultsFolderName);
                app.TextArea_Q1g.Value = [app.TextArea_Q1g.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Results of experiment 1g saved (Run ID: %d)\n", saveDate, app.expCounters(7)));
            catch
                app.ResultsLamp_Q1g.Color = app.colorRed;

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nError saving results", saveDate);
                app.TextArea_Q1g.Value = [app.TextArea_Q1g.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Error saving results of experiment 1g (Run ID: %d)\n", saveDate, app.expCounters(7)));
            end

            scroll(app.TextArea_Q1g, 'bottom');
        end

        % Button pushed function: GenerateReportButton_Q1g
        function GenerateReportButton_Q1gPushed(app, event)
            import mlreportgen.dom.*

            app.ReportLamp_Q1g.Color = app.colorYellow;
            drawnow;

            try
                for iFig = 1:length(app.figurePaths)
                    figures(iFig) = Image(app.figurePaths(iFig));
                end

                runId = strcat("r",sprintf("%02d",(app.expCounters(7))));
                resultsFolderName = fullfile(app.sessionSettings.outputFilePath, filesep, app.currentDataset, filesep, "exp_1g", filesep, runId);

                %--- Document metadata fields
                genTime = string(datetime("now"));
                reportID = string(generateReportSerial([app.opMetaFields, genTime]));

                %--- Report text content
                repTestFields = [genTime, reportID, "1g", string(app.expCounters(7)), app.currentOperator, app.opMetaFields];

                [msg00] = genReport(resultsFolderName, strcat('1g_',reportID), 'rptTmpl\1g_G-PEARL_tmpl.dotx', repTestFields, figures, app.TextArea_Q1g.Value, app.CommentArea_Q1g.Value);

                % ---- if flagged -> Operation Science Report (OSR)
                msg01 = makeAvailable2OSR(app.currentDataset, '1g', resultsFolderName, strcat('1g_',reportID), docMetaFields, app);
                OSR_data = app.OSR;
                save('settings\OSR.mat', "OSR_data");

                %--- Update lamps and buttons
                app.ReportLamp_Q1g.Color = app.colorGreen;
                app.GenerateReportButton_Q1g.Enable = "off";
                %--- Experiment has been completed
                app.expCompleted(7) = true;
                app.Q1g_completed.Color = app.colorGreen;

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nReport saved in: %s\n%s", saveDate, resultsFolderName, msg01);
                app.TextArea_Q1g.Value = [app.TextArea_Q1g.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Report of experiment 1g saved (Run ID: %d)\n", saveDate, app.expCounters(7)));
            catch
                app.ReportLamp_Q1g.Color = app.colorRed;

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nError saving report", saveDate);
                app.TextArea_Q1g.Value = [app.TextArea_Q1g.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Error saving report of experiment 1g (Run ID: %d)\n", saveDate, app.expCounters(7)));
            end

            scroll(app.TextArea_Q1g, 'bottom');
        end

        % Button pushed function: RunExperimentButton_Q1h
        function RunExperimentButton_Q1hPushed(app, event)
            %% Close previous plots, reset lamps and buttons
            close all

            %--- First disable all experiments to avoid interference
            disableExperimentsRunButtons(app);

            app.TextArea_Q1h.Value = "Running experiment...";
            app.CommentArea_Q1h.Value = "No relevant comments.";
            app.ExperimentSuccessLamp_Q1h.Color = app.colorYellow;

            app.ResultsLamp_Q1h.Color = app.colorGray;
            app.ReportLamp_Q1h.Color = app.colorGray;

            resetExpButtons(app);
            drawnow;

            %% Constellations checkboxes
            settings.GPS = app.GPSCheckBox.Value;
            settings.GLO = app.GLOCheckBox.Value;
            settings.GAL = app.GALCheckBox.Value;
            settings.BDS = app.BDSCheckBox.Value;

            %% Run experiment
            try
                %[msg100] = PlotSomething(app.folderName, app.obsFileName, app.obsLimit, app.obsEpochDate, settings, 170);

                app.ExperimentSuccessLamp_Q1h.Color = app.colorGreen;
                app.SaveResultsButton_Q1h.Enable = "on";

                expDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                app.TextArea_Q1h.Value = sprintf("%s\n%s",expDate, msg100);
                fprintf(app.logFileId,strcat(expDate,' Experiment 1h ran succesfully.\n'));
            catch
                app.ExperimentSuccessLamp_Q1h.Color = app.colorRed;

                expDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                app.TextArea_Q1h.Value = sprintf("%s\nExperiment failed!",expDate);
                fprintf(app.logFileId,strcat(expDate,' Experiment 1h failed.\n'));
            end

            %--- Enable all experiments again
            enableExperimentsRunButtons(app);
        end

        % Button pushed function: SaveResultsButton_Q1h
        function SaveResultsButton_Q1hPushed(app, event)
            app.ResultsLamp_Q1h.Color = app.colorYellow;
            drawnow;

            try
                % Save figures
                runId = strcat("r",sprintf("%02d",(app.expCounters(8) + 1)));
                % datetime dropped-off because does not match with input data folder
                resultsFolderName = fullfile(app.sessionSettings.outputFilePath,filesep,app.currentDataset,filesep,"exp_1h",filesep,runId);   % Your destination folder
                app.figurePaths = saveFigs(resultsFolderName);

                % update run counters
                app.expCounters(8) = app.expCounters(8) + 1;
                app.RunEditField_Q1h.Value = app.expCounters(8);

                app.ResultsLamp_Q1h.Color = app.colorGreen;
                app.GenerateReportButton_Q1h.Enable = "on";
                app.SaveResultsButton_Q1h.Enable = "off";

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nResults saved in: %s", saveDate, resultsFolderName);
                app.TextArea_Q1h.Value = [app.TextArea_Q1h.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Results of experiment 1h saved (Run ID: %d)\n", saveDate, app.expCounters(8)));
            catch
                app.ResultsLamp_Q1g.Color = app.colorRed;

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nError saving results", saveDate);
                app.TextArea_Q1h.Value = [app.TextArea_Q1h.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Error saving results of experiment 1h (Run ID: %d)\n", saveDate, app.expCounters(8)));
            end

            scroll(app.TextArea_Q1h, 'bottom');
        end

        % Button pushed function: GenerateReportButton_Q1h
        function GenerateReportButton_Q1hPushed(app, event)
            import mlreportgen.dom.*

            app.ReportLamp_Q1h.Color = app.colorYellow;
            drawnow;

            try
                for iFig = 1:length(app.figurePaths)
                    figures(iFig) = Image(app.figurePaths(iFig));
                end

                runId = strcat("r",sprintf("%02d",(app.expCounters(8))));
                resultsFolderName = fullfile(app.sessionSettings.outputFilePath, filesep, app.currentDataset, filesep, "exp_1h", filesep, runId);

                %--- Document metadata fields
                genTime = string(datetime("now"));
                reportID = string(generateReportSerial([app.opMetaFields, genTime]));

                %--- Report text content
                repTestFields = [genTime, reportID, "1h", string(app.expCounters(8)), app.currentOperator, app.opMetaFields];

                [msg00] = genReport(resultsFolderName, strcat('1h_',reportID), 'rptTmpl\RSPC\Q1h_tmpl_v2.dotx', repTestFields, figures, app.TextArea_Q1h.Value, app.CommentArea_Q1h.Value);

                % ---- if flagged -> Operation Science Report (OSR)
                msg01 = makeAvailable2OSR(app.currentDataset, '1h', resultsFolderName, strcat('1h_',reportID), docMetaFields, app);
                OSR_data = app.OSR;
                save('settings\OSR.mat', "OSR_data");

                %--- Update lamps and buttons
                app.ReportLamp_Q1h.Color = app.colorGreen;
                app.GenerateReportButton_Q1h.Enable = "off";
                %--- Experiment has been completed
                app.expCompleted(8) = true;
                app.Q1g_completed.Color = app.colorGreen;

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nReport saved in: %s\n%s", saveDate, resultsFolderName, msg01);
                app.TextArea_Q1h.Value = [app.TextArea_Q1h.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Report of experiment 1h saved (Run ID: %d)\n", saveDate, app.expCounters(8)));
            catch
                app.ReportLamp_Q1h.Color = app.colorRed;

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nError saving report", saveDate);
                app.TextArea_Q1h.Value = [app.TextArea_Q1h.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Error saving report of experiment 1h (Run ID: %d)\n", saveDate, app.expCounters(8)));
            end

            scroll(app.TextArea_Q1h, 'bottom');
        end

        % Button pushed function: GenerateReportButton_Q2c
        function GenerateReportButton_Q2cPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.AnalysisToolUIFigure = uifigure('Visible', 'off');
            app.AnalysisToolUIFigure.Name = 'Analysis Tool';
            app.AnalysisToolUIFigure.Icon = fullfile(pathToMLAPP, 'images', 'logos', 'LuGRE Insignia.png');
            app.AnalysisToolUIFigure.CloseRequestFcn = createCallbackFcn(app, @AnalysisToolUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.AnalysisToolUIFigure);
            app.ConfigurationTab = uitab(app.TabGroup);
            app.ConfigurationTab.Title = 'Configuration';
            app.Image = uiimage(app.ConfigurationTab);
            app.Image.Tooltip = {''};
            app.Image.ImageSource = fullfile(pathToMLAPP, 'images', 'logos', 'NavSAS_logo_intero.png');
            app.LuGRENavigationAnalysisandReportingToolQuickLookToolLabel = uilabel(app.ConfigurationTab);
            app.LuGRENavigationAnalysisandReportingToolQuickLookToolLabel.Text = 'GNSS Performance analysis, Experimental Assessment and Reporting tooL (v1.0)';
            app.GPEARLLabel = uilabel(app.ConfigurationTab);
            app.GPEARLLabel.Text = 'G-PEARL';
            app.Image3 = uiimage(app.ConfigurationTab);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'images', 'icons', 'G-PEARL_logo 1.png');
            app.SessionsettingsPanel = uipanel(app.ConfigurationTab);
            app.SessionsettingsPanel.Title = 'Session settings';
            app.Panel_17 = uipanel(app.SessionsettingsPanel);
            app.OutputDataEditField = uitextarea(app.Panel_17);
            app.OutputDataEditField.Editable = 'off';
            app.OutputDataEditField.Tooltip = {'Relative path should be ".\POC\SCIENCE\RSPC"'};
            app.OutputFolderLabel = uilabel(app.Panel_17);
            app.OutputFolderLabel.Text = 'Output folder';
            app.OverrideOutputFolder = uibutton(app.Panel_17, 'push');
            app.OverrideOutputFolder.ButtonPushedFcn = createCallbackFcn(app, @inspectOverrideOutputFolderButtonPushed, true);
            app.OverrideOutputFolder.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-folder-50.png');
            app.OverrideOutputFolder.Tooltip = {'Explore folder (not possible to override selection)'};
            app.OverrideOutputFolder.Text = '';
            app.OutputDataPathLamp = uilamp(app.Panel_17);
            app.OutputDataPathLamp.Tooltip = {'If red results will be stored locally only. Send to POC will fail.'};
            app.UserTextAreaLabel = uilabel(app.Panel_17);
            app.UserTextAreaLabel.Text = 'User';
            app.UserTextArea = uitextarea(app.Panel_17);
            app.UserTextArea.Placeholder = 'Default_User';
            app.LoadDataButton = uibutton(app.SessionsettingsPanel, 'state');
            app.LoadDataButton.ValueChangedFcn = createCallbackFcn(app, @LoadDataButtonValueChanged, true);
            app.LoadDataButton.Tooltip = {'Parse telemetry data'};
            app.LoadDataButton.Text = 'Load Data';
            app.Label_136 = uilabel(app.SessionsettingsPanel);
            app.Label_136.Text = '';
            app.Label_137 = uilabel(app.SessionsettingsPanel);
            app.Label_137.Text = '';
            app.ReferenceDataPanel = uipanel(app.SessionsettingsPanel);
            app.ReferenceDataPanel.Title = 'Reference Data';
            app.NMEATextArea_2Label = uilabel(app.ReferenceDataPanel);
            app.NMEATextArea_2Label.Text = 'NMEA';
            app.NMEATextArea_2 = uitextarea(app.ReferenceDataPanel);
            app.NMEATextArea_2.Editable = 'off';
            app.OverrideRINEXFolder_3 = uibutton(app.ReferenceDataPanel, 'push');
            app.OverrideRINEXFolder_3.ButtonPushedFcn = createCallbackFcn(app, @OverrideRINEXFolder_3ButtonPushed, true);
            app.OverrideRINEXFolder_3.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-folder-50.png');
            app.OverrideRINEXFolder_3.Tooltip = {'Explore folder (not possible to override selection)'};
            app.OverrideRINEXFolder_3.Text = '';
            app.ToolStatusPanel = uipanel(app.ConfigurationTab);
            app.ToolStatusPanel.Title = 'Tool Status';
            app.ResetSessionButton = uibutton(app.ToolStatusPanel, 'push');
            app.ResetSessionButton.ButtonPushedFcn = createCallbackFcn(app, @ResetSessionButtonPushed, true);
            app.ResetSessionButton.Tooltip = {'Close plots, clear all variables and corresponding UI elements and refresh available OPs in the input folder (does not affect prediction mode)'};
            app.ResetSessionButton.Text = 'Reset Session';
            app.InputDataLoadingLabel = uilabel(app.ToolStatusPanel);
            app.InputDataLoadingLabel.Text = 'RINEX Obs ....................';
            app.RINEXObsLamp = uilamp(app.ToolStatusPanel);
            app.RINEXnavdataloadingLabel = uilabel(app.ToolStatusPanel);
            app.RINEXnavdataloadingLabel.Text = 'NMEA Nav .....................';
            app.NMEANavLamp = uilamp(app.ToolStatusPanel);
            app.NMEANavreferenceLampLabel = uilabel(app.ToolStatusPanel);
            app.NMEANavreferenceLampLabel.Text = 'NMEA Nav reference .....';
            app.NMEANavreferenceLamp = uilamp(app.ToolStatusPanel);
            app.ReceiverDataPanel = uipanel(app.ConfigurationTab);
            app.ReceiverDataPanel.Title = 'Receiver Data';
            app.OverrideRINEXFolder = uibutton(app.ReceiverDataPanel, 'push');
            app.OverrideRINEXFolder.ButtonPushedFcn = createCallbackFcn(app, @OverrideRINEXFolderButtonPushed, true);
            app.OverrideRINEXFolder.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-folder-50.png');
            app.OverrideRINEXFolder.Tooltip = {'Explore folder (not possible to override selection)'};
            app.OverrideRINEXFolder.Text = '';
            app.OverrideRINEXFolder_2 = uibutton(app.ReceiverDataPanel, 'push');
            app.OverrideRINEXFolder_2.ButtonPushedFcn = createCallbackFcn(app, @OverrideRINEXFolder_2ButtonPushed, true);
            app.OverrideRINEXFolder_2.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-folder-50.png');
            app.OverrideRINEXFolder_2.Tooltip = {'Explore folder (not possible to override selection)'};
            app.OverrideRINEXFolder_2.Text = '';
            app.RINEXObsTextAreaLabel = uilabel(app.ReceiverDataPanel);
            app.RINEXObsTextAreaLabel.Text = 'RINEX Obs';
            app.RINEXObsTextArea = uitextarea(app.ReceiverDataPanel);
            app.RINEXObsTextArea.Editable = 'off';
            app.NMEATextAreaLabel = uilabel(app.ReceiverDataPanel);
            app.NMEATextAreaLabel.Text = 'NMEA';
            app.NMEATextArea = uitextarea(app.ReceiverDataPanel);
            app.NMEATextArea.Editable = 'off';
            app.ConsolePanel = uipanel(app.ConfigurationTab);
            app.ConsolePanel.Title = 'Console';
            app.consoleWindow = uitextarea(app.ConsolePanel);
            app.Image4 = uiimage(app.ConfigurationTab);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'images', 'logos', 'cncg.png');
            app.Image4_2 = uiimage(app.ConfigurationTab);
            app.Image4_2.ImageSource = fullfile(pathToMLAPP, 'images', 'logos', 'CNIT.png');
            app.AnalysesTab = uitab(app.TabGroup);
            app.AnalysesTab.Title = 'Analyses';
            app.TabGroup3 = uitabgroup(app.AnalysesTab);
            app.aTab = uitab(app.TabGroup3);
            app.aTab.Title = '1.a';
            app.Label_12 = uilabel(app.aTab);
            app.Label_12.Text = '';
            app.RunExperimentButton_Q1a = uibutton(app.aTab, 'push');
            app.RunExperimentButton_Q1a.ButtonPushedFcn = createCallbackFcn(app, @RunExperimentButton_Q1aPushed, true);
            app.RunExperimentButton_Q1a.Enable = 'off';
            app.RunExperimentButton_Q1a.Text = 'Run Experiment';
            app.OutputPanel_15 = uipanel(app.aTab);
            app.OutputPanel_15.Title = 'Output';
            app.TextArea_Q1a = uitextarea(app.OutputPanel_15);
            app.TextArea_Q1a.Editable = 'off';
            app.TextArea_Q1a.Enable = 'off';
            app.GenerateReportButton_Q1a = uibutton(app.aTab, 'push');
            app.GenerateReportButton_Q1a.ButtonPushedFcn = createCallbackFcn(app, @GenerateReportButton_Q1aPushed, true);
            app.GenerateReportButton_Q1a.Enable = 'off';
            app.GenerateReportButton_Q1a.Tooltip = {'Generate word and pdf report associated to the current run.'};
            app.GenerateReportButton_Q1a.Text = 'Generate Report';
            app.ExperimentSuccessLamp_Q1a = uilamp(app.aTab);
            app.ReportLamp_Q1a = uilamp(app.aTab);
            app.SaveResultsButton_Q1a = uibutton(app.aTab, 'push');
            app.SaveResultsButton_Q1a.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsButton_Q1aPushed, true);
            app.SaveResultsButton_Q1a.Enable = 'off';
            app.SaveResultsButton_Q1a.Tooltip = {'Save figures and relevant results from the experiment.'};
            app.SaveResultsButton_Q1a.Text = 'Save Results';
            app.ResultsLamp_Q1a = uilamp(app.aTab);
            app.OperatorCommentincludedinthereportPanel = uipanel(app.aTab);
            app.OperatorCommentincludedinthereportPanel.Title = 'Operator Comment (included in the report)';
            app.CommentArea_Q1a = uitextarea(app.OperatorCommentincludedinthereportPanel);
            app.CommentArea_Q1a.Value = {'No relevant comments.'};
            app.Experiment1aLabel_18 = uilabel(app.aTab);
            app.Experiment1aLabel_18.Text = '1.a | Measure signal strength (C/No) ';
            app.Label_121 = uilabel(app.aTab);
            app.Label_121.Text = '• [Plot - 100] Measured C/No time series';
            app.Experiment1bLabel_8 = uilabel(app.aTab);
            app.Experiment1bLabel_8.Text = 'Expected Products';
            app.runPanel = uipanel(app.aTab);
            app.RunEditFieldLabel = uilabel(app.runPanel);
            app.RunEditFieldLabel.Text = 'Run';
            app.RunEditField_Q1a = uieditfield(app.runPanel, 'numeric');
            app.RunEditField_Q1a.Limits = [0 5000];
            app.RunEditField_Q1a.Editable = 'off';
            app.RunEditField_Q1a.Enable = 'off';
            app.bTab = uitab(app.TabGroup3);
            app.bTab.Title = '1.b';
            app.Experiment1bLabel = uilabel(app.bTab);
            app.Experiment1bLabel.Text = '1.b | Evaluate signal visibility and PVT availability';
            app.OutputPanel_2 = uipanel(app.bTab);
            app.OutputPanel_2.Title = 'Output';
            app.TextArea_Q1b = uitextarea(app.OutputPanel_2);
            app.TextArea_Q1b.Editable = 'off';
            app.TextArea_Q1b.Enable = 'off';
            app.ExperimentSuccessLamp_Q1b = uilamp(app.bTab);
            app.RunExperimentButton_Q1b = uibutton(app.bTab, 'push');
            app.RunExperimentButton_Q1b.ButtonPushedFcn = createCallbackFcn(app, @RunExperimentButton_Q1bPushed, true);
            app.RunExperimentButton_Q1b.Enable = 'off';
            app.RunExperimentButton_Q1b.Text = 'Run Experiment';
            app.GenerateReportButton_Q1b = uibutton(app.bTab, 'push');
            app.GenerateReportButton_Q1b.ButtonPushedFcn = createCallbackFcn(app, @GenerateReportButton_Q1bPushed, true);
            app.GenerateReportButton_Q1b.Enable = 'off';
            app.GenerateReportButton_Q1b.Text = 'Generate Report';
            app.SettingsPanel_2 = uipanel(app.bTab);
            app.SettingsPanel_2.Title = 'Settings';
            app.CN0ThresholdSpinnerLabel = uilabel(app.SettingsPanel_2);
            app.CN0ThresholdSpinnerLabel.Text = 'C/N0 Threshold';
            app.CN0ThresholdSpinner = uispinner(app.SettingsPanel_2);
            app.CN0ThresholdSpinner.Step = 0.1;
            app.CN0ThresholdSpinner.Limits = [10 50];
            app.CN0ThresholdSpinner.ValueDisplayFormat = '%11.1f';
            app.CN0ThresholdSpinner.ValueChangedFcn = createCallbackFcn(app, @CN0ThresholdSpinnerValueChanged, true);
            app.CN0ThresholdSpinner.Value = 18;
            app.Label_85 = uilabel(app.SettingsPanel_2);
            app.Label_85.Text = 'dB-Hz';
            app.OperatorCommentincludedinthereportPanel_6 = uipanel(app.bTab);
            app.OperatorCommentincludedinthereportPanel_6.Title = 'Operator Comment (included in the report)';
            app.CommentArea_Q1b = uitextarea(app.OperatorCommentincludedinthereportPanel_6);
            app.CommentArea_Q1b.Value = {'No relevant comments.'};
            app.ResultsLamp_Q1b = uilamp(app.bTab);
            app.ReportLamp_Q1b = uilamp(app.bTab);
            app.SaveResultsButton_Q1b = uibutton(app.bTab, 'push');
            app.SaveResultsButton_Q1b.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsButton_Q1bPushed, true);
            app.SaveResultsButton_Q1b.Enable = 'off';
            app.SaveResultsButton_Q1b.Text = 'Save Results';
            app.Experiment1bLabel_4 = uilabel(app.bTab);
            app.Experiment1bLabel_4.Text = 'Expected Products';
            app.runPanel_Q1b = uipanel(app.bTab);
            app.RunEditField_2Label = uilabel(app.runPanel_Q1b);
            app.RunEditField_2Label.Text = 'Run';
            app.RunEditField_Q1b = uieditfield(app.runPanel_Q1b, 'numeric');
            app.RunEditField_Q1b.Limits = [0 5000];
            app.RunEditField_Q1b.Editable = 'off';
            app.RunEditField_Q1b.Enable = 'off';
            app.ScienceProductLabel_20 = uilabel(app.bTab);
            app.ScienceProductLabel_20.Text = {'• [Plot - 110] Time series of the number of unique visible satellites per band'; '• [Plot - 111] Time series of the number of unique visible satellite per constellation and total'; '• [Plot - 112] Time series of single and multi-constellation Single Point Positioning (SPP) solution availability (with time percentages)'};
            app.cTab = uitab(app.TabGroup3);
            app.cTab.Title = '1.c';
            app.Experiment1cLabel = uilabel(app.cTab);
            app.Experiment1cLabel.Text = '1.c | Measure raw code pseudoranges';
            app.OutputPanel_3 = uipanel(app.cTab);
            app.OutputPanel_3.Title = 'Output';
            app.TextArea_Q1c = uitextarea(app.OutputPanel_3);
            app.TextArea_Q1c.Editable = 'off';
            app.TextArea_Q1c.Enable = 'off';
            app.ExperimentSuccessLamp_Q1c = uilamp(app.cTab);
            app.RunExperimentButton_Q1c = uibutton(app.cTab, 'push');
            app.RunExperimentButton_Q1c.ButtonPushedFcn = createCallbackFcn(app, @RunExperimentButton_Q1cPushed, true);
            app.RunExperimentButton_Q1c.Enable = 'off';
            app.RunExperimentButton_Q1c.Text = 'Run Experiment';
            app.GenerateReportButton_Q1c = uibutton(app.cTab, 'push');
            app.GenerateReportButton_Q1c.ButtonPushedFcn = createCallbackFcn(app, @GenerateReportButton_Q1cPushed, true);
            app.GenerateReportButton_Q1c.Enable = 'off';
            app.GenerateReportButton_Q1c.Text = 'Generate Report';
            app.OperatorCommentincludedinthereportPanel_7 = uipanel(app.cTab);
            app.OperatorCommentincludedinthereportPanel_7.Title = 'Operator Comment (included in the report)';
            app.CommentArea_Q1c = uitextarea(app.OperatorCommentincludedinthereportPanel_7);
            app.CommentArea_Q1c.Value = {'No relevant comments.'};
            app.ResultsLamp_Q1c = uilamp(app.cTab);
            app.ReportLamp_Q1c = uilamp(app.cTab);
            app.SaveResultsButton_Q1c = uibutton(app.cTab, 'push');
            app.SaveResultsButton_Q1c.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsButton_Q1cPushed, true);
            app.SaveResultsButton_Q1c.Enable = 'off';
            app.SaveResultsButton_Q1c.Text = 'Save Results';
            app.ScienceProductLabel_4 = uilabel(app.cTab);
            app.ScienceProductLabel_4.Text = {'• [Plot - 120] Raw code pseudorange times series'; ''};
            app.Experiment1bLabel_5 = uilabel(app.cTab);
            app.Experiment1bLabel_5.Text = 'Expected Products';
            app.runPanel_Q1c = uipanel(app.cTab);
            app.RunEditField_3Label = uilabel(app.runPanel_Q1c);
            app.RunEditField_3Label.Text = 'Run';
            app.RunEditField_Q1c = uieditfield(app.runPanel_Q1c, 'numeric');
            app.RunEditField_Q1c.Limits = [0 5000];
            app.RunEditField_Q1c.Editable = 'off';
            app.RunEditField_Q1c.Enable = 'off';
            app.dTab = uitab(app.TabGroup3);
            app.dTab.Title = '1.d';
            app.Experiment1dLabel = uilabel(app.dTab);
            app.Experiment1dLabel.Text = '1.d | Evaluate pseudorange errors';
            app.OutputPanel_4 = uipanel(app.dTab);
            app.OutputPanel_4.Title = 'Output';
            app.TextArea_Q1d = uitextarea(app.OutputPanel_4);
            app.TextArea_Q1d.Editable = 'off';
            app.TextArea_Q1d.Enable = 'off';
            app.ExperimentSuccessLamp_Q1d = uilamp(app.dTab);
            app.ScienceProductLabel = uilabel(app.dTab);
            app.ScienceProductLabel.Text = {'• [Plot - 130] Finite-difference third derivative time series of code pseudoranges'; '• [Plot - 131] Finite-difference third derivative time series of phase pseudoranges  '; '• [Table - 132] Estimated measurement noise bias and standard deviation from Plot 130 and 131'};
            app.GenerateReportButton_Q1d = uibutton(app.dTab, 'push');
            app.GenerateReportButton_Q1d.ButtonPushedFcn = createCallbackFcn(app, @GenerateReportButton_Q1dPushed, true);
            app.GenerateReportButton_Q1d.Enable = 'off';
            app.GenerateReportButton_Q1d.Text = 'Generate Report';
            app.ReportLamp_Q1d = uilamp(app.dTab);
            app.SaveResultsButton_Q1d = uibutton(app.dTab, 'push');
            app.SaveResultsButton_Q1d.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsButton_Q1dPushed, true);
            app.SaveResultsButton_Q1d.Enable = 'off';
            app.SaveResultsButton_Q1d.Text = 'Save Results';
            app.ResultsLamp_Q1d = uilamp(app.dTab);
            app.OperatorComment_4 = uipanel(app.dTab);
            app.OperatorComment_4.Title = 'Operator Comment (included in the report)';
            app.CommentArea_Q1d = uitextarea(app.OperatorComment_4);
            app.CommentArea_Q1d.Value = {'No relevant comments.'};
            app.PseudorangeerroranalysisLabel = uilabel(app.dTab);
            app.PseudorangeerroranalysisLabel.Text = 'Pseudorange error analysis';
            app.UITable_Q1d = uitable(app.dTab);
            app.UITable_Q1d.ColumnName = {'Channel'; 'PRN'; 'Code Mean [m]'; 'Code Std [m]'; 'Phase Mean [m]'; 'Phase Std [m]'};
            app.UITable_Q1d.RowName = {};
            app.UITable_Q1d.CellEditCallback = createCallbackFcn(app, @UITable_Q1dCellEdit, true);
            app.Experiment1bLabel_6 = uilabel(app.dTab);
            app.Experiment1bLabel_6.Text = 'Expected Products';
            app.runPanel_Q1d = uipanel(app.dTab);
            app.RunEditField_4Label = uilabel(app.runPanel_Q1d);
            app.RunEditField_4Label.Text = 'Run';
            app.RunEditField_Q1d = uieditfield(app.runPanel_Q1d, 'numeric');
            app.RunEditField_Q1d.Limits = [0 5000];
            app.RunEditField_Q1d.Editable = 'off';
            app.RunEditField_Q1d.Enable = 'off';
            app.RunExperimentButton_Q1d = uibutton(app.dTab, 'push');
            app.RunExperimentButton_Q1d.ButtonPushedFcn = createCallbackFcn(app, @RunExperimentButton_Q1dPushed, true);
            app.RunExperimentButton_Q1d.Enable = 'off';
            app.RunExperimentButton_Q1d.Text = 'Run Experiment';
            app.eTab = uitab(app.TabGroup3);
            app.eTab.SizeChangedFcn = createCallbackFcn(app, @eTabSizeChanged, true);
            app.eTab.Title = '1.e';
            app.Experiment1eLabel = uilabel(app.eTab);
            app.Experiment1eLabel.Text = '1.e | Measure Doppler shifts';
            app.OutputPanel_5 = uipanel(app.eTab);
            app.OutputPanel_5.Title = 'Output';
            app.TextArea_Q1e = uitextarea(app.OutputPanel_5);
            app.TextArea_Q1e.Editable = 'off';
            app.TextArea_Q1e.Enable = 'off';
            app.ExperimentSuccessLamp_Q1e = uilamp(app.eTab);
            app.RunExperimentButton_Q1e = uibutton(app.eTab, 'push');
            app.RunExperimentButton_Q1e.ButtonPushedFcn = createCallbackFcn(app, @RunExperimentButton_Q1ePushed, true);
            app.RunExperimentButton_Q1e.Enable = 'off';
            app.RunExperimentButton_Q1e.Text = 'Run Experiment';
            app.GenerateReportButton_Q1e = uibutton(app.eTab, 'push');
            app.GenerateReportButton_Q1e.ButtonPushedFcn = createCallbackFcn(app, @GenerateReportButton_Q1ePushed, true);
            app.GenerateReportButton_Q1e.Enable = 'off';
            app.GenerateReportButton_Q1e.Text = 'Generate Report';
            app.OperatorCommentincludedinthereportPanel_10 = uipanel(app.eTab);
            app.OperatorCommentincludedinthereportPanel_10.Title = 'Operator Comment (included in the report)';
            app.CommentArea_Q1e = uitextarea(app.OperatorCommentincludedinthereportPanel_10);
            app.CommentArea_Q1e.Value = {'No relevant comments.'};
            app.ReportLamp_Q1e = uilamp(app.eTab);
            app.ResultsLamp_Q1e = uilamp(app.eTab);
            app.SaveResultsButton_Q1e = uibutton(app.eTab, 'push');
            app.SaveResultsButton_Q1e.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsButton_Q1ePushed, true);
            app.SaveResultsButton_Q1e.Enable = 'off';
            app.SaveResultsButton_Q1e.Text = 'Save Results';
            app.runPanel_Q1e = uipanel(app.eTab);
            app.RunEditField_5Label = uilabel(app.runPanel_Q1e);
            app.RunEditField_5Label.Text = 'Run';
            app.RunEditField_Q1e = uieditfield(app.runPanel_Q1e, 'numeric');
            app.RunEditField_Q1e.Limits = [0 5000];
            app.RunEditField_Q1e.Editable = 'off';
            app.RunEditField_Q1e.Enable = 'off';
            app.ScienceProductLabel_16 = uilabel(app.eTab);
            app.ScienceProductLabel_16.Text = '• [Plot - 140] Doppler shift time series';
            app.Experiment1bLabel_9 = uilabel(app.eTab);
            app.Experiment1bLabel_9.Text = 'Expected Products';
            app.fTab = uitab(app.TabGroup3);
            app.fTab.Title = '1.f';
            app.Experiment1fLabel = uilabel(app.fTab);
            app.Experiment1fLabel.Text = '1.f | Evaluate the presence and characteristics of multipath';
            app.ExperimentSuccessLamp_Q1f = uilamp(app.fTab);
            app.RunExperimentButton_Q1f = uibutton(app.fTab, 'push');
            app.RunExperimentButton_Q1f.ButtonPushedFcn = createCallbackFcn(app, @RunExperimentButton_Q1fPushed, true);
            app.RunExperimentButton_Q1f.Enable = 'off';
            app.RunExperimentButton_Q1f.Text = 'Run Experiment';
            app.ScienceProductLabel_8 = uilabel(app.fTab);
            app.ScienceProductLabel_8.Text = {'• [Plot - 150] Double-frequency (2F) detrended CMC time series'; '• [Table - 151] List of outliers with associated PRN, epoch and value'; ''};
            app.Experiment1bLabel_14 = uilabel(app.fTab);
            app.Experiment1bLabel_14.Text = 'Expected Products';
            app.runPanel_Q1f = uipanel(app.fTab);
            app.RunEditField_5Label_2 = uilabel(app.runPanel_Q1f);
            app.RunEditField_5Label_2.Text = 'Run';
            app.RunEditField_Q1f = uieditfield(app.runPanel_Q1f, 'numeric');
            app.RunEditField_Q1f.Limits = [0 5000];
            app.RunEditField_Q1f.Editable = 'off';
            app.RunEditField_Q1f.Enable = 'off';
            app.OutputPanel_19 = uipanel(app.fTab);
            app.OutputPanel_19.Title = 'Output';
            app.TextArea_Q1f = uitextarea(app.OutputPanel_19);
            app.TextArea_Q1f.Editable = 'off';
            app.TextArea_Q1f.Enable = 'off';
            app.OperatorCommentincludedinthereportPanel_11 = uipanel(app.fTab);
            app.OperatorCommentincludedinthereportPanel_11.Title = 'Operator Comment (included in the report)';
            app.CommentArea_Q1f = uitextarea(app.OperatorCommentincludedinthereportPanel_11);
            app.CommentArea_Q1f.Value = {'No relevant comments.'};
            app.GenerateReportButton_Q1f = uibutton(app.fTab, 'push');
            app.GenerateReportButton_Q1f.ButtonPushedFcn = createCallbackFcn(app, @GenerateReportButton_Q1fPushed, true);
            app.GenerateReportButton_Q1f.Enable = 'off';
            app.GenerateReportButton_Q1f.Text = 'Generate Report';
            app.ReportLamp_Q1f = uilamp(app.fTab);
            app.ResultsLamp_Q1f = uilamp(app.fTab);
            app.SaveResultsButton_Q1f = uibutton(app.fTab, 'push');
            app.SaveResultsButton_Q1f.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsButton_Q1fPushed, true);
            app.SaveResultsButton_Q1f.Enable = 'off';
            app.SaveResultsButton_Q1f.Text = 'Save Results';
            app.SettingsPanel = uipanel(app.fTab);
            app.SettingsPanel.Title = 'Settings';
            app.WindowSizeSpinnerLabel = uilabel(app.SettingsPanel);
            app.WindowSizeSpinnerLabel.Text = 'Window Size';
            app.WindowSizeSpinner = uispinner(app.SettingsPanel);
            app.WindowSizeSpinner.Step = 10;
            app.WindowSizeSpinner.Limits = [50 200];
            app.WindowSizeSpinner.RoundFractionalValues = 'on';
            app.WindowSizeSpinner.ValueDisplayFormat = '%d';
            app.WindowSizeSpinner.Tooltip = {'Define size of window used for variance estimation'};
            app.WindowSizeSpinner.Value = 100;
            app.ThresholdSpinnerLabel = uilabel(app.SettingsPanel);
            app.ThresholdSpinnerLabel.Text = 'Threshold';
            app.ThresholdSpinner = uispinner(app.SettingsPanel);
            app.ThresholdSpinner.Step = 0.1;
            app.ThresholdSpinner.Limits = [2 5];
            app.ThresholdSpinner.ValueDisplayFormat = '%11.1f';
            app.ThresholdSpinner.Tooltip = {'Define threshold as a multiple of the estimated standard deviation'};
            app.ThresholdSpinner.Value = 4;
            app.FindOutliersCheckBox = uicheckbox(app.SettingsPanel);
            app.FindOutliersCheckBox.Text = 'Find Outliers?';
            app.UITable_Q1f_L1 = uitable(app.fTab);
            app.UITable_Q1f_L1.ColumnName = {'PRN'; 'Time'; 'Value'};
            app.UITable_Q1f_L1.RowName = {};
            app.UITable_Q1f_L5 = uitable(app.fTab);
            app.UITable_Q1f_L5.ColumnName = {'PRN'; 'Time'; 'Value'};
            app.UITable_Q1f_L5.RowName = {};
            app.L1E1OutliersLabel = uilabel(app.fTab);
            app.L1E1OutliersLabel.Text = 'L1 / E1 Outliers';
            app.L5E5OutliersLabel = uilabel(app.fTab);
            app.L5E5OutliersLabel.Text = 'L5 / E5 Outliers';
            app.gTab = uitab(app.TabGroup3);
            app.gTab.Title = '1.g';
            app.Experiment1gLabel = uilabel(app.gTab);
            app.Experiment1gLabel.Text = '1.g | Evaluate the effect of Ionosphere ';
            app.ScienceProductLabel_24 = uilabel(app.gTab);
            app.ScienceProductLabel_24.Text = {'• [Plot - 160] Single frequency (1F) CMC time series (shifted around 0)'; '• [Plot - 161] Slant TEC time series'};
            app.Experiment1bLabel_17 = uilabel(app.gTab);
            app.Experiment1bLabel_17.Text = 'Expected Products';
            app.runPanel_Q1g = uipanel(app.gTab);
            app.RunEditField_5Label_3 = uilabel(app.runPanel_Q1g);
            app.RunEditField_5Label_3.Text = 'Run';
            app.RunEditField_Q1g = uieditfield(app.runPanel_Q1g, 'numeric');
            app.RunEditField_Q1g.Limits = [0 5000];
            app.RunEditField_Q1g.Editable = 'off';
            app.RunEditField_Q1g.Enable = 'off';
            app.ExperimentSuccessLamp_Q1g = uilamp(app.gTab);
            app.RunExperimentButton_Q1g = uibutton(app.gTab, 'push');
            app.RunExperimentButton_Q1g.ButtonPushedFcn = createCallbackFcn(app, @RunExperimentButton_Q1gPushed, true);
            app.RunExperimentButton_Q1g.Enable = 'off';
            app.RunExperimentButton_Q1g.Text = 'Run Experiment';
            app.OperatorCommentincludedinthereportPanel_13 = uipanel(app.gTab);
            app.OperatorCommentincludedinthereportPanel_13.Title = 'Operator Comment (included in the report)';
            app.CommentArea_Q1g = uitextarea(app.OperatorCommentincludedinthereportPanel_13);
            app.CommentArea_Q1g.Value = {'No relevant comments.'};
            app.OutputPanel_22 = uipanel(app.gTab);
            app.OutputPanel_22.Title = 'Output';
            app.TextArea_Q1g = uitextarea(app.OutputPanel_22);
            app.TextArea_Q1g.Editable = 'off';
            app.TextArea_Q1g.Enable = 'off';
            app.GenerateReportButton_Q1g = uibutton(app.gTab, 'push');
            app.GenerateReportButton_Q1g.ButtonPushedFcn = createCallbackFcn(app, @GenerateReportButton_Q1gPushed, true);
            app.GenerateReportButton_Q1g.Enable = 'off';
            app.GenerateReportButton_Q1g.Text = 'Generate Report';
            app.ReportLamp_Q1g = uilamp(app.gTab);
            app.ResultsLamp_Q1g = uilamp(app.gTab);
            app.SaveResultsButton_Q1g = uibutton(app.gTab, 'push');
            app.SaveResultsButton_Q1g.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsButton_Q1gPushed, true);
            app.SaveResultsButton_Q1g.Enable = 'off';
            app.SaveResultsButton_Q1g.Text = 'Save Results';
            app.hTab = uitab(app.TabGroup3);
            app.hTab.Title = '1.h';
            app.Experiment1fLabel_3 = uilabel(app.hTab);
            app.Experiment1fLabel_3.Text = '1.h | Evaluate the precence of interferences';
            app.Experiment1bLabel_18 = uilabel(app.hTab);
            app.Experiment1bLabel_18.Text = 'Expected Products';
            app.runPanel_Q1h = uipanel(app.hTab);
            app.RunEditField_5Label_4 = uilabel(app.runPanel_Q1h);
            app.RunEditField_5Label_4.Text = 'Run';
            app.RunEditField_Q1h = uieditfield(app.runPanel_Q1h, 'numeric');
            app.RunEditField_Q1h.Limits = [0 5000];
            app.RunEditField_Q1h.Editable = 'off';
            app.RunEditField_Q1h.Enable = 'off';
            app.ExperimentSuccessLamp_Q1h = uilamp(app.hTab);
            app.RunExperimentButton_Q1h = uibutton(app.hTab, 'push');
            app.RunExperimentButton_Q1h.ButtonPushedFcn = createCallbackFcn(app, @RunExperimentButton_Q1hPushed, true);
            app.RunExperimentButton_Q1h.Enable = 'off';
            app.RunExperimentButton_Q1h.Text = 'Run Experiment';
            app.OperatorCommentincludedinthereportPanel_14 = uipanel(app.hTab);
            app.OperatorCommentincludedinthereportPanel_14.Title = 'Operator Comment (included in the report)';
            app.CommentArea_Q1h = uitextarea(app.OperatorCommentincludedinthereportPanel_14);
            app.CommentArea_Q1h.Value = {'No relevant comments.'};
            app.OutputPanel_23 = uipanel(app.hTab);
            app.OutputPanel_23.Title = 'Output';
            app.TextArea_Q1h = uitextarea(app.OutputPanel_23);
            app.TextArea_Q1h.Editable = 'off';
            app.TextArea_Q1h.Enable = 'off';
            app.GenerateReportButton_Q1h = uibutton(app.hTab, 'push');
            app.GenerateReportButton_Q1h.ButtonPushedFcn = createCallbackFcn(app, @GenerateReportButton_Q1hPushed, true);
            app.GenerateReportButton_Q1h.Enable = 'off';
            app.GenerateReportButton_Q1h.Text = 'Generate Report';
            app.ReportLamp_Q1h = uilamp(app.hTab);
            app.ResultsLamp_Q1h = uilamp(app.hTab);
            app.SaveResultsButton_Q1h = uibutton(app.hTab, 'push');
            app.SaveResultsButton_Q1h.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsButton_Q1hPushed, true);
            app.SaveResultsButton_Q1h.Enable = 'off';
            app.SaveResultsButton_Q1h.Text = 'Save Results';
            app.ScienceProductLabel_27 = uilabel(app.hTab);
            app.ScienceProductLabel_27.Text = {'• [Plot - 170] '; ''};
            app.aTab_2 = uitab(app.TabGroup3);
            app.aTab_2.Title = '2.a';
            app.Experiment2aLabel = uilabel(app.aTab_2);
            app.Experiment2aLabel.Text = '2.a | Characterize Positioning solutions';
            app.OutputPanel_8 = uipanel(app.aTab_2);
            app.OutputPanel_8.Title = 'Output';
            app.TextArea_Q2a = uitextarea(app.OutputPanel_8);
            app.TextArea_Q2a.Editable = 'off';
            app.TextArea_Q2a.Enable = 'off';
            app.ExperimentSuccessLamp_Q2a = uilamp(app.aTab_2);
            app.RunExperimentButton_Q2a = uibutton(app.aTab_2, 'push');
            app.RunExperimentButton_Q2a.ButtonPushedFcn = createCallbackFcn(app, @RunExperimentButton_Q2aPushed, true);
            app.RunExperimentButton_Q2a.Enable = 'off';
            app.RunExperimentButton_Q2a.Text = 'Run Experiment';
            app.GenerateReportButton_Q2a = uibutton(app.aTab_2, 'push');
            app.GenerateReportButton_Q2a.ButtonPushedFcn = createCallbackFcn(app, @GenerateReportButton_Q2aPushed, true);
            app.GenerateReportButton_Q2a.Enable = 'off';
            app.GenerateReportButton_Q2a.Text = 'Generate Report';
            app.OperatorCommentincludedinthereportPanel_12 = uipanel(app.aTab_2);
            app.OperatorCommentincludedinthereportPanel_12.Title = 'Operator Comment (included in the report)';
            app.CommentArea_Q2a = uitextarea(app.OperatorCommentincludedinthereportPanel_12);
            app.CommentArea_Q2a.Value = {'No relevant comments.'};
            app.ResultsLamp_Q2a = uilamp(app.aTab_2);
            app.ReportLamp_Q2a = uilamp(app.aTab_2);
            app.SaveResultsButton_Q2a = uibutton(app.aTab_2, 'push');
            app.SaveResultsButton_Q2a.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsButton_Q2aPushed, true);
            app.SaveResultsButton_Q2a.Enable = 'off';
            app.SaveResultsButton_Q2a.Text = 'Save Results';
            app.ScienceProductLabel_17 = uilabel(app.aTab_2);
            app.ScienceProductLabel_17.Text = {'• [Plot - 200] Geoplot of estimated trajectory (in latitude and longitude)'; '• [Plot - 201] Estimated position time series (per-axis subplots) compared to reference data (if available)'};
            app.Experiment1bLabel_10 = uilabel(app.aTab_2);
            app.Experiment1bLabel_10.Text = 'Expected Products';
            app.runPanel_6 = uipanel(app.aTab_2);
            app.RunEditField_6Label = uilabel(app.runPanel_6);
            app.RunEditField_6Label.Text = 'Run';
            app.RunEditField_Q2a = uieditfield(app.runPanel_6, 'numeric');
            app.RunEditField_Q2a.Limits = [0 5000];
            app.RunEditField_Q2a.Editable = 'off';
            app.RunEditField_Q2a.Enable = 'off';
            app.ReferenceFrameSwitchLabel = uilabel(app.aTab_2);
            app.ReferenceFrameSwitchLabel.Enable = 'off';
            app.ReferenceFrameSwitchLabel.Visible = 'off';
            app.ReferenceFrameSwitchLabel.Text = 'Reference Frame';
            app.ReferenceFrame_Q2a = uiswitch(app.aTab_2, 'slider');
            app.ReferenceFrame_Q2a.Items = {'ECI', 'ECEF'};
            app.ReferenceFrame_Q2a.ItemsData = [1 2];
            app.ReferenceFrame_Q2a.Enable = 'off';
            app.ReferenceFrame_Q2a.Visible = 'off';
            app.ReferenceFrame_Q2a.Value = 2;
            app.bTab_3 = uitab(app.TabGroup3);
            app.bTab_3.Title = '2.b';
            app.Experiment2bLabel = uilabel(app.bTab_3);
            app.Experiment2bLabel.Text = '2.b | Evaluate Dilution Of Precision (DOP) metrics';
            app.OutputPanel_16 = uipanel(app.bTab_3);
            app.OutputPanel_16.Title = 'Output';
            app.TextArea_Q2b = uitextarea(app.OutputPanel_16);
            app.TextArea_Q2b.Editable = 'off';
            app.TextArea_Q2b.Enable = 'off';
            app.OperatorCommentincludedinthereportPanel_3 = uipanel(app.bTab_3);
            app.OperatorCommentincludedinthereportPanel_3.Title = 'Operator Comment (included in the report)';
            app.CommentArea_Q2b = uitextarea(app.OperatorCommentincludedinthereportPanel_3);
            app.CommentArea_Q2b.Value = {'No relevant comments.'};
            app.ExperimentSuccessLamp_Q2b = uilamp(app.bTab_3);
            app.RunExperimentButton_Q2b = uibutton(app.bTab_3, 'push');
            app.RunExperimentButton_Q2b.ButtonPushedFcn = createCallbackFcn(app, @RunExperimentButton_Q2bPushed, true);
            app.RunExperimentButton_Q2b.Enable = 'off';
            app.RunExperimentButton_Q2b.Text = 'Run Experiment';
            app.GenerateReportButton_Q2b = uibutton(app.bTab_3, 'push');
            app.GenerateReportButton_Q2b.ButtonPushedFcn = createCallbackFcn(app, @GenerateReportButton_Q2bPushed, true);
            app.GenerateReportButton_Q2b.Enable = 'off';
            app.GenerateReportButton_Q2b.Text = 'Generate Report';
            app.ResultsLamp_Q2b = uilamp(app.bTab_3);
            app.ReportLamp_Q2b = uilamp(app.bTab_3);
            app.SaveResultsButton_Q2b = uibutton(app.bTab_3, 'push');
            app.SaveResultsButton_Q2b.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsButton_Q2bPushed, true);
            app.SaveResultsButton_Q2b.Enable = 'off';
            app.SaveResultsButton_Q2b.Text = 'Save Results';
            app.runPanel_Q2b = uipanel(app.bTab_3);
            app.RunEditField_8Label = uilabel(app.runPanel_Q2b);
            app.RunEditField_8Label.Text = 'Run';
            app.RunEditField_Q2b = uieditfield(app.runPanel_Q2b, 'numeric');
            app.RunEditField_Q2b.Limits = [0 5000];
            app.RunEditField_Q2b.Editable = 'off';
            app.RunEditField_Q2b.Enable = 'off';
            app.Label_135 = uilabel(app.bTab_3);
            app.Label_135.Text = {'• [Plot - 210a] DOP metrics time series'; '• [Plot - 210b] Number of used satellites time series'};
            app.Experiment1bLabel_11 = uilabel(app.bTab_3);
            app.Experiment1bLabel_11.Text = 'Expected Products';
            app.cTab_2 = uitab(app.TabGroup3);
            app.cTab_2.Title = '2.c';
            app.Experiment2dLabel_2 = uilabel(app.cTab_2);
            app.Experiment2dLabel_2.Text = '2.c | Compute the PVT in post-processing';
            app.Experiment1bLabel_19 = uilabel(app.cTab_2);
            app.Experiment1bLabel_19.Text = 'Expected Products';
            app.runPanel_Q2c = uipanel(app.cTab_2);
            app.RunEditField_8Label_4 = uilabel(app.runPanel_Q2c);
            app.RunEditField_8Label_4.Text = 'Run';
            app.RunEditField_Q2c = uieditfield(app.runPanel_Q2c, 'numeric');
            app.RunEditField_Q2c.Limits = [0 5000];
            app.RunEditField_Q2c.Editable = 'off';
            app.RunEditField_Q2c.Enable = 'off';
            app.OutputPanel_24 = uipanel(app.cTab_2);
            app.OutputPanel_24.Title = 'Output';
            app.TextArea_Q2c = uitextarea(app.OutputPanel_24);
            app.TextArea_Q2c.Editable = 'off';
            app.TextArea_Q2c.Enable = 'off';
            app.OperatorCommentincludedinthereportPanel_15 = uipanel(app.cTab_2);
            app.OperatorCommentincludedinthereportPanel_15.Title = 'Operator Comment (included in the report)';
            app.CommentArea_Q2c = uitextarea(app.OperatorCommentincludedinthereportPanel_15);
            app.CommentArea_Q2c.Value = {'No relevant comments.'};
            app.ExperimentSuccessLamp_Q2c = uilamp(app.cTab_2);
            app.RunExperimentButton_Q2c = uibutton(app.cTab_2, 'push');
            app.RunExperimentButton_Q2c.Enable = 'off';
            app.RunExperimentButton_Q2c.Text = 'Run Experiment';
            app.GenerateReportButton_Q2c = uibutton(app.cTab_2, 'push');
            app.GenerateReportButton_Q2c.ButtonPushedFcn = createCallbackFcn(app, @GenerateReportButton_Q2cPushed, true);
            app.GenerateReportButton_Q2c.Enable = 'off';
            app.GenerateReportButton_Q2c.Text = 'Generate Report';
            app.ResultsLamp_Q2c = uilamp(app.cTab_2);
            app.ReportLamp_Q2c = uilamp(app.cTab_2);
            app.SaveResultsButton_Q2c = uibutton(app.cTab_2, 'push');
            app.SaveResultsButton_Q2c.Enable = 'off';
            app.SaveResultsButton_Q2c.Text = 'Save Results';
            app.Q1a_completed = uilamp(app.AnalysesTab);
            app.Q1d_completed = uilamp(app.AnalysesTab);
            app.Q1c_completed = uilamp(app.AnalysesTab);
            app.Q1b_completed = uilamp(app.AnalysesTab);
            app.Q1e_completed = uilamp(app.AnalysesTab);
            app.Q2a_completed = uilamp(app.AnalysesTab);
            app.Q1f_completed = uilamp(app.AnalysesTab);
            app.Q2b_completed = uilamp(app.AnalysesTab);
            app.Q1g_completed = uilamp(app.AnalysesTab);
            app.Q1h_completed = uilamp(app.AnalysesTab);
            app.Q2c_completed = uilamp(app.AnalysesTab);
            app.GPSCheckBox = uicheckbox(app.AnalysesTab);
            app.GPSCheckBox.Text = 'GPS';
            app.GPSCheckBox.Value = true;
            app.GLOCheckBox = uicheckbox(app.AnalysesTab);
            app.GLOCheckBox.Text = 'GLO';
            app.GLOCheckBox.Value = true;
            app.GALCheckBox = uicheckbox(app.AnalysesTab);
            app.GALCheckBox.Text = 'GAL';
            app.GALCheckBox.Value = true;
            app.BDSCheckBox = uicheckbox(app.AnalysesTab);
            app.BDSCheckBox.Text = 'BDS';
            app.BDSCheckBox.Value = true;
            app.ConstellationsLabel = uilabel(app.AnalysesTab);
            app.ConstellationsLabel.Text = 'Constellations:';
            app.SummaryReportTab = uitab(app.TabGroup);
            app.SummaryReportTab.Title = 'Summary Report';
            app.ReportselectionPanel = uipanel(app.SummaryReportTab);
            app.ReportselectionPanel.Title = 'Report selection';
            app.Q1aListBoxLabel = uilabel(app.ReportselectionPanel);
            app.Q1aListBoxLabel.Text = 'Q1.a';
            app.ListBox_Q1a = uilistbox(app.ReportselectionPanel);
            app.ListBox_Q1a.Items = {};
            app.ListBox_Q1a.Value = {};
            app.OpenReport_Q1a = uibutton(app.ReportselectionPanel, 'push');
            app.OpenReport_Q1a.ButtonPushedFcn = createCallbackFcn(app, @OpenReport_Q1aPushed, true);
            app.OpenReport_Q1a.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-visualizza-file-30.png');
            app.OpenReport_Q1a.Text = '';
            app.AddReport_Q1a = uibutton(app.ReportselectionPanel, 'push');
            app.AddReport_Q1a.ButtonPushedFcn = createCallbackFcn(app, @AddReport_Q1aButtonPushed, true);
            app.AddReport_Q1a.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-aggiungi-32.png');
            app.AddReport_Q1a.Text = '';
            app.DeleteReport_Q1a = uibutton(app.ReportselectionPanel, 'push');
            app.DeleteReport_Q1a.ButtonPushedFcn = createCallbackFcn(app, @DeleteReport_Q1aPushed, true);
            app.DeleteReport_Q1a.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-cestino-48.png');
            app.DeleteReport_Q1a.Text = '';
            app.OpenReport_Q1b = uibutton(app.ReportselectionPanel, 'push');
            app.OpenReport_Q1b.ButtonPushedFcn = createCallbackFcn(app, @OpenReport_Q1bPushed, true);
            app.OpenReport_Q1b.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-visualizza-file-30.png');
            app.OpenReport_Q1b.Text = '';
            app.AddReport_Q1b = uibutton(app.ReportselectionPanel, 'push');
            app.AddReport_Q1b.ButtonPushedFcn = createCallbackFcn(app, @AddReport_Q1bPushed, true);
            app.AddReport_Q1b.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-aggiungi-32.png');
            app.AddReport_Q1b.Text = '';
            app.DeleteReport_Q1b = uibutton(app.ReportselectionPanel, 'push');
            app.DeleteReport_Q1b.ButtonPushedFcn = createCallbackFcn(app, @DeleteReport_Q1bPushed, true);
            app.DeleteReport_Q1b.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-cestino-48.png');
            app.DeleteReport_Q1b.Text = '';
            app.Q1bListBoxLabel = uilabel(app.ReportselectionPanel);
            app.Q1bListBoxLabel.Text = 'Q1.b';
            app.ListBox_Q1b = uilistbox(app.ReportselectionPanel);
            app.ListBox_Q1b.Items = {};
            app.ListBox_Q1b.Value = {};
            app.OpenReport_Q1c = uibutton(app.ReportselectionPanel, 'push');
            app.OpenReport_Q1c.ButtonPushedFcn = createCallbackFcn(app, @OpenReport_Q1cPushed, true);
            app.OpenReport_Q1c.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-visualizza-file-30.png');
            app.OpenReport_Q1c.Text = '';
            app.AddReport_Q1c = uibutton(app.ReportselectionPanel, 'push');
            app.AddReport_Q1c.ButtonPushedFcn = createCallbackFcn(app, @AddReport_Q1cPushed, true);
            app.AddReport_Q1c.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-aggiungi-32.png');
            app.AddReport_Q1c.Text = '';
            app.DeleteReport_Q1c = uibutton(app.ReportselectionPanel, 'push');
            app.DeleteReport_Q1c.ButtonPushedFcn = createCallbackFcn(app, @DeleteReport_Q1cPushed, true);
            app.DeleteReport_Q1c.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-cestino-48.png');
            app.DeleteReport_Q1c.Text = '';
            app.Q1cListBoxLabel = uilabel(app.ReportselectionPanel);
            app.Q1cListBoxLabel.Text = 'Q1.c';
            app.ListBox_Q1c = uilistbox(app.ReportselectionPanel);
            app.ListBox_Q1c.Items = {};
            app.ListBox_Q1c.Value = {};
            app.OpenReport_Q1d = uibutton(app.ReportselectionPanel, 'push');
            app.OpenReport_Q1d.ButtonPushedFcn = createCallbackFcn(app, @OpenReport_Q1dPushed, true);
            app.OpenReport_Q1d.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-visualizza-file-30.png');
            app.OpenReport_Q1d.Text = '';
            app.AddReport_Q1d = uibutton(app.ReportselectionPanel, 'push');
            app.AddReport_Q1d.ButtonPushedFcn = createCallbackFcn(app, @AddReport_Q1dPushed, true);
            app.AddReport_Q1d.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-aggiungi-32.png');
            app.AddReport_Q1d.Text = '';
            app.DeleteReportQ1d = uibutton(app.ReportselectionPanel, 'push');
            app.DeleteReportQ1d.ButtonPushedFcn = createCallbackFcn(app, @DeleteReportQ1dPushed, true);
            app.DeleteReportQ1d.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-cestino-48.png');
            app.DeleteReportQ1d.Text = '';
            app.Q1dListBoxLabel = uilabel(app.ReportselectionPanel);
            app.Q1dListBoxLabel.Text = 'Q1.d';
            app.ListBox_Q1d = uilistbox(app.ReportselectionPanel);
            app.ListBox_Q1d.Items = {};
            app.ListBox_Q1d.Value = {};
            app.OpenReport_Q1e = uibutton(app.ReportselectionPanel, 'push');
            app.OpenReport_Q1e.ButtonPushedFcn = createCallbackFcn(app, @OpenReport_Q1ePushed, true);
            app.OpenReport_Q1e.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-visualizza-file-30.png');
            app.OpenReport_Q1e.Text = '';
            app.AddReport_Q1e = uibutton(app.ReportselectionPanel, 'push');
            app.AddReport_Q1e.ButtonPushedFcn = createCallbackFcn(app, @AddReport_Q1ePushed, true);
            app.AddReport_Q1e.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-aggiungi-32.png');
            app.AddReport_Q1e.Text = '';
            app.DeleteReport_Q1e = uibutton(app.ReportselectionPanel, 'push');
            app.DeleteReport_Q1e.ButtonPushedFcn = createCallbackFcn(app, @DeleteReport_Q1ePushed, true);
            app.DeleteReport_Q1e.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-cestino-48.png');
            app.DeleteReport_Q1e.Text = '';
            app.Q1eListBoxLabel = uilabel(app.ReportselectionPanel);
            app.Q1eListBoxLabel.Text = 'Q1.e';
            app.ListBox_Q1e = uilistbox(app.ReportselectionPanel);
            app.ListBox_Q1e.Items = {};
            app.ListBox_Q1e.Value = {};
            app.OpenReport_Q1f = uibutton(app.ReportselectionPanel, 'push');
            app.OpenReport_Q1f.ButtonPushedFcn = createCallbackFcn(app, @OpenReport_Q1fPushed, true);
            app.OpenReport_Q1f.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-visualizza-file-30.png');
            app.OpenReport_Q1f.Text = '';
            app.AddReport_Q1f = uibutton(app.ReportselectionPanel, 'push');
            app.AddReport_Q1f.ButtonPushedFcn = createCallbackFcn(app, @AddReport_Q1fPushed, true);
            app.AddReport_Q1f.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-aggiungi-32.png');
            app.AddReport_Q1f.Text = '';
            app.DeleteReport_Q1f = uibutton(app.ReportselectionPanel, 'push');
            app.DeleteReport_Q1f.ButtonPushedFcn = createCallbackFcn(app, @DeleteReport_Q1fPushed, true);
            app.DeleteReport_Q1f.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-cestino-48.png');
            app.DeleteReport_Q1f.Text = '';
            app.Q1fListBoxLabel = uilabel(app.ReportselectionPanel);
            app.Q1fListBoxLabel.Text = 'Q1.f';
            app.ListBox_Q1f = uilistbox(app.ReportselectionPanel);
            app.ListBox_Q1f.Items = {};
            app.ListBox_Q1f.Value = {};
            app.OpenReport_Q2a = uibutton(app.ReportselectionPanel, 'push');
            app.OpenReport_Q2a.ButtonPushedFcn = createCallbackFcn(app, @OpenReport_Q2aPushed, true);
            app.OpenReport_Q2a.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-visualizza-file-30.png');
            app.OpenReport_Q2a.Text = '';
            app.AddReport_Q2a = uibutton(app.ReportselectionPanel, 'push');
            app.AddReport_Q2a.ButtonPushedFcn = createCallbackFcn(app, @AddReport_Q2aPushed, true);
            app.AddReport_Q2a.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-aggiungi-32.png');
            app.AddReport_Q2a.Text = '';
            app.DeleteReport_Q2a = uibutton(app.ReportselectionPanel, 'push');
            app.DeleteReport_Q2a.ButtonPushedFcn = createCallbackFcn(app, @DeleteReport_Q2aPushed, true);
            app.DeleteReport_Q2a.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-cestino-48.png');
            app.DeleteReport_Q2a.Text = '';
            app.OpenReport_Q2c = uibutton(app.ReportselectionPanel, 'push');
            app.OpenReport_Q2c.ButtonPushedFcn = createCallbackFcn(app, @OpenReport_Q2cPushed, true);
            app.OpenReport_Q2c.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-visualizza-file-30.png');
            app.OpenReport_Q2c.Text = '';
            app.AddReport_Q2c = uibutton(app.ReportselectionPanel, 'push');
            app.AddReport_Q2c.ButtonPushedFcn = createCallbackFcn(app, @AddReport_Q2cPushed, true);
            app.AddReport_Q2c.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-aggiungi-32.png');
            app.AddReport_Q2c.Text = '';
            app.DeleteReport_Q2c = uibutton(app.ReportselectionPanel, 'push');
            app.DeleteReport_Q2c.ButtonPushedFcn = createCallbackFcn(app, @DeleteReport_Q2cPushed, true);
            app.DeleteReport_Q2c.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-cestino-48.png');
            app.DeleteReport_Q2c.Text = '';
            app.OpenReport_Q2d = uibutton(app.ReportselectionPanel, 'push');
            app.OpenReport_Q2d.ButtonPushedFcn = createCallbackFcn(app, @OpenReport_Q2dPushed, true);
            app.OpenReport_Q2d.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-visualizza-file-30.png');
            app.OpenReport_Q2d.Text = '';
            app.AddReport_Q2d = uibutton(app.ReportselectionPanel, 'push');
            app.AddReport_Q2d.ButtonPushedFcn = createCallbackFcn(app, @AddReport_Q2dPushed, true);
            app.AddReport_Q2d.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-aggiungi-32.png');
            app.AddReport_Q2d.Text = '';
            app.DeleteReport_Q2d = uibutton(app.ReportselectionPanel, 'push');
            app.DeleteReport_Q2d.ButtonPushedFcn = createCallbackFcn(app, @DeleteReport_Q2dPushed, true);
            app.DeleteReport_Q2d.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-cestino-48.png');
            app.DeleteReport_Q2d.Text = '';
            app.Q2aListBoxLabel = uilabel(app.ReportselectionPanel);
            app.Q2aListBoxLabel.Text = 'Q2.a';
            app.ListBox_Q2a = uilistbox(app.ReportselectionPanel);
            app.ListBox_Q2a.Items = {};
            app.ListBox_Q2a.Value = {};
            app.Q2cListBoxLabel = uilabel(app.ReportselectionPanel);
            app.Q2cListBoxLabel.Text = 'Q2.c';
            app.ListBox_Q2c = uilistbox(app.ReportselectionPanel);
            app.ListBox_Q2c.Items = {};
            app.ListBox_Q2c.Value = {};
            app.Q2dListBoxLabel = uilabel(app.ReportselectionPanel);
            app.Q2dListBoxLabel.Text = 'Q2.d';
            app.ListBox_Q2d = uilistbox(app.ReportselectionPanel);
            app.ListBox_Q2d.Items = {};
            app.ListBox_Q2d.Value = {};
            app.OpenReport_Q3a = uibutton(app.ReportselectionPanel, 'push');
            app.OpenReport_Q3a.ButtonPushedFcn = createCallbackFcn(app, @OpenReport_Q3aPushed, true);
            app.OpenReport_Q3a.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-visualizza-file-30.png');
            app.OpenReport_Q3a.Text = '';
            app.AddReport_Q3a = uibutton(app.ReportselectionPanel, 'push');
            app.AddReport_Q3a.ButtonPushedFcn = createCallbackFcn(app, @AddReport_Q3aPushed, true);
            app.AddReport_Q3a.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-aggiungi-32.png');
            app.AddReport_Q3a.Text = '';
            app.DeleteReport_Q3a = uibutton(app.ReportselectionPanel, 'push');
            app.DeleteReport_Q3a.ButtonPushedFcn = createCallbackFcn(app, @DeleteReport_Q3aPushed, true);
            app.DeleteReport_Q3a.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-cestino-48.png');
            app.DeleteReport_Q3a.Text = '';
            app.OpenReport_Q3b = uibutton(app.ReportselectionPanel, 'push');
            app.OpenReport_Q3b.ButtonPushedFcn = createCallbackFcn(app, @OpenReport_Q3bPushed, true);
            app.OpenReport_Q3b.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-visualizza-file-30.png');
            app.OpenReport_Q3b.Text = '';
            app.AddReport_Q3b = uibutton(app.ReportselectionPanel, 'push');
            app.AddReport_Q3b.ButtonPushedFcn = createCallbackFcn(app, @AddReport_Q3bPushed, true);
            app.AddReport_Q3b.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-aggiungi-32.png');
            app.AddReport_Q3b.Text = '';
            app.DeleteReport_Q3b = uibutton(app.ReportselectionPanel, 'push');
            app.DeleteReport_Q3b.ButtonPushedFcn = createCallbackFcn(app, @DeleteReport_Q3bPushed, true);
            app.DeleteReport_Q3b.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-cestino-48.png');
            app.DeleteReport_Q3b.Text = '';
            app.Q3aListBoxLabel = uilabel(app.ReportselectionPanel);
            app.Q3aListBoxLabel.Text = 'Q3.a';
            app.ListBox_Q3a = uilistbox(app.ReportselectionPanel);
            app.ListBox_Q3a.Items = {};
            app.ListBox_Q3a.Value = {};
            app.Q3bListBoxLabel = uilabel(app.ReportselectionPanel);
            app.Q3bListBoxLabel.Text = 'Q3.b';
            app.ListBox_Q3b = uilistbox(app.ReportselectionPanel);
            app.ListBox_Q3b.Items = {};
            app.ListBox_Q3b.Value = {};
            app.Objective1Label = uilabel(app.ReportselectionPanel);
            app.Objective1Label.Text = 'Objective 1';
            app.Objective2Label = uilabel(app.ReportselectionPanel);
            app.Objective2Label.Text = 'Objective 2';
            app.Objective3Label = uilabel(app.ReportselectionPanel);
            app.Objective3Label.Text = 'Objective 3';
            app.RestorelistsButton = uibutton(app.ReportselectionPanel, 'push');
            app.RestorelistsButton.ButtonPushedFcn = createCallbackFcn(app, @RestorelistsButtonPushed, true);
            app.RestorelistsButton.Text = 'Restore lists';
            app.OPSSummaryReportOSRPanel = uipanel(app.SummaryReportTab);
            app.OPSSummaryReportOSRPanel.Title = 'OPS Summary Report (OSR)';
            app.GenerateReportButton_OSR = uibutton(app.OPSSummaryReportOSRPanel, 'push');
            app.GenerateReportButton_OSR.ButtonPushedFcn = createCallbackFcn(app, @GenerateReportButton_OSRPushed, true);
            app.GenerateReportButton_OSR.Tooltip = {'Generate word and pdf report associated to the current run.'};
            app.GenerateReportButton_OSR.Text = 'Generate OSR';
            app.OSRLamp = uilamp(app.OPSSummaryReportOSRPanel);
            app.CheckBox_Q1a = uicheckbox(app.OPSSummaryReportOSRPanel);
            app.CheckBox_Q1a.Text = 'Q1.a';
            app.CheckBox_Q1b = uicheckbox(app.OPSSummaryReportOSRPanel);
            app.CheckBox_Q1b.Text = 'Q1.b';
            app.CheckBox_Q1d = uicheckbox(app.OPSSummaryReportOSRPanel);
            app.CheckBox_Q1d.Text = 'Q1.d';
            app.CheckBox_Q1e = uicheckbox(app.OPSSummaryReportOSRPanel);
            app.CheckBox_Q1e.Text = 'Q1.e';
            app.CheckBox_Q1f = uicheckbox(app.OPSSummaryReportOSRPanel);
            app.CheckBox_Q1f.Text = 'Q1.f';
            app.CheckBox_Q2a = uicheckbox(app.OPSSummaryReportOSRPanel);
            app.CheckBox_Q2a.Text = 'Q2.a';
            app.CheckBox_Q2c = uicheckbox(app.OPSSummaryReportOSRPanel);
            app.CheckBox_Q2c.Text = 'Q2.c';
            app.CheckBox_Q3a = uicheckbox(app.OPSSummaryReportOSRPanel);
            app.CheckBox_Q3a.Text = 'Q3.a';
            app.CheckBox_Q3b = uicheckbox(app.OPSSummaryReportOSRPanel);
            app.CheckBox_Q3b.Text = 'Q3.b';
            app.Objective3Label_3 = uilabel(app.OPSSummaryReportOSRPanel);
            app.Objective3Label_3.Text = 'Objective 3';
            app.Objective2Label_2 = uilabel(app.OPSSummaryReportOSRPanel);
            app.Objective2Label_2.Text = 'Objective 2';
            app.Objective1Label_2 = uilabel(app.OPSSummaryReportOSRPanel);
            app.Objective1Label_2.Text = 'Objective 1';
            app.EditField_Q1a_OSR = uieditfield(app.OPSSummaryReportOSRPanel, 'text');
            app.EditField_Q1a_OSR.Enable = 'off';
            app.EditField_Q1b_OSR = uieditfield(app.OPSSummaryReportOSRPanel, 'text');
            app.EditField_Q1b_OSR.Enable = 'off';
            app.EditField_Q1c_OSR = uieditfield(app.OPSSummaryReportOSRPanel, 'text');
            app.EditField_Q1c_OSR.Enable = 'off';
            app.EditField_Q1e_OSR = uieditfield(app.OPSSummaryReportOSRPanel, 'text');
            app.EditField_Q1e_OSR.Enable = 'off';
            app.EditField_Q1f_OSR = uieditfield(app.OPSSummaryReportOSRPanel, 'text');
            app.EditField_Q1f_OSR.Enable = 'off';
            app.EditField_Q2a_OSR = uieditfield(app.OPSSummaryReportOSRPanel, 'text');
            app.EditField_Q2a_OSR.Enable = 'off';
            app.EditField_Q2c_OSR = uieditfield(app.OPSSummaryReportOSRPanel, 'text');
            app.EditField_Q2c_OSR.Enable = 'off';
            app.EditField_Q3a_OSR = uieditfield(app.OPSSummaryReportOSRPanel, 'text');
            app.EditField_Q3a_OSR.Enable = 'off';
            app.EditField_Q3b_OSR = uieditfield(app.OPSSummaryReportOSRPanel, 'text');
            app.EditField_Q3b_OSR.Enable = 'off';
            app.CheckBox_Q2d = uicheckbox(app.OPSSummaryReportOSRPanel);
            app.CheckBox_Q2d.Text = 'Q2.d';
            app.EditField_Q2d_OSR = uieditfield(app.OPSSummaryReportOSRPanel, 'text');
            app.EditField_Q2d_OSR.Enable = 'off';
            app.EditField_Q1d_OSR = uieditfield(app.OPSSummaryReportOSRPanel, 'text');
            app.EditField_Q1d_OSR.Enable = 'off';
            app.CheckBox_Q1c = uicheckbox(app.OPSSummaryReportOSRPanel);
            app.CheckBox_Q1c.Text = 'Q1.c';
            app.OSR_operatorComments = uipanel(app.OPSSummaryReportOSRPanel);
            app.OSR_operatorComments.Title = 'Operator Comment (included in the OSR first page)';
            app.OSR_comments = uitextarea(app.OSR_operatorComments);
            app.OSR_comments.Value = {'No relevant comments.'};
            app.IQSTab = uitab(app.TabGroup);
            app.IQSTab.Title = 'IQS';
            app.OverrideIQSFolder = uibutton(app.IQSTab, 'push');
            app.OverrideIQSFolder.ButtonPushedFcn = createCallbackFcn(app, @OverrideIQSFolderButtonPushed, true);
            app.OverrideIQSFolder.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-folder-50.png');
            app.OverrideIQSFolder.Tooltip = {'Explore folder (not possible to override selection)'};
            app.OverrideIQSFolder.Text = '';
            app.IQSFilebinTextAreaLabel = uilabel(app.IQSTab);
            app.IQSFilebinTextAreaLabel.Text = {'IQS File'; ' (.bin)'};
            app.IQSFilebinTextArea = uitextarea(app.IQSTab);
            app.IQSFilebinTextArea.Editable = 'off';
            app.Experiment3bLabel_3 = uilabel(app.IQSTab);
            app.Experiment3bLabel_3.Text = 'IQ samples analysis';
            app.ExperimentSuccessLamp_Q3b = uilamp(app.IQSTab);
            app.RunExperimentButton_Q3b = uibutton(app.IQSTab, 'push');
            app.RunExperimentButton_Q3b.ButtonPushedFcn = createCallbackFcn(app, @RunExperimentButton_Q3bPushed, true);
            app.RunExperimentButton_Q3b.Enable = 'off';
            app.RunExperimentButton_Q3b.Text = 'Run Experiment';
            app.OutputPanel_25 = uipanel(app.IQSTab);
            app.OutputPanel_25.Title = 'Output';
            app.TextArea_Q3b = uitextarea(app.OutputPanel_25);
            app.TextArea_Q3b.Editable = 'off';
            app.TextArea_Q3b.Enable = 'off';
            app.OperatorCommentincludedinthereportPanel_16 = uipanel(app.IQSTab);
            app.OperatorCommentincludedinthereportPanel_16.Title = 'Operator Comment (included in the report)';
            app.CommentArea_Q3b = uitextarea(app.OperatorCommentincludedinthereportPanel_16);
            app.CommentArea_Q3b.Value = {'No relevant comments.'};
            app.GenerateReportButton_Q3b = uibutton(app.IQSTab, 'push');
            app.GenerateReportButton_Q3b.ButtonPushedFcn = createCallbackFcn(app, @GenerateReportButton_Q3bPushed, true);
            app.GenerateReportButton_Q3b.Enable = 'off';
            app.GenerateReportButton_Q3b.Tooltip = {'Generating report will quickly open and close MS Word '};
            app.GenerateReportButton_Q3b.Text = 'Generate Report';
            app.ResultsLamp_Q3b = uilamp(app.IQSTab);
            app.ReportLamp_Q3b = uilamp(app.IQSTab);
            app.SaveResultsButton_Q3b = uibutton(app.IQSTab, 'push');
            app.SaveResultsButton_Q3b.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsButton_Q3bPushed, true);
            app.SaveResultsButton_Q3b.Enable = 'off';
            app.SaveResultsButton_Q3b.Text = 'Save Results';
            app.runPanel_Q3b = uipanel(app.IQSTab);
            app.RunEditField_8Label_6 = uilabel(app.runPanel_Q3b);
            app.RunEditField_8Label_6.Text = 'Run';
            app.RunEditField_Q3b_3 = uieditfield(app.runPanel_Q3b, 'numeric');
            app.RunEditField_Q3b_3.Limits = [0 5000];
            app.RunEditField_Q3b_3.Editable = 'off';
            app.RunEditField_Q3b_3.Enable = 'off';
            app.ScienceProductLabel_26 = uilabel(app.IQSTab);
            app.ScienceProductLabel_26.Text = {'[Plot - 320 & 322] - Acquisition results with estimated C/N0'; '[Table - 321 & 323] - Summary tables with estimated C/N0 and Doppler shifts'; ''};
            app.Experiment1bLabel_21 = uilabel(app.IQSTab);
            app.Experiment1bLabel_21.Text = 'Expected Products';
            app.GNSSSoftwareReceiverSettingsAcquisitionStagePanel = uipanel(app.IQSTab);
            app.GNSSSoftwareReceiverSettingsAcquisitionStagePanel.Title = 'Acquisition Settings';
            app.DopplerstepHzEditFieldLabel = uilabel(app.GNSSSoftwareReceiverSettingsAcquisitionStagePanel);
            app.DopplerstepHzEditFieldLabel.Text = 'Doppler step [Hz]';
            app.DopplerstepHzEditField = uieditfield(app.GNSSSoftwareReceiverSettingsAcquisitionStagePanel, 'numeric');
            app.DopplerstepHzEditField.Limits = [1 500];
            app.DopplerstepHzEditField.Value = 50;
            app.NonCoherentsumsEditFieldLabel = uilabel(app.GNSSSoftwareReceiverSettingsAcquisitionStagePanel);
            app.NonCoherentsumsEditFieldLabel.Text = 'Non-Coherent sums';
            app.NonCoherentsumsEditField = uieditfield(app.GNSSSoftwareReceiverSettingsAcquisitionStagePanel, 'numeric');
            app.NonCoherentsumsEditField.Limits = [1 20];
            app.NonCoherentsumsEditField.Value = 1;
            app.CoherentIntegrationtimesEditFieldLabel = uilabel(app.GNSSSoftwareReceiverSettingsAcquisitionStagePanel);
            app.CoherentIntegrationtimesEditFieldLabel.Text = 'Coherent Integration time [s]';
            app.CoherentIntegrationtimesEditField = uieditfield(app.GNSSSoftwareReceiverSettingsAcquisitionStagePanel, 'numeric');
            app.CoherentIntegrationtimesEditField.Limits = [0.001 0.02];
            app.CoherentIntegrationtimesEditField.Value = 0.001;
            app.FalseAlarmProbabilityEditFieldLabel = uilabel(app.GNSSSoftwareReceiverSettingsAcquisitionStagePanel);
            app.FalseAlarmProbabilityEditFieldLabel.Text = 'False Alarm Probability';
            app.FalseAlarmProbabilityEditField = uieditfield(app.GNSSSoftwareReceiverSettingsAcquisitionStagePanel, 'numeric');
            app.FalseAlarmProbabilityEditField.Limits = [0.001 0.005];
            app.FalseAlarmProbabilityEditField.Value = 0.001;
            app.G1CCheckBox = uicheckbox(app.GNSSSoftwareReceiverSettingsAcquisitionStagePanel);
            app.G1CCheckBox.ValueChangedFcn = createCallbackFcn(app, @G1CCheckBox_2ValueChanged, true);
            app.G1CCheckBox.Text = 'G1C';
            app.G1CCheckBox.Value = true;
            app.E1BCheckBox = uicheckbox(app.GNSSSoftwareReceiverSettingsAcquisitionStagePanel);
            app.E1BCheckBox.ValueChangedFcn = createCallbackFcn(app, @G1CCheckBox_2ValueChanged, true);
            app.E1BCheckBox.Text = 'E1B';
            app.E1BCheckBox.Value = true;
            app.DopplerStepEditField = uieditfield(app.GNSSSoftwareReceiverSettingsAcquisitionStagePanel, 'numeric');
            app.DopplerStepEditField.Limits = [1 500];
            app.DopplerStepEditField.Value = 50;
            app.NonCoherentsumsEditField_2 = uieditfield(app.GNSSSoftwareReceiverSettingsAcquisitionStagePanel, 'numeric');
            app.NonCoherentsumsEditField_2.Limits = [1 20];
            app.NonCoherentsumsEditField_2.Value = 1;
            app.CoherentIntegrationtimeEditField = uieditfield(app.GNSSSoftwareReceiverSettingsAcquisitionStagePanel, 'numeric');
            app.CoherentIntegrationtimeEditField.Limits = [0.004 0.02];
            app.CoherentIntegrationtimeEditField.Value = 0.004;
            app.FalseAlarmProbabilityEditField_2 = uieditfield(app.GNSSSoftwareReceiverSettingsAcquisitionStagePanel, 'numeric');
            app.FalseAlarmProbabilityEditField_2.Limits = [0.001 0.005];
            app.FalseAlarmProbabilityEditField_2.Value = 0.001;
            app.TelemetryInputLamp_4Label = uilabel(app.GNSSSoftwareReceiverSettingsAcquisitionStagePanel);
            app.TelemetryInputLamp_4Label.Text = 'GPS L1';
            app.TelemetryInputLamp_4Label_2 = uilabel(app.GNSSSoftwareReceiverSettingsAcquisitionStagePanel);
            app.TelemetryInputLamp_4Label_2.Text = 'Gal E1';
            app.DopplerStepEditField_2 = uieditfield(app.GNSSSoftwareReceiverSettingsAcquisitionStagePanel, 'numeric');
            app.DopplerStepEditField_2.Limits = [-10000 10000];
            app.DopplercenteroffsetHzEditFieldLabel = uilabel(app.GNSSSoftwareReceiverSettingsAcquisitionStagePanel);
            app.DopplercenteroffsetHzEditFieldLabel.Text = 'Doppler center offset [Hz]';
            app.DopplercenteroffsetHzEditField = uieditfield(app.GNSSSoftwareReceiverSettingsAcquisitionStagePanel, 'numeric');
            app.DopplercenteroffsetHzEditField.Limits = [-10000 10000];
            app.DopplerStepEditField_3 = uieditfield(app.GNSSSoftwareReceiverSettingsAcquisitionStagePanel, 'numeric');
            app.DopplerStepEditField_3.Limits = [0 50000];
            app.DopplerStepEditField_3.ValueDisplayFormat = '%d';
            app.DopplerStepEditField_3.Value = 10000;
            app.DopplerrangeHzEditFieldLabel = uilabel(app.GNSSSoftwareReceiverSettingsAcquisitionStagePanel);
            app.DopplerrangeHzEditFieldLabel.Text = 'Doppler range [Hz]';
            app.DopplerrangeHzEditField = uieditfield(app.GNSSSoftwareReceiverSettingsAcquisitionStagePanel, 'numeric');
            app.DopplerrangeHzEditField.Limits = [0 50000];
            app.DopplerrangeHzEditField.ValueDisplayFormat = '%d';
            app.DopplerrangeHzEditField.Value = 10000;
            app.CodeDopplerSearchLabel = uilabel(app.GNSSSoftwareReceiverSettingsAcquisitionStagePanel);
            app.CodeDopplerSearchLabel.Text = 'Code Doppler Search*';
            app.CodeDopplerCheckBoxG1C = uicheckbox(app.GNSSSoftwareReceiverSettingsAcquisitionStagePanel);
            app.CodeDopplerCheckBoxG1C.Text = '';
            app.CodeDopplerCheckBoxE1B = uicheckbox(app.GNSSSoftwareReceiverSettingsAcquisitionStagePanel);
            app.CodeDopplerCheckBoxE1B.Text = '';
            app.UITable_Q3b_GPS = uitable(app.IQSTab);
            app.UITable_Q3b_GPS.ColumnName = {'PRN'; 'IQS C/No [dB-Hz]'; 'IQS Dopp. [Hz]'};
            app.UITable_Q3b_GPS.RowName = {};
            app.UITable_Q3b_Galileo = uitable(app.IQSTab);
            app.UITable_Q3b_Galileo.ColumnName = {'PRN'; 'IQS C/No [dB-Hz]'; 'IQS Dopp. [Hz]'};
            app.UITable_Q3b_Galileo.RowName = {};
            app.GPSL1CALabel = uilabel(app.IQSTab);
            app.GPSL1CALabel.Text = 'GPS L1 C/A';
            app.GalE1bLabel = uilabel(app.IQSTab);
            app.GalE1bLabel.Text = 'Gal E1b';
            app.WARNINGLabel_q1d_5 = uilabel(app.IQSTab);
            app.WARNINGLabel_q1d_5.Text = '*WARNING: The experiment may last for tens of minutes based on the configuration';
            % Show the figure after all components are created
            app.AnalysisToolUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GPEARL

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AnalysisToolUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AnalysisToolUIFigure)
        end
    end
end